classdef HWCC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu1                     matlab.ui.container.Menu
        Menu1_1                   matlab.ui.container.Menu
        Menu_2                    matlab.ui.container.Menu
        Menu2_1                   matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        TabGroup1                 matlab.ui.container.TabGroup
        Tab_Data                  matlab.ui.container.Tab
        GridLayout1               matlab.ui.container.GridLayout
        Panel_5                   matlab.ui.container.Panel
        GridLayout27              matlab.ui.container.GridLayout
        TabGroupData_3            matlab.ui.container.TabGroup
        TabData3_1                matlab.ui.container.Tab
        GridLayout28              matlab.ui.container.GridLayout
        UIAxesData1               matlab.ui.control.UIAxes
        TabData3_2                matlab.ui.container.Tab
        GridLayout29              matlab.ui.container.GridLayout
        UIAxesData2               matlab.ui.control.UIAxes
        Panel1_1                  matlab.ui.container.Panel
        GridLayout1_1             matlab.ui.container.GridLayout
        TabGroupData_1            matlab.ui.container.TabGroup
        TabData1_1                matlab.ui.container.Tab
        GridLayout1_1_1           matlab.ui.container.GridLayout
        UITableData1_1            matlab.ui.control.Table
        TabData1_2                matlab.ui.container.Tab
        GridLayout1_1_2           matlab.ui.container.GridLayout
        UITableData1_2            matlab.ui.control.Table
        Panel1_2                  matlab.ui.container.Panel
        GridLayout1_2             matlab.ui.container.GridLayout
        TabGroupData_2            matlab.ui.container.TabGroup
        TabData2_1                matlab.ui.container.Tab
        GridLayout1_2_1           matlab.ui.container.GridLayout
        UITableData2_1            matlab.ui.control.Table
        TabData2_2                matlab.ui.container.Tab
        GridLayout1_2_2           matlab.ui.container.GridLayout
        UITableData2_2            matlab.ui.control.Table
        TabData2_3                matlab.ui.container.Tab
        GridLayout26              matlab.ui.container.GridLayout
        UITableData2_3            matlab.ui.control.Table
        Panel1_3                  matlab.ui.container.Panel
        GridLayout1_3_1           matlab.ui.container.GridLayout
        UITableData3_1            matlab.ui.control.Table
        Panel1_4                  matlab.ui.container.Panel
        GridLayout1_4_1           matlab.ui.container.GridLayout
        UITableData4_1            matlab.ui.control.Table
        Tab_P3                    matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        PanelP3_1                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        TabGroupP3_1              matlab.ui.container.TabGroup
        TabP3_1_1                 matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        PanelP3_1_1               matlab.ui.container.Panel
        GridLayout14              matlab.ui.container.GridLayout
        UITableP3_1_1             matlab.ui.control.Table
        PanelP3_1_2               matlab.ui.container.Panel
        GridLayout15              matlab.ui.container.GridLayout
        UITableP3_1_2             matlab.ui.control.Table
        PanelP3_1_3               matlab.ui.container.Panel
        GridLayout17              matlab.ui.container.GridLayout
        UITableP3_1_3             matlab.ui.control.Table
        PanelP3_2                 matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        TabGroupP3_2              matlab.ui.container.TabGroup
        TabP3_2_1                 matlab.ui.container.Tab
        GridLayout18              matlab.ui.container.GridLayout
        TabGroupCvCs1             matlab.ui.container.TabGroup
        TabCvCs1_2                matlab.ui.container.Tab
        GridLayout19_4            matlab.ui.container.GridLayout
        EditField_19Label         matlab.ui.control.Label
        EditField_14Label         matlab.ui.control.Label
        EditField_17Label         matlab.ui.control.Label
        EditField_15Label         matlab.ui.control.Label
        DropDown_4Label           matlab.ui.control.Label
        CsEditField_8Label        matlab.ui.control.Label
        CvEditField_8Label        matlab.ui.control.Label
        CsEditField_7Label        matlab.ui.control.Label
        CvEditField_7Label        matlab.ui.control.Label
        CsCvEditField_8Label      matlab.ui.control.Label
        CsCvEditField_7Label      matlab.ui.control.Label
        CsEditField1_1            matlab.ui.control.NumericEditField
        CsEditField1_2            matlab.ui.control.NumericEditField
        CsEditField1_3            matlab.ui.control.NumericEditField
        CsEditField1_4            matlab.ui.control.NumericEditField
        CsEditField1_5            matlab.ui.control.NumericEditField
        CsButton1_6               matlab.ui.control.StateButton
        CsLabel1_6                matlab.ui.control.Label
        CsDropDown1_7             matlab.ui.control.DropDown
        CsButton1_8               matlab.ui.control.StateButton
        CsLabel1_8                matlab.ui.control.Label
        CsEditField1_9            matlab.ui.control.NumericEditField
        CsEditField1_10           matlab.ui.control.NumericEditField
        CsEditField1_11           matlab.ui.control.NumericEditField
        CsEditField1_12           matlab.ui.control.NumericEditField
        CsEditField1_13           matlab.ui.control.NumericEditField
        TabCv1                    matlab.ui.container.Tab
        GridLayout19              matlab.ui.container.GridLayout
        EditField_18Label         matlab.ui.control.Label
        EditField_9Label          matlab.ui.control.Label
        CvEditField1_2            matlab.ui.control.NumericEditField
        CvDropDown1_7             matlab.ui.control.DropDown
        CvEditField1_4            matlab.ui.control.NumericEditField
        CvEditField1_1            matlab.ui.control.NumericEditField
        CvEditField1_5            matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        CvButton1_6               matlab.ui.control.StateButton
        CvLabel1_6                matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        CvButton1_8               matlab.ui.control.StateButton
        CvLabel1_8                matlab.ui.control.Label
        CvEditField_2Label        matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        CvEditField1_3            matlab.ui.control.NumericEditField
        CsEditField_2Label        matlab.ui.control.Label
        CvEditField1_9            matlab.ui.control.NumericEditField
        CsEditFieldLabel          matlab.ui.control.Label
        CvEditFieldLabel          matlab.ui.control.Label
        CsCvEditField_2Label      matlab.ui.control.Label
        CvEditField1_10           matlab.ui.control.NumericEditField
        CvEditField1_11           matlab.ui.control.NumericEditField
        CsCvEditFieldLabel        matlab.ui.control.Label
        CvEditField1_12           matlab.ui.control.NumericEditField
        CvEditField1_13           matlab.ui.control.NumericEditField
        UIAxesP3_1                matlab.ui.control.UIAxes
        Tab_Flood                 matlab.ui.container.Tab
        GridLayout20              matlab.ui.container.GridLayout
        Panel_2                   matlab.ui.container.Panel
        GridLayout22              matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        P1Tab                     matlab.ui.container.Tab
        GridLayout25              matlab.ui.container.GridLayout
        FloodUITableP1            matlab.ui.control.Table
        FloodUIAxesP1             matlab.ui.control.UIAxes
        P2Tab                     matlab.ui.container.Tab
        GridLayout25_2            matlab.ui.container.GridLayout
        FloodUITableP2            matlab.ui.control.Table
        FloodUIAxesP2             matlab.ui.control.UIAxes
        P3Tab                     matlab.ui.container.Tab
        GridLayout25_3            matlab.ui.container.GridLayout
        FloodUITableP3            matlab.ui.control.Table
        FloodUIAxesP3             matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        GridLayout21              matlab.ui.container.GridLayout
        Panel_4                   matlab.ui.container.Panel
        GridLayout24              matlab.ui.container.GridLayout
        FloodEditFieldQmd         matlab.ui.control.NumericEditField
        QmdLabel                  matlab.ui.control.Label
        FloodEditFieldW1d         matlab.ui.control.NumericEditField
        FloodEditFieldW3d         matlab.ui.control.NumericEditField
        W7dLabel                  matlab.ui.control.Label
        FloodEditFieldW7d         matlab.ui.control.NumericEditField
        W3dEditFieldLabel         matlab.ui.control.Label
        W1dEditFieldLabel         matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        GridLayout23              matlab.ui.container.GridLayout
        W_1P_1Label               matlab.ui.control.Label
        Q_mp_3Label               matlab.ui.control.Label
        Q_mp_2Label               matlab.ui.control.Label
        Q_mp_1Label               matlab.ui.control.Label
        W_1P_2Label               matlab.ui.control.Label
        W_3P_2Label               matlab.ui.control.Label
        W_7P_2Label               matlab.ui.control.Label
        W_7P_1Label               matlab.ui.control.Label
        W_1P_3Label               matlab.ui.control.Label
        W_3P_1Label               matlab.ui.control.Label
        W_3P_3Label               matlab.ui.control.Label
        W_3P_7Label               matlab.ui.control.Label
        FloodButtonP1             matlab.ui.control.StateButton
        FloodEditFieldP1          matlab.ui.control.EditField
        FloodEditFieldP1_Qm       matlab.ui.control.NumericEditField
        FloodEditFieldP1_W1       matlab.ui.control.NumericEditField
        FloodEditFieldP1_W3       matlab.ui.control.NumericEditField
        FloodEditFieldP1_W7       matlab.ui.control.NumericEditField
        FloodButtonP2             matlab.ui.control.StateButton
        FloodEditFieldP2          matlab.ui.control.EditField
        FloodEditFieldP2_Qm       matlab.ui.control.NumericEditField
        FloodEditFieldP2_W1       matlab.ui.control.NumericEditField
        FloodEditFieldP2_W3       matlab.ui.control.NumericEditField
        FloodEditFieldP2_W7       matlab.ui.control.NumericEditField
        FloodButtonP3             matlab.ui.control.StateButton
        FloodEditFieldP3          matlab.ui.control.EditField
        FloodEditFieldP3_Qm       matlab.ui.control.NumericEditField
        FloodEditFieldP3_W1       matlab.ui.control.NumericEditField
        FloodEditFieldP3_W3       matlab.ui.control.NumericEditField
        FloodEditFieldP3_W7       matlab.ui.control.NumericEditField
        Tab_rainflood             matlab.ui.container.Tab
        GridLayout30              matlab.ui.container.GridLayout
        Panel_10                  matlab.ui.container.Panel
        GridLayout35              matlab.ui.container.GridLayout
        rainfloodButton3          matlab.ui.control.StateButton
        rainfloodButton2          matlab.ui.control.StateButton
        rainfloodButton1          matlab.ui.control.StateButton
        rainfloodUITable2         matlab.ui.control.Table
        rainfloodUITable1         matlab.ui.control.Table
        rainfloodUIAxes           matlab.ui.control.UIAxes
        Panel_9                   matlab.ui.container.Panel
        GridLayout34              matlab.ui.container.GridLayout
        rainfloodButton5          matlab.ui.control.Button
        n_2EditField              matlab.ui.control.NumericEditField
        n_2EditFieldLabel         matlab.ui.control.Label
        n_1EditField              matlab.ui.control.NumericEditField
        n_1EditFieldLabel         matlab.ui.control.Label
        beta_2EditField           matlab.ui.control.NumericEditField
        beta_2EditFieldLabel      matlab.ui.control.Label
        beta_1EditField           matlab.ui.control.NumericEditField
        beta_1EditFieldLabel      matlab.ui.control.Label
        H_24mianEditField         matlab.ui.control.NumericEditField
        H_24Label                 matlab.ui.control.Label
        H_6mianEditField          matlab.ui.control.NumericEditField
        H_6Label                  matlab.ui.control.Label
        H_1mianEditField          matlab.ui.control.NumericEditField
        H_1Label                  matlab.ui.control.Label
        rainfloodUITable          matlab.ui.control.Table
        Panel_8                   matlab.ui.container.Panel
        GridLayout33              matlab.ui.container.GridLayout
        alpha_24EditField         matlab.ui.control.NumericEditField
        alpha_24EditFieldLabel    matlab.ui.control.Label
        alpha_6EditField          matlab.ui.control.NumericEditField
        alpha_6EditFieldLabel     matlab.ui.control.Label
        alpha_1EditField          matlab.ui.control.NumericEditField
        alpha_1EditFieldLabel     matlab.ui.control.Label
        Panel_7                   matlab.ui.container.Panel
        GridLayout32              matlab.ui.container.GridLayout
        H_24dianEditField         matlab.ui.control.NumericEditField
        H_24EditFieldLabel        matlab.ui.control.Label
        H_6dianEditField          matlab.ui.control.NumericEditField
        H_6EditFieldLabel         matlab.ui.control.Label
        H_1dianEditField          matlab.ui.control.NumericEditField
        H_1EditFieldLabel         matlab.ui.control.Label
        Panel_6                   matlab.ui.container.Panel
        GridLayout31              matlab.ui.container.GridLayout
        rainfloodButton4          matlab.ui.control.Button
        C_SC_VEditField           matlab.ui.control.NumericEditField
        C_SC_VEditFieldLabel      matlab.ui.control.Label
        PEditField                matlab.ui.control.NumericEditField
        PLabel                    matlab.ui.control.Label
        C_V24EditField            matlab.ui.control.NumericEditField
        C_V24EditFieldLabel       matlab.ui.control.Label
        C_V6EditField             matlab.ui.control.NumericEditField
        C_V6EditFieldLabel        matlab.ui.control.Label
        C_V1EditField             matlab.ui.control.NumericEditField
        C_V1EditFieldLabel        matlab.ui.control.Label
        overlineH_24EditField     matlab.ui.control.NumericEditField
        overlineH_24Label         matlab.ui.control.Label
        overlineH_6EditField      matlab.ui.control.NumericEditField
        overlineH_6Label          matlab.ui.control.Label
        overlineH_1EditField      matlab.ui.control.NumericEditField
        overlineH_1Label          matlab.ui.control.Label
        Tab_flood_routing         matlab.ui.container.Tab
        GridLayout36              matlab.ui.container.GridLayout
        Panel_12                  matlab.ui.container.Panel
        GridLayout38              matlab.ui.container.GridLayout
        TabGroup3                 matlab.ui.container.TabGroup
        Tab_H1                    matlab.ui.container.Tab
        GridLayout38_2            matlab.ui.container.GridLayout
        EditFieldq1               matlab.ui.control.NumericEditField
        Label_16                  matlab.ui.control.Label
        EditFieldV1               matlab.ui.control.NumericEditField
        Label_15                  matlab.ui.control.Label
        EditFieldH1               matlab.ui.control.NumericEditField
        Label_14                  matlab.ui.control.Label
        UITableH1_q               matlab.ui.control.Table
        UITableH1_Q               matlab.ui.control.Table
        UIAxesH1                  matlab.ui.control.UIAxes
        Tab_H2                    matlab.ui.container.Tab
        GridLayout38_3            matlab.ui.container.GridLayout
        EditFieldq2               matlab.ui.control.NumericEditField
        EditField_10Label         matlab.ui.control.Label
        EditFieldV2               matlab.ui.control.NumericEditField
        Label_18                  matlab.ui.control.Label
        EditFieldH2               matlab.ui.control.NumericEditField
        Label_17                  matlab.ui.control.Label
        UITableH2_q               matlab.ui.control.Table
        UITableH2_Q               matlab.ui.control.Table
        UIAxesH2                  matlab.ui.control.UIAxes
        Tab_H3                    matlab.ui.container.Tab
        GridLayout38_4            matlab.ui.container.GridLayout
        EditFieldq3               matlab.ui.control.NumericEditField
        EditField_13Label         matlab.ui.control.Label
        EditFieldV3               matlab.ui.control.NumericEditField
        Label_20                  matlab.ui.control.Label
        EditFieldH3               matlab.ui.control.NumericEditField
        Label_19                  matlab.ui.control.Label
        UITableH3_q               matlab.ui.control.Table
        UITableH3_Q               matlab.ui.control.Table
        UIAxesH3                  matlab.ui.control.UIAxes
        Panel_11                  matlab.ui.container.Panel
        GridLayout37              matlab.ui.container.GridLayout
        Label_C2A                 matlab.ui.control.Label
        nEditField                matlab.ui.control.NumericEditField
        nLabel                    matlab.ui.control.Label
        n_splineEditField         matlab.ui.control.NumericEditField
        n_splineLabel             matlab.ui.control.Label
        EditField_maxdiff_q       matlab.ui.control.NumericEditField
        Label_13                  matlab.ui.control.Label
        EditField_C2A             matlab.ui.control.NumericEditField
        EditFieldq_safedischarge  matlab.ui.control.NumericEditField
        Label_9                   matlab.ui.control.Label
        EditFieldDeltaT           matlab.ui.control.NumericEditField
        DeltaTEditFieldLabel      matlab.ui.control.Label
        EditFieldB                matlab.ui.control.NumericEditField
        BEditFieldLabel           matlab.ui.control.Label
        EditFieldM                matlab.ui.control.NumericEditField
        MEditFieldLabel           matlab.ui.control.Label
        EditFieldHspillway        matlab.ui.control.NumericEditField
        EditFieldH0               matlab.ui.control.NumericEditField
        Label_8                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        UITableZ_V                matlab.ui.control.Table
        ContextMenu1_1            matlab.ui.container.ContextMenu
        Menu1_1_1                 matlab.ui.container.Menu
        Menu_20                   matlab.ui.container.Menu
        ContextMenu2_1_1          matlab.ui.container.ContextMenu
        Menu2_1_1_1               matlab.ui.container.Menu
        ContextMenu2_1_2          matlab.ui.container.ContextMenu
        Menu2_1_2_1               matlab.ui.container.Menu
        Menu2_1_2_2               matlab.ui.container.Menu
        ContextMenu1_2_2          matlab.ui.container.ContextMenu
        Menu1_2_2                 matlab.ui.container.Menu
        ContextMenu2_1_3          matlab.ui.container.ContextMenu
        Menu2_1_3_1               matlab.ui.container.Menu
        Menu2_1_3_2               matlab.ui.container.Menu
        Menu_2_1_3_2_1            matlab.ui.container.Menu
        Menu_2_1_3_2_2            matlab.ui.container.Menu
        ContextMenu1_2_1          matlab.ui.container.ContextMenu
        Menu1_2_1_1               matlab.ui.container.Menu
        Menu_21                   matlab.ui.container.Menu
        ContextMenu1_2_3          matlab.ui.container.ContextMenu
        Menu1_2_3_9               matlab.ui.container.Menu
        ContextMenu3_3_1          matlab.ui.container.ContextMenu
        Menu_3                    matlab.ui.container.Menu
        Menu_4                    matlab.ui.container.Menu
        Menu_24                   matlab.ui.container.Menu
        Menu_25                   matlab.ui.container.Menu
        ContextMenu3_3_2          matlab.ui.container.ContextMenu
        Menu_5                    matlab.ui.container.Menu
        Menu_6                    matlab.ui.container.Menu
        Menu_26                   matlab.ui.container.Menu
        Menu_27                   matlab.ui.container.Menu
        ContextMenu4              matlab.ui.container.ContextMenu
        Menu_7                    matlab.ui.container.Menu
        Menu_8                    matlab.ui.container.Menu
        Menu_28                   matlab.ui.container.Menu
        Menu_29                   matlab.ui.container.Menu
        ContextMenu5              matlab.ui.container.ContextMenu
        Menu_9                    matlab.ui.container.Menu
        ContextMenu6              matlab.ui.container.ContextMenu
        Menu_10                   matlab.ui.container.Menu
        ContextMenu7              matlab.ui.container.ContextMenu
        Menu_11                   matlab.ui.container.Menu
        ContextMenu8              matlab.ui.container.ContextMenu
        Menu_12                   matlab.ui.container.Menu
        ContextMenu9              matlab.ui.container.ContextMenu
        Menu_13                   matlab.ui.container.Menu
        ContextMenu10             matlab.ui.container.ContextMenu
        Menu_14                   matlab.ui.container.Menu
        Menu_17                   matlab.ui.container.Menu
        ContextMenu11             matlab.ui.container.ContextMenu
        Menu_15                   matlab.ui.container.Menu
        Menu_18                   matlab.ui.container.Menu
        ContextMenu12             matlab.ui.container.ContextMenu
        Menu_16                   matlab.ui.container.Menu
        Menu_19                   matlab.ui.container.Menu
        ContextMenu13             matlab.ui.container.ContextMenu
        Menu_22                   matlab.ui.container.Menu
        ContextMenu14             matlab.ui.container.ContextMenu
        Menu_23                   matlab.ui.container.Menu
    end


    properties (Access = public)
        %         DataUItable2_2_timetable % Tab_Data中第2个panel中的第2个tab
        %         DataUItable2_3_timetable % Tab_Data中第2个panel中的第3个tab
        % Tab1 导入数据
        Dataindex_choose % 记录选择的数据范围
        DataUItable_choose % 记录选择的哪个UItable
        DataFlag_choose = false % 记录写入开关(需要选择数据写入的时候才是 true)
        DataUItable_write % 记录写入哪个UItable

        % Tab2 经验适线
        H1 % 适线的 H1 line的句柄
        H2 % 适线的 H2 line的句柄
        H3 % 适线的 H3 line的句柄
        H4 % 适线的 H4 line的句柄

        % Tab3 （流量）设计洪水
        DataMaxday1 % 记录典型洪水中最大一天洪水的时间
        DataMaxday3 % 记录典型洪水中最大三天洪水的时间
        DataMaxday7 % 记录典型洪水中最大七天洪水的时间

        % Tab4 （暴雨）设计洪水
        H5 % 流域出口设计洪水的句柄
        H6 % 水库相应洪水的句柄
        H7 % 区间设计洪水的句柄
        PUItable_choose % 记录选择哪个概率的流域出口设计洪水
        PFlag_choose % 记录写入开关(需要选择数据写入的时候才是 true)

        % Tab5 调洪计算
        FloodUItable_choose % 记录选择填入哪个洪水UItable
        FloodFlag_choose1 % 记录写入开关(需要选择数据写入的时候才是 true)
        FloodFlag_choose2 % 记录写入开关(需要选择数据写入的时候才是 true)
        FloodFlag_choose3 % 记录写入开关(需要选择数据写入的时候才是 true)

        H1_q % 记录防洪高水位调洪演算时候的水库下泄q变化数据
        H1_H % 记录防洪高水位调洪演算时候的水库水位H变化数据
        H1_V % 记录防洪高水位调洪演算时候的水库库容V变化数据
        H2_q % 记录设计洪水位调洪演算时候的水库下泄q变化数据
        H2_H % 记录设计洪水位调洪演算时候的水库水位H变化数据
        H2_V % 记录设计洪水位调洪演算时候的水库库容V变化数据
        H3_q % 记录校核洪水位调洪演算时候的水库下泄q变化数据
        H3_H % 记录校核洪水位调洪演算时候的水库水位H变化数据
        H3_V % 记录校核洪水位调洪演算时候的水库库容V变化数据

    end

    methods (Access = public)
        % 选择并写入数据函数
        function func_choose2writeData(app)

            try
                temp_col = app.Dataindex_choose(1,2);
                temp_row = app.Dataindex_choose(:,1);
            catch
                errordlg('你选择无效数据','选择数据失败')
                app.Dataindex_choose = [];
                app.DataFlag_choose = false;
                return
            end
            if all(app.Dataindex_choose(:,2) == temp_col)
                app.DataUItable_write.Data = app.DataUItable_choose.Data(temp_row,[1,temp_col])';
                app.DataUItable_write.RowName = app.DataUItable_choose.ColumnName([1,temp_col]);
                %                 % 改变表格格式ColumnWidth设置为40
                %                 n = length(app.DataUItable_write.Data);
                %                 temp_cell = cell(1,n);
                %                 addColumnWidth = 40;
                %                 for i=1:n
                %                     temp_cell(i) = {addColumnWidth};
                %                 end
                %                 app.DataUItable_write.ColumnWidth = temp_cell;
                app.Dataindex_choose = [];
                app.DataFlag_choose = false;
            else
                errordlg('你选择的数据超过一列','选择数据失败')
                app.Dataindex_choose = [];
                app.DataFlag_choose = false;
            end
        end

        function func_choose2plotData(app,inputTable,outputAxes,outputTab)
            try
                temp_col = app.Dataindex_choose(1,2);
                temp_row = app.Dataindex_choose(:,1);
            catch
                return
            end

            if all(app.Dataindex_choose(:,2) == temp_col)
                if temp_col == 1
                    return
                end
                %                 app.DataUItable_write.Data = app.DataUItable_choose.Data(temp_row,[1,temp_col])';
                %                 app.DataUItable_write.RowName =app.DataUItable_choose.ColumnName([1,temp_col]);
                delete(outputAxes.Children)

                if outputAxes == app.UIAxesData1
                    plot(outputAxes,inputTable.Data(temp_row,1),inputTable.Data(temp_row,temp_col),'-k.');
                else
                    if inputTable == app.UITableData2_1
                        plot(outputAxes,cell2mat(inputTable.Data(temp_row,1)),cell2mat(inputTable.Data(temp_row,temp_col)),'-k.')
                        app.Dataindex_choose = [];
                    else
                        plot(outputAxes,inputTable.Data.Time(temp_row,1),inputTable.Data.Var1(temp_row,temp_col-1),'-k.');
                        app.Dataindex_choose = [];
                    end
                end
                %                 a = inputTable.Data(temp_row,1);
                %                 b = inputTable.Data(temp_row,temp_col);
                %                 app.UITableData2_2.Data
                app.TabGroupData_3.SelectedTab = outputTab;
                outputAxes.XLabel.String = inputTable.ColumnName{1};
                outputAxes.YLabel.String = inputTable.ColumnName{temp_col};
            end
        end

        % 清空表格函数
        function func_cleartable(~,app_table)
            app_table.Data = [];
            app_table.RowName =[];
            app_table.ColumnName = [];
            app_table.ColumnWidth = 'auto';
        end

        function results = func_Discontinues_Pm(~,app_observedtable,app_historytable,switch_num)
            observed_data = app_observedtable.Data;
            history_data = app_historytable.Data;
            % 计算a
            a = length(history_data); % N年中有a个特大值
            % 组合调查期，实测期数据
            year = [history_data(1,:) observed_data(1,:)];
            value = [history_data(2,:) observed_data(2,:)];
            % 按value从大到小排列
            [value,temp_index]=sort(value,'descend');
            year=year(temp_index);
            % 计算N
            N=max(year)-min(year)+1; % 最大年份与最小年份之差（注意不是最大洪水年份到最小洪水年份之差）

            % 注意：要求实测洪水年是连续的，一般都能满足，要不然还叫什么连续洪水资料
            % 注意：所以此处就默认按照一般情况(实测洪水年是连续的)处理（如不连续下面计算 n,l 方法出错）

            % 计算n
            min_observed_year=min(observed_data(1,:));
            max_observed_year=max(observed_data(1,:));
            n=max_observed_year-min_observed_year+1; % 实测系列年数
            % 计算 l (特大洪水中来自于实测期的个数)
            l=0; %初始化 l
            for i=1:a
                if history_data(1,i)>=min_observed_year && history_data(1,i) <= max_observed_year
                    l=l+1; % 来自于实测资料的特大洪水年个数
                end
            end
            % 知道N,n,a,l就可以频率计算了
            switch switch_num
                case 1 % 统一处理法
                    Pm = zeros(1,n+a-l);
                    Pm(1:a) = (1:a)/(N+1); % 公式2-7 水文水利计算p25
                    Pm(a+1:end) = Pm(a)+(1-Pm(a))*((l+1:n)-l)/(n-l+1); % 公式2-9 水文水利计算p25
                case 2 % 分别处理法
                    Pm=zeros(1,n+a-l);
                    Pm(1:a)=(1:a)/(N+1); % 公式2-7 水文水利计算p25
                    Pm(a+1:end)=(l+1:n)/(n+1); % 公式2-10 水文水利计算p25
                otherwise
                    errordlg("错误的switch_num")
            end
            results = [value;Pm*100];
        end


        function H = func_plot_Hpg(~,app_ax,P,y,line_style)

            new_P = norminv(P/100,0,1);
            Up_01 = norminv(0.01/100,0,1);
            new_P = new_P-Up_01;
            H = plot(app_ax,new_P,y,line_style);
        end

        function [new_Y,fit] = func_getnew_Y_fit(app,Y,P,Cv,Cs,switch_fun)

            Y_bar = mean(Y);
            phi_p = Cs/2*gaminv((1-P/100),4/Cs^2,1)-2/Cs;
            new_Y = Y_bar*(1+Cv*phi_p);
            if nargin == 6
                fit = obj_function(app,Y,new_Y,switch_fun); % 用选定的目标函数求误差
            else
                fit = NaN;
            end
        end


        function ERR = obj_function(~,y,new_y,switch_fun)
            switch switch_fun
                case 1
                    % ERR = (y-new_y)^2 OLS
                    ERR = sum((y-new_y).^2);
                case 2
                    % ERR = |y-new_y|  ABS
                    ERR = sum(abs(y-new_y));
                case 3
                    % ERR = (y-new_y)^2/y WLS
                    ERR = sum((y-new_y).^2./y);
                case 4
                    % ERR = |y-new_y|/y 一次 相对误差
                    ERR = sum(abs(y-new_y)./y);
                otherwise
                    errordlg("目标函数选择出现溢出")
            end
        end


        function [Cv,Cs,g_best] = func_P3CvCs(app,peak,P)
            %% 粒子群算法PSO
            %% 初始化
            N = 100;     % 种群中粒子个数f
            D = 2;       % 粒子维数
            T = 200;     % 最大迭代次数
            c1 = 1.5;    % 学习因子1
            c2 = 1.5;    % 学习因子2
            Wmax = 2;    % 惯性权重
            Wmin = 0.4;
            Xmax = [4 10];   % 位置最大值
            Xmin = [0 0];    % 位置最小值
            Vmax = 0.1*(Xmax-Xmin);   % 速度最大值
            Vmin = -Vmax;             % 速度最小值

            %% 初始化种群个体（位置和速度）
            x = rand(N,D).*(Xmax-Xmin)+Xmin;
            v = rand(N,D).*(Vmax-Vmin)+Vmin;

            % 记录种群中个体历史最优位置，因为所有个体目前只有初始位置x
            p = x;
            % 记录种群个体最优位置对应的 fit 值
            p_best = zeros(N,1); % 开辟空间
            for i = 1:N
                Cv = p(i,1);
                Cs = p(i,2);
                %                 p_best(i) = fit_fun(peak,P,Cv,Cs);
                [~,p_best(i)] = func_getnew_Y_fit(app,peak,P,Cv,Cs,eval(app.CsDropDown1_7.Value));
            end

            g = zeros(1,D); % 开辟空间
            g_best = inf;   % 开辟空间
            for i = 1:N
                if p_best(i) < g_best
                    g = p(i,:); % 记录更好的位置
                    g_best = p_best(i); % 记录更好位置对应的fit值
                end
            end

            g_T = zeros(T,D);     % 开辟空间，记录不同迭代次数T的g
            gbest_T = zeros(T,1); % 开辟空间，记录不同迭代次数T的g_best

            for i = 1:T
                w = Wmax-(Wmax-Wmin)*i/T;
                for j =1:N
                    % 更新个体最优位置和最优值
                    %                     temp = fit_fun(peak,P,x(j,1),x(j,2));
                    [~,temp] = func_getnew_Y_fit(app,peak,P,x(j,1),x(j,2),eval(app.CsDropDown1_7.Value));
                    if temp < p_best(j)
                        p(j,:) = x(j,:);
                        p_best(j) = temp;
                        % 更新全局最优位置和最优值
                        if temp < g_best
                            g = p(j,:);
                            g_best = temp;
                        end
                    end
                    % 更新位置和速度
                    v(j,:) = w*v(j,:)+c1*rand*(p(j,:)-x(j,:))+c2*rand*(g-x(j,:));
                    for k = 1:D
                        if v(j,k) > Vmax(k)
                            v(j,k) = Vmax(k);
                        elseif v(j,k) < Vmin(k)
                            v(j,k) = Vmin(k);
                        end
                    end

                    x(j,:) = x(j,:)+v(j,:);
                    for k = 1:D
                        if x(j,k) > Xmax(k)
                            x(j,k) = Xmax(k);
                        elseif x(j,k) < Xmin(k)
                            x(j,k) = Xmin(k);
                        end
                    end
                end
                g_T(i,:) = g;
                gbest_T(i) = g_best;
            end
            Cv = g(1);
            Cs = g(2);
        end
        function [Cv,Cs,g_best] = func_P3Cv(app,peak,P)

            CsdivCv = app.CvEditField1_13.Value;

            %% 粒子群算法PSO
            %% 初始化
            N = 100;    % 种群中粒子个数f
            D = 1;      % 粒子维数
            T = 200;    % 最大迭代次数
            c1 = 1.5;   % 学习因子1
            c2 = 1.5;   % 学习因子2
            Wmax = 2;    % 惯性权重
            Wmin = 0.4;
            Xmax = 4;   % 位置最大值
            Xmin = 0;   % 位置最小值
            Vmax = 0.1*(Xmax-Xmin);   % 速度最大值
            Vmin = -Vmax;             % 速度最小值

            %% 初始化种群个体（位置和速度）
            x = rand(N,D)*(Xmax-Xmin)+Xmin;
            v = rand(N,D)*(Vmax-Vmin)+Vmin;

            % 记录种群中个体历史最优位置，因为所有个体目前只有初始位置x
            p = x;
            % 记录种群个体最优位置对应的 fit 值
            p_best = zeros(N,1); % 开辟空间
            for i = 1:N
                Cv = p(i);
                Cs = CsdivCv*Cv;
                %                 p_best(i) = fit_fun(peak,P,Cv,Cs);
                [~,p_best(i)] = func_getnew_Y_fit(app,peak,P,Cv,Cs,eval(app.CvDropDown1_7.Value));
            end

            g = zeros(1,D); % 开辟空间
            g_best = inf; % 开辟空间
            for i = 1:N
                if p_best(i) < g_best
                    g = p(i,:); % 记录更好的位置
                    g_best = p_best(i); % 记录更好位置对应的fit值
                end
            end

            gbest_T = zeros(1,T); % 开辟空间，记录不同迭代次数T的gbest

            for i = 1:T
                w = Wmax-(Wmax-Wmin)*i/T;
                for j =1:N
                    % 更新个体最优位置和最优值
                    %                     temp = fit_fun(peak,P,x(j),CsdivCv*x(j));
                    [~,temp] = func_getnew_Y_fit(app,peak,P,x(j),CsdivCv*x(j),eval(app.CsDropDown1_7.Value));
                    if temp < p_best(j)
                        p(j,:) = x(j,:);
                        p_best(j) = temp;
                        % 更新全局最优位置和最优值
                        if temp < g_best
                            g = p(j,:);
                            g_best = temp;
                        end
                    end
                    % 更新位置和速度

                    v(j,:) = w*v(j,:)+c1*rand*(p(j,:)-x(j,:))+c2*rand*(g-x(j,:));

                    if v(j,:) > Vmax
                        v(j,:) = Vmax;
                    elseif v(j,:) < Vmin
                        v(j,:) = Vmin;
                    end

                    x(j,:) = x(j,:)+v(j,:);
                    if x(j,:) > Xmax
                        x(j,:) = Xmax;
                    elseif x(j,:) < Xmin
                        x(j,:) = Xmin;
                    end
                end
                gbest_T(i) = g_best;
            end
            Cv =  g;
            Cs = g*CsdivCv;
        end


        function [MaxWd,Maxdaytime] = func_getMaxWd(app,minute)
            x = app.UITableData2_3.Data.Time;
            y = app.UITableData2_3.Data.Var1;
            MaxWd = 0;
            Maxdaytime = [];
            for i=1:1:length(x)-minute
                temp = func_getArea(app,y,i,i+minute);
                if MaxWd < temp
                    MaxWd = temp;
                    Maxdaytime = i:i+minute;
                    Maxdaytime = Maxdaytime';
                end
            end
        end

        function sumArea = func_getArea(~,y,start_num,end_num)
            sumArea = 0;
            for i = start_num:end_num-1
                sumArea = sumArea + (y(i)+y(i+1))/2*60*60;
            end
        end


        function func_enlarge_samefrequency(app,Qm_Value,W1_Value,W3_Value,W7_Value,outputTable,outputAxes)
            Qmd = app.FloodEditFieldQmd.Value;
            W1d = app.FloodEditFieldW1d.Value;
            W3d = app.FloodEditFieldW3d.Value;
            W7d = app.FloodEditFieldW7d.Value;

            Qmp = Qm_Value;
            W1p = W1_Value;
            W3p = W3_Value;
            W7p = W7_Value;

            Time = app.UITableData2_3.Data.Time;
            Var1 = app.UITableData2_3.Data.Var1;
            Var2 = ones(length(Var1),1);

            kq = Qmp/Qmd;
            [~,indexkq] = max(Var1);
            Var1(indexkq) = Var1(indexkq)*kq;
            Var2(indexkq) = Var2(indexkq)*kq;

            k1 = W1p/W1d;
            temp_index = ismember(app.DataMaxday1,indexkq);
            indexk1 = app.DataMaxday1(~temp_index);
            Var1(indexk1) = Var1(indexk1)*k1;
            Var2(indexk1) = Var2(indexk1)*k1;

            if W1d < W3d
                k3_1 = (W3p-W1p)/(W3d-W1d);
                temp_index = ismember(app.DataMaxday3,app.DataMaxday1);
                indexk3_1 = app.DataMaxday3(~temp_index);
                Var1(indexk3_1) = Var1(indexk3_1)*k3_1;
                Var2(indexk3_1) = Var2(indexk3_1)*k3_1;
            end
            if W3d < W7d
                k7_3 = (W7p-W3p)/(W7d-W3d);
                temp_index = ismember(app.DataMaxday7,app.DataMaxday3);
                indexk7_3 = app.DataMaxday7(~temp_index);
                Var1(indexk7_3) = Var1(indexk7_3)*k7_3;
                Var2(indexk7_3) = Var2(indexk7_3)*k7_3;
            end
            temp_timetable = timetable(Time,Var1,Var2);
            outputTable.Data = timetable2table(temp_timetable);
            outputTable.ColumnName = {'时间','设计洪水过程','放大倍比'};
            plot(outputAxes,outputTable.Data.Time,outputTable.Data.Var1,'.-');
            outputAxes.Title = [];
            outputAxes.XLabel.String = app.UITableData2_3.ColumnName{1};
            outputAxes.YLabel.String = app.UITableData2_3.ColumnName{2};
        end

        % 试算法
        function [q2,H2] = func_trialcalculation(~,Q1,Q2,q1,delta_T,V1,Z_V,H0,B,M)
            % 因为相邻时段的下泄流量数值关系紧密，所以以q1为试算的初始值有一定的依据
            q2_1 = q1;
            while true
                allowed_deviation = q2_1*0.0001;
                % 水量平衡计算V2
                V2 = (Q1+Q2-q1-q2_1)/2*delta_T+V1;
                % 用试算的V2插值出H2
                H2 = spline(Z_V(2,:),Z_V(1,:),V2);
                % 代入溢洪道泄流计算公式求得q2_2
                q2_2 = M*B*(H2-H0)^(1.5);
                if abs(q2_1-q2_2) > allowed_deviation
                    % 继续试算
                    q2_1 = q2_2;
                else
                    q2 = (q2_1+q2_2)/2;
                    break
                end
            end
        end


        % excel 表格保存n
        function func_save_excel_n(~,sheetname,callingTable)
            if isempty(callingTable.Data)
                errordlg('选择的保存的数据为空，无法保存','保存失败')
                return
            else
                if  istable(callingTable.Data)
                    temp_table = callingTable.Data;
                    if ~isempty(callingTable.ColumnName)
                        temp_table.Properties.VariableNames = callingTable.ColumnName;
                    end
                else
                    if isempty(callingTable.ColumnName)
                        callingTable.ColumnName = num2cell(1:size(callingTable.Data,2));
                        temp_table = array2table(callingTable.Data,'VariableNames',callingTable.ColumnName,'RowNames',callingTable.RowName);
                        callingTable.ColumnName = [];
                    else
                        temp_table = array2table(callingTable.Data,'VariableNames',callingTable.ColumnName,'RowNames',callingTable.RowName);
                    end
                end


            end

            dname =  uigetdir('..\','选择一个文件夹作为新建Excel保存的位置');
            filename =  sprintf('%s\\%s.xlsx',dname,sheetname);
            if dname == 0
                errordlg('已取消保存','保存失败')
            else
                %                 if temp_table.
                try
                    writetable(temp_table,filename,'Sheet',sheetname,'WriteRowNames',true)
                    msgbox(['导出数据的位置为：',filename],'保存成功')
                catch
                    errordlg('无法写入，你选择保存文件夹处有与将要新建 Excel 同名的 Excel 文件，并且该同名的 Excel 正处于打开的状态','保存失败')
                end
            end
        end

        % excel 表格保存s
        function func_save_excel_s(~,sheetname,callingTable)

            if isempty(callingTable.Data)
                errordlg('选择的保存的数据为空，无法保存','保存失败')
                return
            else
                if  istable(callingTable.Data)
                    temp_table = callingTable.Data;
                    if ~isempty(callingTable.ColumnName)
                        temp_table.Properties.VariableNames = callingTable.ColumnName;
                    end
                else

                    if isempty(callingTable.ColumnName)
                        callingTable.ColumnName = num2cell(1:size(callingTable.Data,2));
                        temp_table = array2table(callingTable.Data,'VariableNames',callingTable.ColumnName,'RowNames',callingTable.RowName);
                        callingTable.ColumnName = [];
                    else
                        temp_table = array2table(callingTable.Data,'VariableNames',callingTable.ColumnName,'RowNames',callingTable.RowName);
                    end
                end
            end

            [file,path] =  uigetfile({'*.xls;*.xlsx'},'选择一个Excel文件作为导出数据保存的位置');
            filename =  [path,file];
            if file == 0
                errordlg('已取消保存数据','保存失败')
            else
                try
                    writetable(temp_table,filename,'Sheet',sheetname,'WriteRowNames',true)
                    msgbox(['导出数据的位置为：',filename],'保存成功')
                catch
                    errordlg('无法写入，你选择的文件正处于打开的状态或你选择的不是 Excel 表格文件','保存失败')
                end
            end
        end

        function func_test(~)
            msgbox('hello world')
        end
        
        function func_enlarge_samemultiple(app,Q_Wmp,Q_Wmd,outputTable,outputAxes)   
             try
                 k = Q_Wmp/Q_Wmd;
             catch
                 errordlg('峰比计算结果有问题','计算失败')
                 return
             end

             try
                 Time = app.UITableData2_3.Data.Time;
                 Var1 = app.UITableData2_3.Data.Var1;
                 Var1 = Var1*k;
                 Var2 = ones(length(Var1),1)*k;       
            catch
                errordlg('插值为等时段数据表为空或此表数据中存在问题','计算失败')
             end

            temp_timetable = timetable(Time,Var1,Var2);
            outputTable.Data = timetable2table(temp_timetable);
            outputTable.ColumnName = {'时间','设计洪水过程','放大倍比'};
            plot(outputAxes,outputTable.Data.Time,outputTable.Data.Var1,'.-');
            outputAxes.Title = [];
            outputAxes.XLabel.String = app.UITableData2_3.ColumnName{1};
            outputAxes.YLabel.String = app.UITableData2_3.ColumnName{2};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 功能：程序启动时，自动赋值或调用
            %             app.DataFlag_choose = false; % 初始选择数据flag为false

            %             Menu1_1Selected(app, 'true');
            %             Menu1_1_1Selected(app, 'on');
            %
            %             app.UITableP3_1_1.Data = app.UITableData1_2.Data(:,[1,2])';
            %             app.UITableP3_1_1.RowName = app.UITableData1_2.ColumnName([1,2]);
            %             Menu2_1_2_1Selected(app,'on');
            % 调整app.UIFIgure居中
            scnsize = get(0,'ScreenSize');
            appsize = app.UIFigure.Position;
            app.UIFigure.Position(1) = scnsize(3)/2 - appsize(3)/2;
            app.UIFigure.Position(2) = scnsize(4)/2 - appsize(4)/2;
        end

        % Menu selected function: Menu1_1
        function Menu1_1Selected(app, event)
            % 功能: 一键加载课设数据
            % 位置：菜单栏第一个选项
            % 激活：菜单栏选项(关联位置处 鼠标右键)
            login(app);
        end

        % Menu selected function: Menu1_1_1
        function Menu1_1_1Selected(app, event)
            % 功能：进行相关性分析法插补数据（使用1日与3日的相关关系）
            % 位置：Tab Data中panel_1中的表1
            % 激活：上下文菜单选项(关联位置处 鼠标右键)


            try
                temp =  app.UITableData1_1.Data;
                p = polyfit(temp(:,2),temp(:,4),1);
                temp_index = find(temp(:,4)==-1);
                temp(temp_index,4) = round(polyval(p,temp(temp_index,2)));
                app.UITableData1_2.ColumnName = app.UITableData1_1.ColumnName;
                app.UITableData1_2.Data = temp;
                % 跳转 Data模块 Panel1中表2 数据的插补延展
                app.TabGroupData_1.SelectedTab = app.TabData1_2;
            catch
                errordlg('导入洪水特征统计数据表为空或此表数据中存在问题','插补数据失败')
            end
        end

        % Callback function
        function Menu_tableData2_2_1Selected(app, event)
            % 无用
            %             temp_MD = app.UITableData2_1.Data(:,1);
            %             temp_HM = app.UITableData2_1.Data(:,2);
            %             format = 'M月d日 HH:mm';
            %             %             temp_table =
            %             % %             temp_table = zeros(length(temp_MD),1);
            %             for i=1:length(temp_MD)
            %                 temp_md = string(temp_MD{i});
            %                 temp_hm = string(temp_HM{i});
            %                 if floor(temp_HM{i}) == double(temp_HM{i})
            %                     temp_hm = strcat(" ",temp_hm);
            %                     date = strcat(temp_md,temp_hm);
            %                     infmt = 'M.d H';
            %                     temp_table(i) = datetime(date,...
            %                         "InputFormat",infmt,"Format",format);
            %                 else
            %                     temp_index = strfind(temp_hm,'.');
            %                     integer_part = temp_hm{1}(1:temp_index-1);
            %                     decimal_part = temp_hm{1}(temp_index+1:end);
            %                     temp_m = floor(60/10^length(decimal_part)*eval(decimal_part));
            %                     temp_m = sprintf('%02d',temp_m);
            %                     temp_h = sprintf('%02c',integer_part);
            %                     infmt = 'M.d HH:mm';
            %                     date = strcat(temp_md," ",temp_h,':',temp_m);
            %                     temp_table(i) = datetime(date,...
            %                         "InputFormat",infmt,"Format",format);
            %                 end
            %             end
            %             app.UItable2_2_timetable = timetable(temp_table', ...
            %                 cell2mat(app.UITableData2_1.Data(:,3)));
            %             app.UITableData2_2.Data = timetable2table(app.UItable2_2_timetable);
            %

        end

        % Menu selected function: Menu2_1_2_1
        function Menu2_1_2_1Selected(app, event)
            % 功能：直接添加调查考证期资料（写死了）
            % 位置：Tab P3中panel_1中tab_1中 表2(调查考证期XX资料)
            % 激活：上下文菜单选项(关联位置处 鼠标右键)
            try
                app.UITableP3_1_2.Data = app.UITableData4_1.Data';
                app.UITableP3_1_2.ColumnName = [];
                app.UITableP3_1_2.RowName = app.UITableData4_1.ColumnName([1,2]);
                n = length(app.UITableP3_1_2.Data);
                temp_cell = cell(1,n);
                addColumnWidth = 58.5;
                for i=1:n
                    temp_cell(i) = {addColumnWidth};
                end
                app.UITableP3_1_2.ColumnWidth = temp_cell;
            catch
                errordlg("历史洪水资料表为空",'添加失败')
            end
        end

        % Cell selection callback: UITableData1_2
        function UITableData1_2CellSelection(app, event)
            % 功能：鼠标选择'UITableData1_2'的数据，返回index（选择数据）
            % 位置：'UITableData1_2' Tab Data中panel_1 表2
            % 激活：不需要主动激活，调用的子程序，配合选择添加实测资料函数
            app.Dataindex_choose = event.Indices;
            app.DataUItable_choose = app.UITableData1_2;
        end

        % Menu selected function: Menu2_1_1_1
        function Menu2_1_1_1Selected(app, event)
            % 功能：鼠标选择并添加实测资料（写活了）（选择数据开始的标志）
            % 位置：Tab P3中panel_1中tab_1中 表1(实测期资料)
            % 激活：上下文菜单选项(关联位置处 鼠标右键)
            app.DataUItable_choose = []; %清空数据
            app.TabGroup1.SelectedTab = app.Tab_Data;
            app.DataFlag_choose = true;
            app.DataUItable_write = app.UITableP3_1_1; %传出要写写入的位置信息
        end

        % Cell selection callback: UITableData1_1
        function UITableData1_1CellSelection(app, event)
            % 功能：鼠标选择'UITableData1_1'的数据，返回index（选择数据）
            % 位置：'UITableData1_1' Tab Data中panel_1 表1
            % 激活：不需要主动激活，调用的子程序，配合选择添加实测资料函数
            app.Dataindex_choose = event.Indices;
            app.DataUItable_choose = app.UITableData1_1;
            %             plot()
        end

        % Button down function: Tab_P3
        function Tab_P3ButtonDown(app, event)
            % 功能：按下Tab P3后，如果Flag == true时候，代表数据选择完毕（选择数据完成的标志）
            % 位置：Tab P3
            % 激活：不需要主动激活，调用的子程序，配合选择添加实测资料函数
            if app.DataFlag_choose
                app.TabGroup1.SelectedTab = app.Tab_P3;
                func_choose2writeData(app)
            end
        end

        % Menu selected function: Menu2_1_2_2
        function Menu2_1_2_2Selected(app, event)
            % 功能：清空调查考证资料
            % 位置：Tab P3 panel_1中tab_1中 表2(调查考证期资料)
            func_cleartable(app,app.UITableP3_1_2);
        end

        % Menu selected function: Menu2_1_3_1
        function Menu2_1_3_1Selected(app, event)
            % 功能：频率计算——连续系列
            % 位置：Tab P3中panel_1中tab_1中 表3(频率计算结果)
            % 激活：上下文菜单选项(关联位置处 鼠标右键)
            try
                n = length(app.UITableP3_1_1.Data);
                value = sort(app.UITableP3_1_1.Data(2,:),'descend');
                Pm = (1:n)/(n+1);
                app.UITableP3_1_3.Data = [value;Pm*100];
                app.UITableP3_1_3.RowName = {app.UITableP3_1_1.RowName{2},'概率(%)'};
                % 在海森机率格纸上点绘经验频率
                Menu2_2_1_1Selected(app, 'on')
            catch
                errordlg('实测期资料表为空或此表数据中存在问题','计算失败')
            end
        end

        % Menu selected function: Menu_2_1_3_2_1
        function Menu_2_1_3_2_1Selected(app, event)
            % 功能：频率计算——非连续系列——统一处理法
            % 位置：Tab P3中panel_1中tab_1中 表3(频率计算结果)
            % 激活：上下文菜单选项(关联位置处 鼠标右键)

            %             history_data = app.UITableP3_1_2.Data;
            %             observed_data = app.UITableP3_1_1.Data;
            %             % 计算a
            %             a = length(history_data); % N年中有a个特大值
            %             % 组合调查期，实测期数据
            %             year = [history_data(1,:) observed_data(1,:)];
            %             value = [history_data(2,:) observed_data(2,:)];
            %             % 按value从大到小排列
            %             [value,temp_index]=sort(value,'descend');
            %             year=year(temp_index);
            %             % 计算N
            %             N=max(year)-min(year)+1; % 最大年份与最小年份之差（注意不是最大洪水年份到最小洪水年份之差）
            %
            %             % 注意：要求实测洪水年是连续的，一般都能满足，要不然还叫什么连续洪水资料
            %             % 注意：所以此处就默认按照一般情况(实测洪水年是连续的)处理（如不连续下面计算 n,l 方法出错）
            %
            %             % 计算n
            %             min_observed_year=min(observed_data(1,:));
            %             max_observed_year=max(observed_data(1,:));
            %             n=max_observed_year-min_observed_year+1; % 实测系列年数
            %             % 计算 l (特大洪水中来自于实测期的个数)
            %             l=0; %初始化 l
            %             for i=1:a
            %                 if history_data(1,i)>=min_observed_year && history_data(1,i) <= max_observed_year
            %                     l=l+1; % 来自于实测资料的特大洪水年个数
            %                 end
            %             end
            %             % 知道N,n,a,l就可以频率计算了
            %             Pm = zeros(1,n+a-l);
            %             Pm(1:a) = (1:a)/(N+1); % 公式2-7 水文水利计算p25
            %             Pm(a+1:end) = Pm(a)+(1-Pm(a))*((l+1:n)-l)/(n-l+1); % 公式2-9 水文水利计算p25
            %             app.UITableP3_1_3.Data = [value;Pm*100];
            %             app.UITableP3_1_3.RowName = {app.UITableP3_1_1.RowName{2},'概率(%)'};
            try
                swtich_num = 1;% 统一处理法
                app.UITableP3_1_3.Data = app.func_Discontinues_Pm(app.UITableP3_1_1,app.UITableP3_1_2,swtich_num);
                app.UITableP3_1_3.RowName = {app.UITableP3_1_1.RowName{2},'概率(%)'};

                % 在海森机率格纸上点绘经验频率
                Menu2_2_1_1Selected(app, 'on')
            catch
                errordlg('实测期资料表或调查考证期资料表为空或此表数据中存在问题','计算失败')
            end
        end

        % Menu selected function: Menu_2_1_3_2_2
        function Menu_2_1_3_2_2Selected(app, event)
            % 功能：频率计算——非连续系列——统一处理法
            % 位置：Tab P3中panel_1中tab_1中 表3(频率计算结果)
            % 激活：上下文菜单选项(关联位置处 鼠标右键)
            try
                swtich_num = 2;% 分别处理法
                app.UITableP3_1_3.Data = app.func_Discontinues_Pm(app.UITableP3_1_1,app.UITableP3_1_2,swtich_num);
                app.UITableP3_1_3.RowName = {app.UITableP3_1_1.RowName{2},'概率(%)'};

                % 在海森机率格纸上点绘经验频率
                Menu2_2_1_1Selected(app, 'on')
            catch
                errordlg('实测期资料表或调查考证期资料表为空或此表数据中存在问题','计算失败')
            end
        end

        % Value changed function: CsEditField1_1
        function CsEditField1_1ValueChanged(app, event)

            app.CsEditField1_3.Value = app.CsEditField1_2.Value/app.CsEditField1_1.Value;
            CsButton1_6ValueChanged(app, event)

        end

        % Value changed function: CsEditField1_2
        function CsEditField1_2ValueChanged(app, event)

            app.CsEditField1_3.Value = app.CsEditField1_2.Value/app.CsEditField1_1.Value;
            CsButton1_6ValueChanged(app, event)
        end

        % Value changed function: CsButton1_8
        function CsButton1_8ValueChanged(app, event)
            value = app.CsButton1_8.Value;
            if value
                try
                    [Cv,Cs,gbest] = app.func_P3CvCs(app.UITableP3_1_3.Data(1,:),app.UITableP3_1_3.Data(2,:));
                catch
                    errordlg('经验频率为空，无法进行适线','适线失败')
                    return
                end
                app.CsEditField1_11.Value = Cv;
                app.CsEditField1_12.Value = Cs;
                app.CsEditField1_13.Value = Cs/Cv;
                app.CsEditField1_9.Value = gbest;


                P = app.UITableP3_1_3.Data(2,:);
                Y = app.UITableP3_1_3.Data(1,:);
                y = app.func_getnew_Y_fit(Y,P,Cv,Cs,eval(app.CsDropDown1_7.Value));
                app.H4 = app.func_plot_Hpg(app.UIAxesP3_1,P,y,'-');

                P = app.CsEditField1_10.Value;
                temp = app.func_getnew_Y_fit(Y,P,Cv,Cs,eval(app.CsDropDown1_7.Value));
                app.CsLabel1_8.Text = sprintf('%.2f',temp);
            else
                delete(app.H4)
            end
        end

        % Callback function
        function Menu2_2_1_1Selected(app, event)
            % 在海森机率格纸上点绘经验频率 (已删除原有上下文菜单接口，只剩下函数接口 Menu2_2_1_1Selected(app, 'on'))
            delete(app.UIAxesP3_1.Children(1:end));
            app_ax = app.UIAxesP3_1;
            %             app.UIAxesP3_1.Children(1) = []
            %             delete(app.UIAxesP3_1.Children(1))
            x = [0.01 0.05 0.1 0.5 1.0 5 10 15 20 30 40 50 60 70 80 85 90 95 99 99.5 99.9];
            Up = norminv(x/100,0,1);
            Up_01 = norminv(0.01/100,0,1);
            Lp = Up-Up_01;
            app_ax.YGrid = 'on';
            app_ax.XLim = [0 Lp(end)];
            app_ax.XTick = Lp;
            app_ax.XTickLabel = x';
            app_ax.XGrid = 'on';
            app_ax.XMinorGrid = 'on';
            app_ax.Title = [];
            app_ax.YLabel.String = app.UITableP3_1_3.RowName{1};
            app_ax.XLabel.String = app.UITableP3_1_3.RowName{2};

            line_style = '*';
            y = app.UITableP3_1_3.Data(1,:);
            P = app.UITableP3_1_3.Data(2,:);
            func_plot_Hpg(app,app.UIAxesP3_1,P,y,line_style);
            hold(app.UIAxesP3_1,"on")
            %             plot(app.UIAxesP3_1,[5 2],[1400 1300],'-')
        end

        % Value changed function: CvButton1_6
        function CvButton1_6ValueChanged(app, event)
            value = app.CvButton1_6.Value;
            if value == 1
                Cv = app.CvEditField1_1.Value;
                Cs = app.CvEditField1_2.Value;
                try
                    Y = app.UITableP3_1_3.Data(1,:);
                    P = app.UITableP3_1_3.Data(2,:);
                catch
                    errordlg('经验频率为空，无法适线','适线失败')
                    return
                end
                switch_fun = eval(app.CvDropDown1_7.Value);
                [new_Y,fit] = func_getnew_Y_fit(app,Y,P,Cv,Cs,switch_fun);
                app.CvEditField1_5.Value = fit;
                delete(app.H1)
                app.H1 = app.func_plot_Hpg(app.UIAxesP3_1,P,new_Y,'-');
                P = app.CvEditField1_4.Value;
                P_Y = func_getnew_Y_fit(app,Y,P,Cv,Cs);
                app.CvLabel1_6.Text = sprintf('%.2f',P_Y);
            else
                delete(app.H1)
            end
        end

        % Value changed function: CsButton1_6
        function CsButton1_6ValueChanged(app, event)
            value = app.CsButton1_6.Value;
            if value == 1
                Cv = app.CsEditField1_1.Value;
                Cs = app.CsEditField1_2.Value;
                try
                    Y = app.UITableP3_1_3.Data(1,:);
                    P = app.UITableP3_1_3.Data(2,:);
                catch
                    errordlg('经验频率为空，无法进行适线','适线失败')
                    return
                end
                switch_fun = eval(app.CsDropDown1_7.Value);
                [new_Y,fit] = func_getnew_Y_fit(app,Y,P,Cv,Cs,switch_fun);
                app.CsEditField1_5.Value = fit;
                delete(app.H3)
                app.H3 = app.func_plot_Hpg(app.UIAxesP3_1,P,new_Y,'-');
                P = app.CsEditField1_4.Value;
                P_Y = func_getnew_Y_fit(app,Y,P,Cv,Cs);
                app.CsLabel1_6.Text = sprintf('%.2f',P_Y);
            else
                delete(app.H3)
            end
        end

        % Value changed function: CvButton1_8
        function CvButton1_8ValueChanged(app, event)
            value = app.CvButton1_8.Value;
            if value
                try
                    [Cv,Cs,gbest] = app.func_P3Cv(app.UITableP3_1_3.Data(1,:),app.UITableP3_1_3.Data(2,:));
                catch
                    errordlg('经验频率为空，无法适线','适线失败')
                    return
                end
                app.CvEditField1_11.Value = Cv;
                app.CvEditField1_12.Value = Cs;
                app.CvEditField1_13.Value = Cs/Cv;
                app.CvEditField1_9.Value = gbest;


                P = app.UITableP3_1_3.Data(2,:);
                Y = app.UITableP3_1_3.Data(1,:);
                y = app.func_getnew_Y_fit(Y,P,Cv,Cs,eval(app.CvDropDown1_7.Value));
                app.H2 = app.func_plot_Hpg(app.UIAxesP3_1,P,y,'-');

                P = app.CvEditField1_10.Value;
                temp = app.func_getnew_Y_fit(Y,P,Cv,Cs,eval(app.CvDropDown1_7.Value));
                app.CvLabel1_8.Text = sprintf('%.2f',temp);
            else
                delete(app.H2)
            end
        end

        % Value changed function: CsEditField1_10
        function CsEditField1_10ValueChanged(app, event)

            Cv = app.CsEditField1_11.Value ;
            Cs = app.CsEditField1_12.Value ;
            Y = app.UITableP3_1_3.Data(1,:);
            P = app.CsEditField1_10.Value;
            temp = app.func_getnew_Y_fit(Y,P,Cv,Cs,eval(app.CsDropDown1_7.Value));
            app.CsLabel1_8.Text = sprintf('%.2f',temp);
        end

        % Value changed function: CsEditField1_4
        function CsEditField1_4ValueChanged(app, event)
            Cv = app.CsEditField1_1.Value ;
            Cs = app.CsEditField1_2.Value ;
            Y = app.UITableP3_1_3.Data(1,:);
            P = app.CsEditField1_4.Value;
            temp = app.func_getnew_Y_fit(Y,P,Cv,Cs,eval(app.CsDropDown1_7.Value));
            app.CsLabel1_6.Text = sprintf('%.2f',temp);

        end

        % Value changed function: CvEditField1_13
        function CvEditField1_13ValueChanged(app, event)
            value = app.CvEditField1_13.Value;
            app.CvEditField1_3.Value = value;
            app.CvEditField1_2.Value = app.CvEditField1_1.Value*app.CvEditField1_3.Value;
            CvButton1_6ValueChanged(app, event)
            CvButton1_8ValueChanged(app, event)

        end

        % Value changed function: CvEditField1_3
        function CvEditField1_3ValueChanged(app, event)
            value = app.CvEditField1_3.Value;
            app.CvEditField1_13.Value = value;
            app.CvEditField1_2.Value = app.CvEditField1_1.Value*app.CvEditField1_3.Value;
            CvButton1_6ValueChanged(app, event)
            CvButton1_8ValueChanged(app, event)
        end

        % Value changed function: CvEditField1_1
        function CvEditField1_1ValueChanged(app, event)
            app.CvEditField1_2.Value = app.CvEditField1_1.Value*app.CvEditField1_3.Value;
            CvButton1_6ValueChanged(app, event)
        end

        % Value changed function: CvEditField1_4
        function CvEditField1_4ValueChanged(app, event)
            Cv = app.CvEditField1_1.Value ;
            Cs = app.CvEditField1_2.Value ;
            Y = app.UITableP3_1_3.Data(1,:);
            P = app.CvEditField1_4.Value;
            temp = app.func_getnew_Y_fit(Y,P,Cv,Cs,eval(app.CvDropDown1_7.Value));
            app.CvLabel1_6.Text = sprintf('%.2f',temp);
        end

        % Menu selected function: Menu1_2_2
        function Menu1_2_2Selected(app, event)
            try
                %             temp = app.DataUItable2_2_timetable;
                temp = app.UITableData2_2.Data;
                %             save('jlj.timetable.mat','temp','-mat')
                Time = temp.Time(1):minutes(60):temp.Time(end);
                Time = Time';
                Var1 = interp1(temp.Time,temp.Var1,Time,'spline');
                DataUItable2_3_timetable = timetable(Time,Var1);

                %             app.DataUItable2_2_timetable = timetable(temp_table', ...
                %                 cell2mat(app.UITableData2_1.Data(:,3)))
                app.UITableData2_3.Data = timetable2table(DataUItable2_3_timetable);
                app.UITableData2_3.ColumnName = {'时间' ,app.UITableData2_1.ColumnName{3}};
                % 跳转 Data模块 Panel2中表3 插值为等时段数据
                app.TabGroupData_2.SelectedTab = app.TabData2_3;
            catch
                errordlg("整理数据时间格式表为空数据或此表数据中存在问题","插值失败")
            end
        end

        % Key press function: UITableData1_1
        function UITableData1_1KeyPress(app, event)
            key = event.Key;
            if key == 'p'
                app.func_choose2plotData(app.UITableData1_1,app.UIAxesData1,app.TabData3_1)
            end
            if string(key) == "return"
                app.Tab_P3ButtonDown;
            end

            sheetname = '导入洪水特征统计数据';
            callingTable = app.UITableData1_1;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key release function: UITableData1_1
        function UITableData1_1KeyRelease(app, event)

        end

        % Key press function: UITableData1_2
        function UITableData1_2KeyPress(app, event)
            key = event.Key;
            if key == 'p'
                app.func_choose2plotData(app.UITableData1_2,app.UIAxesData1,app.TabData3_1)
            end
            if string(key) == "return"
                app.Tab_P3ButtonDown;
            end

            sheetname = '数据的插补延展';
            callingTable = app.UITableData1_2;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Cell selection callback: UITableData2_1
        function UITableData2_1CellSelection(app, event)
            % 功能：鼠标选择'UITableData2_1'的数据，返回index（选择数据）
            % 位置：'UITableData2_1' Tab Data中panel_2 表1
            % 激活：不需要主动激活，调用的子程序，配合选择添加实测资料函数
            app.Dataindex_choose = event.Indices;
            app.DataUItable_choose = app.UITableData2_1;
        end

        % Cell selection callback: UITableData2_2
        function UITableData2_2CellSelection(app, event)
            % 功能：鼠标选择'UITableData2_2'的数据，返回index（选择数据）
            % 位置：'UITableData2_2' Tab Data中panel_2 表2
            % 激活：不需要主动激活，调用的子程序，配合选择添加实测资料函数
            app.Dataindex_choose = event.Indices;
            app.DataUItable_choose = app.UITableData2_2;
        end

        % Cell selection callback: UITableData2_3
        function UITableData2_3CellSelection(app, event)
            % 功能：鼠标选择'UITableData1_3'的数据，返回index（选择数据）
            % 位置：'UITableData2_3' Tab Data中panel_2 表3
            % 激活：不需要主动激活，调用的子程序，配合选择添加实测资料函数
            app.Dataindex_choose = event.Indices;
            app.DataUItable_choose = app.UITableData2_3;
        end

        % Key press function: UITableData2_1
        function UITableData2_1KeyPress(app, event)
            key = event.Key;
            if key == 'p'
                app.func_choose2plotData(app.UITableData2_1,app.UIAxesData2,app.TabData3_2)
            end

            sheetname = '导入典型洪水数据';
            callingTable = app.UITableData2_1;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableData2_2
        function UITableData2_2KeyPress(app, event)
            key = event.Key;
            if key == 'p'
                app.func_choose2plotData(app.UITableData2_2,app.UIAxesData2,app.TabData3_2)
            end

            sheetname = '整理数据时间格式';
            callingTable = app.UITableData2_2;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableData2_3
        function UITableData2_3KeyPress(app, event)
            key = event.Key;
            if key == 'p'
                app.func_choose2plotData(app.UITableData2_3,app.UIAxesData2,app.TabData3_2)
            end

            sheetname = '插值为等时段数据';
            callingTable = app.UITableData2_3;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Menu selected function: Menu1_2_1_1
        function Menu1_2_1_1Selected(app, event)
            % 整理原始典型洪水的时间数据
            % 功能：转换并统一时间格式TabgroupData
            % 位置：Tab Data中panel_2中的表2

            % 跳转 Data模块 Panel2中表2 整理原始时间数据
            try
                temp_MD = app.UITableData2_1.Data(:,1);
                temp_HM = app.UITableData2_1.Data(:,2);
                format = 'M月d日 HH:mm';
                %             temp_table =
                % %             temp_table = zeros(length(temp_MD),1);
                for i=1:length(temp_MD)
                    temp_md = string(temp_MD{i});
                    temp_hm = string(temp_HM{i});
                    if floor(temp_HM{i}) == double(temp_HM{i})
                        temp_hm = strcat(" ",temp_hm);
                        date = strcat(temp_md,temp_hm);
                        infmt = 'M.d H';
                        temp_table(i) = datetime(date,...
                            "InputFormat",infmt,"Format",format);
                    else
                        temp_index = strfind(temp_hm,'.');
                        integer_part = temp_hm{1}(1:temp_index-1);
                        decimal_part = temp_hm{1}(temp_index+1:end);
                        %                     temp_m = floor(60/10^length(decimal_part)*eval(decimal_part));
                        temp_m = sprintf('%-02s',decimal_part); % 把3 --> 30
                        temp_h = sprintf('%02s',integer_part); % 把2 --> 02
                        infmt = 'M.d HH:mm';
                        date = strcat(temp_md," ",temp_h,':',temp_m);
                        temp_table(i) = datetime(date,...
                            "InputFormat",infmt,"Format",format);
                    end
                end
                %             app.DataUItable2_2_timetable = timetable(temp_table', ...
                %                 cell2mat(app.UITableData2_1.Data(:,3)));
                DataUItable2_2_timetable = timetable(temp_table', ...
                    cell2mat(app.UITableData2_1.Data(:,3)));
                app.UITableData2_2.Data = timetable2table(DataUItable2_2_timetable);
                app.UITableData2_2.ColumnName = {'时间' ,app.UITableData2_1.ColumnName{3}};
                app.TabGroupData_2.SelectedTab = app.TabData2_2;
            catch
                errordlg('导入典型洪水数据表为空或此表数据中存在问题','整理数据失败')
            end

            %             plot(app.UIAxesP3_1,app.DataUItable2_2_timetable.Time,app.DataUItable2_2_timetable.Var1,'.')
            %             temp = app.DataUItable2_2_timetable;
            %             save('jlj.timetable.mat','temp','-mat')

        end

        % Callback function
        function Menu1_2_3_2Selected(app, event)
            % 空
        end

        % Callback function
        function Menu1_2_3_3Selected(app, event)
            % 空
        end

        % Callback function
        function Menu1_2_3_4Selected(app, event)
            % 空
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            Qm_Value = app.FloodEditFieldP1_Qm.Value;
            W1_Value = app.FloodEditFieldP1_W1.Value;
            W3_Value = app.FloodEditFieldP1_W3.Value;
            W7_Value = app.FloodEditFieldP1_W7.Value;
            outputTable = app.FloodUITableP1;
            outputAxes = app.FloodUIAxesP1;
            try
                func_enlarge_samefrequency(app,Qm_Value,W1_Value,W3_Value,W7_Value,outputTable,outputAxes)
            catch
                errordlg('插值为等时段数据表为空或此表数据中存在问题','计算失败')
            end
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            Qm_Value = app.FloodEditFieldP2_Qm.Value;
            W1_Value = app.FloodEditFieldP2_W1.Value;
            W3_Value = app.FloodEditFieldP2_W3.Value;
            W7_Value = app.FloodEditFieldP2_W7.Value;
            outputTable = app.FloodUITableP2;
            outputAxes = app.FloodUIAxesP2;
            try
                func_enlarge_samefrequency(app,Qm_Value,W1_Value,W3_Value,W7_Value,outputTable,outputAxes)
            catch
                errordlg('插值为等时段数据表为空或此表数据中存在问题','计算失败')
            end
        end

        % Button down function: FloodUITableP3
        function FloodUITableP3ButtonDown(app, event)
            % 空
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            Qm_Value = app.FloodEditFieldP3_Qm.Value;
            W1_Value = app.FloodEditFieldP3_W1.Value;
            W3_Value = app.FloodEditFieldP3_W3.Value;
            W7_Value = app.FloodEditFieldP3_W7.Value;
            outputTable = app.FloodUITableP3;
            outputAxes = app.FloodUIAxesP3;
            try
                func_enlarge_samefrequency(app,Qm_Value,W1_Value,W3_Value,W7_Value,outputTable,outputAxes)
            catch
                errordlg('插值为等时段数据表为空或此表数据中存在问题','计算失败')
            end
        end

        % Menu selected function: Menu1_2_3_9
        function Menu1_2_3_9Selected2(app, event)
            try
                app.FloodEditFieldQmd.Value = max(app.UITableData2_3.Data.Var1);
                % 计算最大一日洪量（滑动日）
                minute = 24;
                [MaxWd,app.DataMaxday1] = func_getMaxWd(app,minute);
                app.FloodEditFieldW1d.Value = MaxWd;

                % 计算最大三日洪量（滑动日）
                minute = 24*3;
                [MaxWd,app.DataMaxday3] = func_getMaxWd(app,minute);
                app.FloodEditFieldW3d.Value = MaxWd;

                % 计算最大七日洪量（滑动日）
                minute = 24*7;
                [MaxWd,app.DataMaxday7] = func_getMaxWd(app,minute);
                app.FloodEditFieldW7d.Value = MaxWd;

                app.TabGroup1.SelectedTab = app.Tab_Flood;
            catch
                errordlg("插值为等时段数据表为空或此表数据中存在问题","计算失败")
            end
        end

        % Cell selection callback: FloodUITableP1
        function FloodUITableP1CellSelection(app, event)
            %             indices = event.Indices;
            % %             max(app)
            %             if size(indices,1) == 1
            %                 row = indices(1);
            %                 col = indices(2);
            %                 if col == 1
            %                     x = app.FloodUITableP1.Data(row,col);
            %                     y = app.FloodUITableP1.Data(row,2);
            %                 elseif col == 2
            %                     x = app.FloodUITableP1.Data(row,1);
            %                     y = app.FloodUITableP1.Data(row,col);
            %                 end
            %                 hold(app.FloodUIAxesP1,"on")
            %                 plot(app.FloodUIAxesP1,x.Time,y.Var1,'*')
            %
            %             end
            % smooth_flood(app.FloodUITableP1,app.FloodUIAxesP1)
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            % 调用洪水修匀app
            %             try
            smooth_flood(app,app.FloodUITableP1,app.FloodUIAxesP1)

            %             catch
            %                 errordlg("P1(%)设计洪水表为空% ","修匀失败")
            %             end
        end

        % Button pushed function: rainfloodButton4
        function rainfloodButton4Pushed(app, event)
            try
                H1_bar = app.overlineH_1EditField.Value;
                H6_bar = app.overlineH_6EditField.Value;
                H24_bar = app.overlineH_24EditField.Value;
                Cv1 = app.C_V1EditField.Value;
                Cv6 = app.C_V6EditField.Value;
                Cv24 = app.C_V24EditField.Value;
                Cs_Cv = app.C_SC_VEditField.Value;
                Cs1 = Cv1*Cs_Cv;
                Cs6 = Cv6*Cs_Cv;
                Cs24 = Cv24*Cs_Cv;
                A1 = 4/Cs1^2;
                A6 = 4/Cs6^2;
                A24 = 4/Cs24^2;
                B1 = H1_bar*Cv1*Cs1/2;
                B6 = H6_bar*Cv6*Cs6/2;
                B24 = H24_bar*Cv24*Cs24/2;
                P = 1-app.PEditField.Value/100;
                app.H_1dianEditField.Value = gaminv(P,A1,B1)+H1_bar*(1-2*Cv1/Cs1);
                app.H_6dianEditField.Value = gaminv(P,A6,B6)+H6_bar*(1-2*Cv6/Cs6);
                app.H_24dianEditField.Value = gaminv(P,A24,B24)+H24_bar*(1-2*Cv24/Cs24);
            catch
                errordlg('计算设计点雨量出现错误，请检查你输入的H,Cv,Cs/Cv,P','计算失败')
            end
            %             phi_p = Cs6/2*gaminv(P,4/Cs6^2,1)-2/Cs6
            %             (1+Cv6*phi_p)*H6_bar
        end

        % Button pushed function: rainfloodButton5
        function rainfloodButton5Pushed(app, event)
            try
                app.H_1mianEditField.Value = app.alpha_1EditField.Value*app.H_1dianEditField.Value;
                app.H_6mianEditField.Value = app.alpha_6EditField.Value*app.H_6dianEditField.Value;
                app.H_24mianEditField.Value = app.alpha_24EditField.Value*app.H_24dianEditField.Value;

                app.beta_1EditField.Value = app.H_1mianEditField.Value/app.H_6mianEditField.Value;
                app.beta_2EditField.Value = app.H_6mianEditField.Value/app.H_24mianEditField.Value;
                app.n_1EditField.Value = 1+0.558*log(app.beta_1EditField.Value); % matlab中log是以e为底的对数
                app.n_2EditField.Value = 1+0.721*log(app.beta_2EditField.Value);


                rainfall_notsort = zeros(24,1);
                for t=1:24
                    if t<6
                        rainfall_notsort(t) = app.H_1mianEditField.Value*t^(1-app.n_1EditField.Value);
                    else
                        rainfall_notsort(t) = app.H_24mianEditField.Value*24^(app.n_2EditField.Value-1)*t^(1-app.n_2EditField.Value);
                    end
                end
                app.rainfloodUITable.Data = rainfall_notsort;
                app.rainfloodUITable.RowName = num2cell(1:24);
                app.rainfloodUITable.ColumnName = {'24h面雨量'};
            catch
                errordlg('计算设计面雨量出现错误，请检查你输入的点雨量数据，点面雨量转换系数','计算失败')
            end
        end

        % Value changed function: rainfloodButton3
        function rainfloodButton3ValueChanged(app, event)
            value = app.rainfloodButton3.Value;
            if value == 1
                hold(app.rainfloodUIAxes,"on")
                app.H7 = plot(app.rainfloodUIAxes,app.rainfloodUITable2.Data,'.-');
            else
                delete(app.H7)
            end
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            % p1 调用暴雨洪水计算app
            rain_flood(app,app.rainfloodUITable,app.rainfloodUITable2)
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            app.PFlag_choose = true;
            app.TabGroup1.SelectedTab = app.Tab_Flood;
        end

        % Button down function: FloodUITableP1
        function FloodUITableP1ButtonDown(app, event)
            % 空
            %             if app.PFlag_choose
            %                 app.rainfloodUITable1.Data = app.FloodUITableP1.Data(:,2);
            %                 app.rainfloodUITable1.RowName = 1:length(app.FloodUIAxesP1.Data(:,2));
            %             end
        end

        % Button down function: Tab_rainflood
        function Tab_rainfloodButtonDown(app, event)
            app.PFlag_choose = false;
        end

        % Key press function: FloodUITableP1
        function FloodUITableP1KeyPress(app, event)
            key = event.Key;
            if app.PFlag_choose
                if string(key) == "return"
                    try
                        app.rainfloodUITable1.Data = app.FloodUITableP1.Data.Var1;
                        app.rainfloodUITable1.RowName = num2cell(1:size(app.FloodUITableP1.Data(:,2),1));
                        app.TabGroup1.SelectedTab = app.Tab_rainflood;
                    catch
                        errordlg('你选择P1(%)设计洪水为空，选择已取消','选择失败')
                    end
                    app.PFlag_choose = false;
                end
            end

            sheetname = 'P1(%)设计洪水';
            callingTable = app.FloodUITableP1;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Value changed function: rainfloodButton1
        function rainfloodButton1ValueChanged(app, event)
            value = app.rainfloodButton1.Value;
            if value == 1
                hold(app.rainfloodUIAxes,"on")
                app.H5 = plot(app.rainfloodUIAxes,app.rainfloodUITable1.Data,'.-');
            else
                delete(app.H5)
            end
        end

        % Value changed function: rainfloodButton2
        function rainfloodButton2ValueChanged(app, event)
            value = app.rainfloodButton2.Value;
            if value == 1
                hold(app.rainfloodUIAxes,"on")
                n1 = length(app.rainfloodUITable1.Data);
                n2 = length(app.rainfloodUITable2.Data);
                if n1 <= n2
                    flood_2 = app.rainfloodUITable2.Data(1:n1);
                else
                    flood_2 = zeros(n1,1);
                    flood_2(1:n2) = app.rainfloodUITable2.Data;
                end
                flood_diff = app.rainfloodUITable1.Data - flood_2; % 水库相应洪水
                app.H6 = plot(app.rainfloodUIAxes,flood_diff,'.-');
            else
                delete(app.H6)
            end
        end

        % Menu selected function: Menu_12
        function Menu_12Selected(app, event)
            % p2 调用洪水修匀app
            %             try
            smooth_flood(app,app.FloodUITableP2,app.FloodUIAxesP2)
            %             catch
            %                 errordlg("P2(%)设计洪水表为空% ","修匀失败")
            %             end

        end

        % Menu selected function: Menu_13
        function Menu_13Selected(app, event)
            % p3 调用洪水修匀app
            smooth_flood(app,app.FloodUITableP3,app.FloodUIAxesP3);
            %             if ~app.DialogApp_smooth_flood
            %                 errordlg("P3(%)设计洪水表为空% ","无法修匀")
            %             end
        end

        % Key press function: FloodUITableP2
        function FloodUITableP2KeyPress(app, event)
            key = event.Key;
            if app.PFlag_choose
                if string(key) == "return"
                    try
                        app.rainfloodUITable1.Data = app.FloodUITableP2.Data.Var1;
                        app.rainfloodUITable1.RowName = num2cell(1:size(app.FloodUITableP2.Data(:,2),1));
                        app.TabGroup1.SelectedTab = app.Tab_rainflood;
                    catch
                        errordlg('你选择P2(%)设计洪水为空，选择已取消','选择失败')
                    end
                    app.PFlag_choose = false;
                end
            end

            sheetname = 'P2(%)设计洪水';
            callingTable = app.FloodUITableP2;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: FloodUITableP3
        function FloodUITableP3KeyPress(app, event)
            key = event.Key;
            if app.PFlag_choose
                if string(key) == "return"
                    try
                        app.rainfloodUITable1.Data = app.FloodUITableP3.Data.Var1;
                        app.rainfloodUITable1.RowName = num2cell(1:size(app.FloodUITableP3.Data(:,2),1));
                        app.TabGroup1.SelectedTab = app.Tab_rainflood;
                    catch
                        errordlg('你选择P3(%)设计洪水为空，选择已取消','选择失败')
                    end
                    app.PFlag_choose = false;
                end
            end

            sheetname = 'P3(%)设计洪水';
            callingTable = app.FloodUITableP3;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Button down function: Tab_flood_routing
        function Tab_flood_routingButtonDown(app, event)
            if isempty(app.UITableZ_V.Data)
                Z_V = [ 405 410 415 420 425 430 435 440 445 450
                    30 110 300 620 1060 1640 2390 3300 4430 5800];
                Z_V(2,:) = Z_V(2,:)*10000;
                app.UITableZ_V.Data = Z_V;
                app.UITableZ_V.ColumnName = [];
                app.UITableZ_V.RowName = {'Z','V'};
            end
            app.FloodFlag_choose1 = false;
            app.FloodFlag_choose2 = false;
            app.FloodFlag_choose3 = false;
        end

        % Button down function: rainfloodUIAxes
        function rainfloodUIAxesButtonDown(app, event)
            if app.FloodFlag_choose1
                try
                    app.FloodUItable_choose.Data = app.H6.YData';
                    app.FloodUItable_choose.RowName = num2cell(1:length(app.FloodUItable_choose.Data));
                    app.TabGroup1.SelectedTab = app.Tab_flood_routing;
                    msgbox('成功添加水库相应洪水过程','正确')
                    plot(app.UIAxesH1,app.FloodUItable_choose.Data,'-')
                catch
                    errordlg('水库相应洪水过程为空，选择已取消','添加失败')
                end
                app.FloodFlag_choose1 = false;
            end
        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            app.FloodUItable_choose = app.UITableH1_Q;
            app.FloodFlag_choose1 = true;
            app.TabGroup1.SelectedTab = app.Tab_rainflood;
        end

        % Menu selected function: Menu_15
        function Menu_15Selected(app, event)
            app.FloodUItable_choose = app.UITableH2_Q;
            app.FloodFlag_choose2 = true;
            app.TabGroup1.SelectedTab = app.Tab_Flood;
        end

        % Button down function: FloodUIAxesP1
        function FloodUIAxesP1ButtonDown(app, event)
            if app.PFlag_choose
                try
                    app.rainfloodUITable1.Data = app.FloodUITableP1.Data.Var1;
                    app.rainfloodUITable1.RowName = num2cell(1:size(app.FloodUITableP1.Data(:,2),1));
                    app.TabGroup1.SelectedTab = app.Tab_rainflood;
                catch
                    errordlg('你选择P1(%)设计洪水为空，选择已取消','选择失败')
                end
                app.PFlag_choose = false;
            end

            if app.FloodFlag_choose2
                try
                    app.FloodUItable_choose.Data = app.FloodUITableP1.Data.Var1*app.EditField_C2A.Value;
                    app.FloodUItable_choose.RowName = num2cell(1:length(app.FloodUItable_choose.Data));
                    app.TabGroup1.SelectedTab = app.Tab_flood_routing;
                    msgbox('成功选择参考站的洪水过程，并用面积比法缩放成水库洪水过程','正确')
                    plot(app.UIAxesH2,app.FloodUItable_choose.Data,'-')
                catch
                    errordlg('你选择的洪水过程为空，选择已取消','添加失败')
                end
                app.FloodFlag_choose2 = false;
            end

            if app.FloodFlag_choose3
                try
                    app.FloodUItable_choose.Data = app.FloodUITableP1.Data.Var1*app.EditField_C2A.Value;
                    app.FloodUItable_choose.RowName = num2cell(1:length(app.FloodUItable_choose.Data));
                    app.TabGroup1.SelectedTab = app.Tab_flood_routing;
                    msgbox('成功选择参考站的洪水过程，并用面积比法缩放成水库洪水过程','正确')
                    plot(app.UIAxesH3,app.FloodUItable_choose.Data,'-')
                catch
                    errordlg('你选择的洪水过程为空，选择已取消','添加失败')
                end
                app.FloodFlag_choose3 = false;
            end
        end

        % Button down function: FloodUIAxesP2
        function FloodUIAxesP2ButtonDown(app, event)
            if app.PFlag_choose
                try
                    app.rainfloodUITable1.Data = app.FloodUITableP2.Data.Var1;
                    app.rainfloodUITable1.RowName = num2cell(1:size(app.FloodUITableP2.Data(:,2),1));
                    app.TabGroup1.SelectedTab = app.Tab_rainflood;
                catch
                    errordlg('你选择P2(%)设计洪水为空，选择已取消','选择失败')
                end
                app.PFlag_choose = false;
            end

            if app.FloodFlag_choose2
                try
                    app.FloodUItable_choose.Data = app.FloodUITableP2.Data.Var1*app.EditField_C2A.Value;
                    app.FloodUItable_choose.RowName = num2cell(1:length(app.FloodUItable_choose.Data));
                    app.TabGroup1.SelectedTab = app.Tab_flood_routing;
                    msgbox('成功选择参考站的洪水过程，并用面积比法缩放成水库洪水过程','正确')
                    plot(app.UIAxesH2,app.FloodUItable_choose.Data,'-')
                catch
                    errordlg('你选择的洪水过程为空，选择已取消','添加失败')
                end
                app.FloodFlag_choose2 = false;
            end

            if app.FloodFlag_choose3
                try
                    app.FloodUItable_choose.Data = app.FloodUITableP2.Data.Var1*app.EditField_C2A.Value;
                    app.FloodUItable_choose.RowName = num2cell(1:length(app.FloodUItable_choose.Data));
                    app.TabGroup1.SelectedTab = app.Tab_flood_routing;

                    msgbox('成功选择参考站的洪水过程，并用面积比法缩放成水库洪水过程','正确')
                    plot(app.UIAxesH3,app.FloodUItable_choose.Data,'-')
                catch
                    errordlg('你选择的洪水过程为空，选择已取消','添加失败')
                end
                app.FloodFlag_choose3 = false;
            end
        end

        % Button down function: FloodUIAxesP3
        function FloodUIAxesP3ButtonDown(app, event)
            if app.PFlag_choose
                try
                    app.rainfloodUITable1.Data = app.FloodUITableP3.Data.Var1;
                    app.rainfloodUITable1.RowName = num2cell(1:size(app.FloodUITableP3.Data(:,2),1));
                    app.TabGroup1.SelectedTab = app.Tab_rainflood;
                catch
                    errordlg('你选择P3(%)设计洪水为空，选择已取消','选择失败')
                end
                app.PFlag_choose = false;
            end

            if app.FloodFlag_choose2
                try
                    app.FloodUItable_choose.Data = app.FloodUITableP3.Data.Var1*app.EditField_C2A.Value;
                    app.FloodUItable_choose.RowName = num2cell(1:length(app.FloodUItable_choose.Data));
                    app.TabGroup1.SelectedTab = app.Tab_flood_routing;
                    msgbox('成功选择参考站的洪水过程，并用面积比法缩放成水库洪水过程','正确')
                    plot(app.UIAxesH2,app.FloodUItable_choose.Data,'-')
                catch
                    errordlg('你选择的洪水过程为空，选择已取消','添加失败')
                end
                app.FloodFlag_choose2 = false;
            end

            if app.FloodFlag_choose3
                try
                    app.FloodUItable_choose.Data = app.FloodUITableP3.Data.Var1*app.EditField_C2A.Value;
                    app.FloodUItable_choose.RowName = num2cell(1:length(app.FloodUItable_choose.Data));
                    app.TabGroup1.SelectedTab = app.Tab_flood_routing;
                    msgbox('成功选择参考站的洪水过程，并用面积比法缩放成水库洪水过程','正确')
                    plot(app.UIAxesH3,app.FloodUItable_choose.Data,'-')
                catch
                    errordlg('你选择的洪水过程为空，选择已取消','添加失败')
                end
                app.FloodFlag_choose3 = false;
            end
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            app.FloodUItable_choose = app.UITableH3_Q;
            app.FloodFlag_choose3 = true;
            app.TabGroup1.SelectedTab = app.Tab_Flood;
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            %% 确定防洪高水位
            %% 输入数据
            % 输入 水库相应洪水 flood_diff
            % load('flood_diff.mat')
            flood_diff = app.UITableH1_Q.Data;
            % 输入 区间入流 flood_2
            % load('flood_2.mat')
            flood_2 = app.rainfloodUITable2.Data;
            %% 调洪演算
            n1_length = length(flood_diff);
            n1 = 1:n1_length;
            %             调整区间洪水的历时大小，与水库设计洪水一样历时
            try
                flood_2 = flood_2(1:n1_length);
            catch
                errordlg('区间设计洪水过程表为空，无法进行调洪计算','计算失败')
                return
            end


            n_spline = app.n_splineEditField.Value;
            n2 = 1:1/n_spline:n1_length ;
            n2_length = length(n2);
            flood_diff_spline = spline(n1,flood_diff,n2);
            flood_2_spline = spline(n1,flood_2,n2);
            % 输入 库容曲线(已给)
            % Z_V = [ 405 410 415 420 425 430 435 440 445 450
            %     30 110 300 620 1060 1640 2390 3300 4430 5800];
            % Z_V(2,:) = Z_V(2,:)*10000;
            Z_V = app.UITableZ_V.Data;
            % 防洪限制水位（可理解为洪水到来的初始水位高程）
            % 要求：防洪限制水位要小于正常蓄水位
            %(其中 正常蓄水位小于448m,死水位不低于423m)
            % 需要自己拟定
            % 又称起调水位
            % flood_control_level = 430;
            flood_control_level = app.EditFieldH0.Value;
            % 溢洪道高程
            % spillway_level = 429;
            spillway_level = app.EditFieldHspillway.Value;
            % 泄流计算参数M(已给)
            % M = 1.57;
            M = app.EditFieldM.Value;
            % 闸门总宽度小于40m（单个宽度小于5m）
            % B = 20;
            B = app.EditFieldB.Value;
            % 下游安全泄量
            % downstream_safe_flood_discharge = max(flood_2_spline)+200;
            downstream_safe_flood_discharge = app.EditFieldq_safedischarge.Value;
            % % hold on
            % plot(safe_flood_discharge - flood_2_spline)

            % 水量平衡中的delta_T
            % delta_T = 1*3600;
            delta_T = app.EditFieldDeltaT.Value/n_spline;
            %% 调洪演算

            % 为大坝下泄流量Dam_discharge开辟空间
            Dam_discharge = zeros(n2_length,1); %推演到n时段就可以
            choose = zeros(n2_length,1);
            % %% 调洪演算的第一阶段 当下泄+区间的流量不超过下游安全流量时，来多少泄多少
            %
            % % 从洪水开始找到第一个大于安全泄量的时刻
            % part1_index = find(sum(flood_diff_spline+flood_2_spline,2)>safe_flood_discharge,1)-1;
            % % 来多少泄多少（几乎不用考虑此时溢洪道最大泄量满足不了来多少泄多少，因为下游安全流量远远小于下泄能力）
            % Dam_discharge(1:part1_index) = flood_diff_spline(1:part1_index);
            %
            % %% 调洪演算的第二阶段 进行流量调节，保证下游安全流量，同时不能超过最大泄量

            % 从下游安全泄量角度来考虑 的 大坝时段最大安全泄量
            Dam_safe_flood_discharge = downstream_safe_flood_discharge - flood_2_spline;


            H0 = flood_control_level;
            V0 = spline(Z_V(1,:),Z_V(2,:),H0);

            H = zeros(n2_length,1);
            V = zeros(n2_length,1);
            [Dam_discharge(1),index] = min([flood_diff_spline(1),B*M*(H0-spillway_level)^(1.5),Dam_safe_flood_discharge(1)]);
            switch index
                case 1
                    H(1) = H0;
                    V(1) = V0;
                case 2
                    V(1) = V0 + (flood_diff_spline(1)-Dam_discharge(1))*delta_T; % 此处做了省略处理
                    H(1) = spline(Z_V(2,:),Z_V(1,:),V(1));
                case 3
                    errordlg("下游安全泄流定设置的太小了")
            end


            for i=2:n2_length
                Q1 = flood_diff_spline(i-1);
                Q2 = flood_diff_spline(i);
                V1 = V(i-1);

                %     从整个调洪过程不能水位不能低于防洪限制水位的角度来考虑 的 大坝最大泄流量
                %     if V1-V0 ~=0
                %         q1 = 2*(V1-V0)/delta_T+Q1+Q2-Dam_discharge(i-1);
                %     else
                %         Q1 = Dam_discharge(i-1);
                %         q1 = Q2;
                %     end
                q1 = 2*(V1-V0)/delta_T+Q1+Q2-Dam_discharge(i-1);

                %     if i == 23
                %         a = 1;
                %     end
                % 从溢洪道泄流能力角度来考虑 的 大坝时段最大泄流量 q2 = B*M*(H - spillway_level)^(1.5);
                q2 = func_trialcalculation(app,Q1,Q2,Dam_discharge(i-1),delta_T,V1,Z_V,spillway_level,B,M);
                % 从大坝安全泄量角度来考虑 的 大坝时段最大安全泄量
                q3 = Dam_safe_flood_discharge(i);

                [Dam_discharge(i),choose(i)] = min([q1,q2,q3]);
                if choose(i) ~= 1
                    V2 = V1+(Q1+Q2-Dam_discharge(i-1)-Dam_discharge(i))/2*delta_T;
                    V(i) = V2;
                    H(i) = spline(Z_V(2,:),Z_V(1,:),V(i));
                else
                    Dam_discharge(i) = Q2;
                    V(i) = V0;
                    H(i) = H0;
                end
            end
            % 画图
            % 因为有hold on的原因，所以要清空画布
            %             delete(app.UIAxesH1.Children)

            plot(app.UIAxesH1,downstream_safe_flood_discharge-flood_2_spline)
            hold(app.UIAxesH1,"on")
            plot(app.UIAxesH1,flood_diff_spline,'-')
            plot(app.UIAxesH1,Dam_discharge,'-')
            hold(app.UIAxesH1,"off")

            % 结果值的写出
            app.EditFieldH1.Value = max(H);
            app.EditFieldV1.Value = spline(Z_V(1,:),Z_V(2,:),app.EditFieldH1.Value)-spline(Z_V(1,:),Z_V(2,:),app.EditFieldH0.Value);
            app.EditFieldq1.Value = max(Dam_discharge);
            app.UITableH1_q.Data = Dam_discharge;
            app.UITableH1_q.ColumnName = {'调洪下泄流量过程'};
            app.UITableH1_q.RowName = num2cell(1:length(Dam_discharge));

            % 记录防洪高水位H3的水库下泄q、水位H、库容V变化情况
            app.H1_q = Dam_discharge;
            app.H1_H = H;
            app.H1_V = V;

            %             % 把插值修改后的入库流量修改回去
            %             app.UITableH1_Q.Data = flood_diff_spline';
            %             app.UITableH1_Q.RowName = num2cell(1:length(flood_diff_spline));
        end

        % Menu selected function: Menu_18
        function Menu_18Selected(app, event)
            % 设计洪水位的计算
            % 导入设计洪水过程
            %             load('flood_p1.mat')
            flood_p1 = app.UITableH2_Q.Data;
            % 输入 区间入流 flood_2
            %             load('flood_2.mat')
            flood_2 = app.rainfloodUITable2.Data;
            n1_length = length(flood_p1);
            n1 = 1:n1_length;
            %             调整区间洪水的历时大小，与水库设计洪水一样历时
            try
                flood_2 = flood_2(1:n1_length);
            catch
                errordlg('区间设计洪水过程表为空，无法进行调洪计算','计算失败')
                return
            end

            n_spline = app.n_splineEditField.Value;
            n2 = 1:1/n_spline:n1_length ;
            n2_length = length(n2);
            flood_p1_spline = spline(n1,flood_p1,n2);
            flood_2_spline = spline(n1,flood_2,n2);
            % 输入 库容曲线(已给)
            %             Z_V = [ 405 410 415 420 425 430 435 440 445 450
            %                 30 110 300 620 1060 1640 2390 3300 4430 5800];
            %             Z_V(2,:) = Z_V(2,:)*10000;
            Z_V = app.UITableZ_V.Data;
            % 防洪限制水位（可理解为洪水到来的初始水位高程）
            % 要求：防洪限制水位要小于正常蓄水位
            %(其中 正常蓄水位小于448m,死水位不低于423m)
            % 需要自己拟定
            % 又称起调水位
            % flood_control_level = 430;
            flood_control_level = app.EditFieldH0.Value;
            % 溢洪道高程
            % spillway_level = 429;
            spillway_level = app.EditFieldHspillway.Value;
            % 泄流计算参数M(已给)
            % M = 1.57;
            M = app.EditFieldM.Value;
            % 闸门总宽度小于40m（单个宽度小于5m）
            % B = 20;
            B = app.EditFieldB.Value;
            % delta_T = 1*3600;
            delta_T = app.EditFieldDeltaT.Value/n_spline;

            % downstream_safe_flood_discharge = max(flood_2_spline)+200;
            downstream_safe_flood_discharge = app.EditFieldq_safedischarge.Value;
            % 防洪高水位
            %             upper_water_level_for_flood_control = 437.3383;
            upper_water_level_for_flood_control = app.EditFieldH1.Value;
            flag_floodcontrol = true;
            % 考虑河道通航约束，相邻时段的流量变幅约束
            max_diff_q = app.EditField_maxdiff_q.Value/n_spline;
            %% 调洪演算

            % 为大坝下泄流量Dam_discharge开辟空间
            Dam_discharge = zeros(n2_length,1); %推演到n时段就可以
            choose = zeros(n2_length,1);
            % %% 调洪演算的第一阶段 当下泄+区间的流量不超过下游安全流量时，来多少泄多少
            %
            % % 从洪水开始找到第一个大于安全泄量的时刻
            % part1_index = find(sum(flood_diff+flood_2_spline,2)>safe_flood_discharge,1)-1;
            % % 来多少泄多少（几乎不用考虑此时溢洪道最大泄量满足不了来多少泄多少，因为下游安全流量远远小于下泄能力）
            % Dam_discharge(1:part1_index) = flood_diff(1:part1_index);
            %
            % %% 调洪演算的第二阶段 进行流量调节，保证下游安全流量，同时不能超过最大泄量

            % 从下游安全泄量角度来考虑 的 大坝时段最大安全泄量
            Dam_safe_flood_discharge = downstream_safe_flood_discharge - flood_2_spline;
            H0 = flood_control_level;
            V0 = spline(Z_V(1,:),Z_V(2,:),H0);

            H = zeros(n2_length,1);
            V = zeros(n2_length,1);
            [Dam_discharge(1),index] = min([flood_p1_spline(1),B*M*(H0-spillway_level)^(1.5),Dam_safe_flood_discharge(1)]);
            switch index
                case 1
                    H(1) = H0;
                    V(1) = V0;
                case 2
                    V(1) = V0 + (flood_p1_spline(1)-Dam_discharge(1))*delta_T; % 此处做了省略处理
                    H(1) = spline(Z_V(2,:),Z_V(1,:),V(1));
                case 3
                    errordlg("下游安全泄流定设置的太小了")
            end


            for i=2:n2_length
                Q1 = flood_p1_spline(i-1);
                Q2 = flood_p1_spline(i);
                V1 = V(i-1);

                %     从整个调洪过程不能水位不能低于防洪限制水位的角度来考虑 的 大坝最大泄流量
                %     if V1-V0 ~=0
                %         q1 = 2*(V1-V0)/delta_T+Q1+Q2-Dam_discharge(i-1);
                %     else
                %         Q1 = Dam_discharge(i-1);
                %         q1 = Q2;
                %     end
                q1 = 2*(V1-V0)/delta_T+Q1+Q2-Dam_discharge(i-1);

                %     if i == 23
                %         a = 1;
                %     end
                % 从溢洪道泄流能力角度来考虑 的 大坝时段最大泄流量 q2 = B*M*(H - spillway_level)^(1.5);
                q2 = func_trialcalculation(app,Q1,Q2,Dam_discharge(i-1),delta_T,V1,Z_V,spillway_level,B,M);
                % 从大坝安全泄量角度来考虑 的 大坝时段最大安全泄量
                q3 = Dam_safe_flood_discharge(i);

                [Dam_discharge(i),choose(i)] = min([q1,q2,q3]);

                if abs(Dam_discharge(i)-Dam_discharge(i-1))>max_diff_q
                    if Dam_discharge(i)>Dam_discharge(i-1)
                        Dam_discharge(i) = Dam_discharge(i-1)+max_diff_q;
                    else
                        Dam_discharge(i) = Dam_discharge(i-1)-max_diff_q;
                    end
                end

                if choose(i) ~= 1
                    V2 = V1+(Q1+Q2-Dam_discharge(i-1)-Dam_discharge(i))/2*delta_T;
                    V(i) = V2;
                    H(i) = spline(Z_V(2,:),Z_V(1,:),V(i));
                else
                    Dam_discharge(i) = Q2;
                    V(i) = V0;
                    H(i) = H0;
                end
                if flag_floodcontrol
                    if H(i) > upper_water_level_for_flood_control
                        Dam_safe_flood_discharge(i+1:end) = ones(n2_length-i,1)*max(flood_p1_spline);
                        flag_floodcontrol =false; % 达到防洪高水位，改大坝安全泄量为洪峰，改一次就可以
                    end
                end
            end


            % plot(Dam_safe_flood_discharge)
            %             hold on
            %             plot(flood_p1_spline)
            %             plot(Dam_discharge)
            % 画图
            % 因为有hold on的原因，所以要清空画布
            %             delete(app.UIAxesH2.Children)

            plot(app.UIAxesH2,downstream_safe_flood_discharge-flood_2_spline)
            hold(app.UIAxesH2,"on")
            plot(app.UIAxesH2,flood_p1_spline,'-')
            plot(app.UIAxesH2,Dam_discharge,'-')
            hold(app.UIAxesH2,"off")

            % 结果值的写出
            app.EditFieldH2.Value = max(H);
            app.EditFieldV2.Value = spline(Z_V(1,:),Z_V(2,:),app.EditFieldH2.Value)-spline(Z_V(1,:),Z_V(2,:),app.EditFieldH0.Value);
            app.EditFieldq2.Value = max(Dam_discharge);
            app.UITableH2_q.Data = Dam_discharge;
            app.UITableH2_q.ColumnName = {'调洪下泄流量过程'};
            app.UITableH2_q.RowName = num2cell(1:length(Dam_discharge));

            % 记录设计洪水位H3的水库下泄q、水位H、库容V变化情况
            app.H2_q = Dam_discharge;
            app.H2_H = H;
            app.H2_V = V;
        end

        % Menu selected function: Menu_19
        function Menu_19Selected(app, event)
            % 校核洪水位的计算
            % 导入校核洪水过程
            %             load('flood_p1.mat')
            flood_p1 = app.UITableH3_Q.Data;
            % 输入 区间入流 flood_2
            %             load('flood_2.mat')
            flood_2 = app.rainfloodUITable2.Data;

            n1_length = length(flood_p1);
            n1 = 1:n1_length;
            %             调整区间洪水的历时大小，与水库设计洪水一样历时
            try
                flood_2 = flood_2(1:n1_length);
            catch
                errordlg('区间设计洪水过程表为空，无法进行调洪计算','计算失败')
                return
            end

            n_spline = app.n_splineEditField.Value;
            n2 = 1:1/n_spline:n1_length ;
            n2_length = length(n2);
            flood_p1_spline = spline(n1,flood_p1,n2);
            flood_2_spline = spline(n1,flood_2,n2);
            % 输入 库容曲线(已给)
            %             Z_V = [ 405 410 415 420 425 430 435 440 445 450
            %                 30 110 300 620 1060 1640 2390 3300 4430 5800];
            %             Z_V(2,:) = Z_V(2,:)*10000;
            Z_V = app.UITableZ_V.Data;
            % 防洪限制水位（可理解为洪水到来的初始水位高程）
            % 要求：防洪限制水位要小于正常蓄水位
            %(其中 正常蓄水位小于448m,死水位不低于423m)
            % 需要自己拟定
            % 又称起调水位
            % flood_control_level = 430;
            flood_control_level = app.EditFieldH0.Value;
            % 溢洪道高程
            % spillway_level = 429;
            spillway_level = app.EditFieldHspillway.Value;
            % 泄流计算参数M(已给)
            % M = 1.57;
            M = app.EditFieldM.Value;
            % 闸门总宽度小于40m（单个宽度小于5m）
            % B = 20;
            B = app.EditFieldB.Value;
            % delta_T = 1*3600;
            delta_T = app.EditFieldDeltaT.Value/n_spline;

            % downstream_safe_flood_discharge = max(flood_2_spline)+200;
            downstream_safe_flood_discharge = app.EditFieldq_safedischarge.Value;
            % 防洪高水位
            %             upper_water_level_for_flood_control = 437.3383;
            upper_water_level_for_flood_control = app.EditFieldH1.Value;
            flag_floodcontrol = true;
            % 考虑河道通航约束，相邻时段的流量变幅约束
            max_diff_q = app.EditField_maxdiff_q.Value/n_spline;
            %% 调洪演算

            % 为大坝下泄流量Dam_discharge开辟空间
            Dam_discharge = zeros(n2_length,1); %推演到n时段就可以
            choose = zeros(n2_length,1);
            % %% 调洪演算的第一阶段 当下泄+区间的流量不超过下游安全流量时，来多少泄多少
            %
            % % 从洪水开始找到第一个大于安全泄量的时刻
            % part1_index = find(sum(flood_diff+flood_2_spline,2)>safe_flood_discharge,1)-1;
            % % 来多少泄多少（几乎不用考虑此时溢洪道最大泄量满足不了来多少泄多少，因为下游安全流量远远小于下泄能力）
            % Dam_discharge(1:part1_index) = flood_diff(1:part1_index);
            %
            % %% 调洪演算的第二阶段 进行流量调节，保证下游安全流量，同时不能超过最大泄量

            % 从下游安全泄量角度来考虑 的 大坝时段最大安全泄量
            Dam_safe_flood_discharge = downstream_safe_flood_discharge - flood_2_spline;
            H0 = flood_control_level;
            V0 = spline(Z_V(1,:),Z_V(2,:),H0);

            H = zeros(n2_length,1);
            V = zeros(n2_length,1);
            [Dam_discharge(1),index] = min([flood_p1_spline(1),B*M*(H0-spillway_level)^(1.5),Dam_safe_flood_discharge(1)]);
            switch index
                case 1
                    H(1) = H0;
                    V(1) = V0;
                case 2
                    V(1) = V0 + (flood_p1_spline(1)-Dam_discharge(1))*delta_T; % 此处做了省略处理
                    H(1) = spline(Z_V(2,:),Z_V(1,:),V(1));
                case 3
                    errordlg("下游安全泄流定设置的太小了")
            end


            for i=2:n2_length
                Q1 = flood_p1_spline(i-1);
                Q2 = flood_p1_spline(i);
                V1 = V(i-1);

                %     从整个调洪过程不能水位不能低于防洪限制水位的角度来考虑 的 大坝最大泄流量
                %     if V1-V0 ~=0
                %         q1 = 2*(V1-V0)/delta_T+Q1+Q2-Dam_discharge(i-1);
                %     else
                %         Q1 = Dam_discharge(i-1);
                %         q1 = Q2;
                %     end
                q1 = 2*(V1-V0)/delta_T+Q1+Q2-Dam_discharge(i-1);

                %     if i == 23
                %         a = 1;
                %     end
                % 从溢洪道泄流能力角度来考虑 的 大坝时段最大泄流量 q2 = B*M*(H - spillway_level)^(1.5);
                q2 = func_trialcalculation(app,Q1,Q2,Dam_discharge(i-1),delta_T,V1,Z_V,spillway_level,B,M);
                % 从大坝安全泄量角度来考虑 的 大坝时段最大安全泄量
                q3 = Dam_safe_flood_discharge(i);

                [Dam_discharge(i),choose(i)] = min([q1,q2,q3]);

                if abs(Dam_discharge(i)-Dam_discharge(i-1))>max_diff_q
                    if Dam_discharge(i)>Dam_discharge(i-1)
                        Dam_discharge(i) = Dam_discharge(i-1)+max_diff_q;
                    else
                        Dam_discharge(i) = Dam_discharge(i-1)-max_diff_q;
                    end
                end

                if choose(i) ~= 1
                    V2 = V1+(Q1+Q2-Dam_discharge(i-1)-Dam_discharge(i))/2*delta_T;
                    V(i) = V2;
                    H(i) = spline(Z_V(2,:),Z_V(1,:),V(i));
                else
                    Dam_discharge(i) = Q2;
                    V(i) = V0;
                    H(i) = H0;
                end
                if flag_floodcontrol
                    if H(i) > upper_water_level_for_flood_control
                        Dam_safe_flood_discharge(i+1:end) = ones(n2_length-i,1)*max(flood_p1_spline);
                        flag_floodcontrol =false; % 达到防洪高水位，改大坝安全泄量为洪峰，改一次就可以
                    end
                end
            end


            % 画图
            plot(app.UIAxesH3,downstream_safe_flood_discharge-flood_2_spline)
            hold(app.UIAxesH3,"on")
            plot(app.UIAxesH3,flood_p1_spline,'-')
            plot(app.UIAxesH3,Dam_discharge,'-')
            hold(app.UIAxesH3,"off")

            % 结果值的写出
            app.EditFieldH3.Value = max(H);
            app.EditFieldV3.Value = spline(Z_V(1,:),Z_V(2,:),app.EditFieldH3.Value)-spline(Z_V(1,:),Z_V(2,:),app.EditFieldH0.Value);
            app.EditFieldq3.Value = max(Dam_discharge);
            app.UITableH3_q.Data = Dam_discharge;
            app.UITableH3_q.ColumnName = {'调洪下泄流量过程'};
            app.UITableH3_q.RowName = num2cell(1:length(Dam_discharge));

            % 记录校核洪水位H3的水库下泄q、水位H、库容V变化情况
            app.H3_q = Dam_discharge;
            app.H3_H = H;
            app.H3_V = V;
        end

        % Button down function: UIAxesH1
        function UIAxesH1ButtonDown(app, event)
            if length(app.UIAxesH1.Children)==3
                if app.UIAxesH1.Children(end).Visible == "on"
                    app.UIAxesH1.Children(end).Visible = 'off';
                else
                    app.UIAxesH1.Children(end).Visible = 'on';
                end
            end
        end

        % Button down function: UIAxesH2
        function UIAxesH2ButtonDown(app, event)
            if length(app.UIAxesH2.Children)==3
                if app.UIAxesH2.Children(end).Visible == "on"
                    app.UIAxesH2.Children(end).Visible = 'off';
                else
                    app.UIAxesH2.Children(end).Visible = 'on';
                end
            end
        end

        % Button down function: UIAxesH3
        function UIAxesH3ButtonDown(app, event)
            if length(app.UIAxesH3.Children)==3
                if app.UIAxesH3.Children(end).Visible == "on"
                    app.UIAxesH3.Children(end).Visible = 'off';
                else
                    app.UIAxesH3.Children(end).Visible = 'on';
                end
            end
        end

        % Key press function: UITableH3_q
        function UITableH3_qKeyPress(app, event)
            key = event.Key;
            if key == 'q'
                app.UITableH3_q.Data = app.H3_q;
                app.UITableH3_q.ColumnName = {'调洪下泄流量过程'};
            elseif key == 'h'
                app.UITableH3_q.Data = app.H3_H;
                app.UITableH3_q.ColumnName = {'水库水位变化过程'};
            elseif key == 'v'
                app.UITableH3_q.Data = app.H3_V;
                app.UITableH3_q.ColumnName = {'水库库容变化过程'};
            end

            sheetname = ['校核洪水位调洪演算_',app.UITableH3_q.ColumnName{1}];
            callingTable = app.UITableH3_q;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableH2_q
        function UITableH2_qKeyPress(app, event)
            key = event.Key;
            if key == 'q'
                app.UITableH2_q.Data = app.H2_q;
                app.UITableH2_q.ColumnName = {'调洪下泄流量过程'};
            elseif key == 'h'
                app.UITableH2_q.Data = app.H2_H;
                app.UITableH2_q.ColumnName = {'水库水位变化过程'};
            elseif key == 'v'
                app.UITableH2_q.Data = app.H2_V;
                app.UITableH2_q.ColumnName = {'水库库容变化过程'};
            end

            sheetname = ['设计洪水位调洪演算_',app.UITableH2_q.ColumnName{1}];
            callingTable = app.UITableH2_q;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableH1_q
        function UITableH1_qKeyPress(app, event)
            key = event.Key;
            if key == 'q'
                app.UITableH1_q.Data = app.H1_q;
                app.UITableH1_q.ColumnName = {'调洪下泄流量过程'};
            elseif key == 'h'
                app.UITableH1_q.Data = app.H1_H;
                app.UITableH1_q.ColumnName = {'水库水位变化过程'};
            elseif key == 'v'
                app.UITableH1_q.Data = app.H1_V;
                app.UITableH1_q.ColumnName = {'水库库容变化过程'};
            end

            sheetname = ['防洪高水位调洪演算_',app.UITableH1_q.ColumnName{1}];
            callingTable = app.UITableH1_q;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableData4_1
        function UITableData4_1KeyPress(app, event)
            key = event.Key;
            sheetname = '历史洪水数据';
            callingTable = app.UITableData4_1;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableData3_1
        function UITableData3_1KeyPress(app, event)
            key = event.Key;
            sheetname = '流域其他资料';
            callingTable = app.UITableData3_1;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableP3_1_1
        function UITableP3_1_1KeyPress(app, event)
            key = event.Key;

            sheetname = '实测期资料';
            callingTable = app.UITableP3_1_1;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableP3_1_2
        function UITableP3_1_2KeyPress(app, event)
            key = event.Key;
            sheetname = '调查考证期资料';
            callingTable = app.UITableP3_1_2;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableP3_1_3
        function UITableP3_1_3KeyPress(app, event)
            key = event.Key;
            sheetname = '频率计算结果';
            callingTable = app.UITableP3_1_3;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: rainfloodUITable1
        function rainfloodUITable1KeyPress(app, event)
            key = event.Key;
            sheetname = '流域出口设计洪水过程';
            callingTable = app.rainfloodUITable1;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: rainfloodUITable2
        function rainfloodUITable2KeyPress(app, event)
            key = event.Key;
            sheetname = '区间设计洪水过程';
            callingTable = app.rainfloodUITable2;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: rainfloodUITable
        function rainfloodUITableKeyPress(app, event)
            key = event.Key;
            sheetname = '24h面雨量';
            callingTable = app.rainfloodUITable;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableZ_V
        function UITableZ_VKeyPress(app, event)
            key = event.Key;
            sheetname = '库容曲线';
            callingTable = app.UITableZ_V;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableH1_Q
        function UITableH1_QKeyPress(app, event)
            key = event.Key;
            sheetname = '水库相应洪水过程';
            callingTable = app.UITableH1_Q;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableH2_Q
        function UITableH2_QKeyPress(app, event)
            key = event.Key;
            sheetname = '水库设计洪水过程';
            callingTable = app.UITableH2_Q;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Key press function: UITableH3_Q
        function UITableH3_QKeyPress(app, event)
            key = event.Key;
            sheetname = '水库校核洪水过程';
            callingTable = app.UITableH3_Q;
            if key == 'n'
                func_save_excel_n(app,sheetname,callingTable)
            end
            if key == 's'
                func_save_excel_s(app,sheetname,callingTable)
            end
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.Label_C2A.Text = sprintf('$(F_设/F_参)^{%g}',value);
        end

        % Menu selected function: Menu_24
        function Menu_24Selected(app, event)
            % p1 同倍比放大 峰比
            Qmp = app.FloodEditFieldP1_Qm.Value;
            Qmd = app.FloodEditFieldQmd.Value; % 典型洪峰不变
            outputTable = app.FloodUITableP1;
            outputAxes = app.FloodUIAxesP1;

            func_enlarge_samemultiple(app,Qmp,Qmd,outputTable,outputAxes)
        end

        % Menu selected function: Menu_25
        function Menu_25Selected(app, event)
            % 这里的程序普适性适中
            % （除了课设外可能遇到历时超过7天的洪水）
            % p1 同倍比放大 量比
            Wmp = max([app.FloodEditFieldP1_W1.Value,app.FloodEditFieldP1_W3.Value,app.FloodEditFieldP1_W7.Value]); 
            Wmd = max([app.FloodEditFieldW1d.Value,app.FloodEditFieldW3d.Value,app.FloodEditFieldW7d.Value]); %典型洪量不变
            outputTable = app.FloodUITableP1;
            outputAxes = app.FloodUIAxesP1;

            func_enlarge_samemultiple(app,Wmp,Wmd,outputTable,outputAxes)
        end

        % Menu selected function: Menu_26
        function Menu_26Selected(app, event)
            % p2 同倍比放大 峰比
            Qmp = app.FloodEditFieldP2_Qm.Value;
            Qmd = app.FloodEditFieldQmd.Value; % 典型洪峰不变
            outputTable = app.FloodUITableP2;
            outputAxes = app.FloodUIAxesP2;

            func_enlarge_samemultiple(app,Qmp,Qmd,outputTable,outputAxes)
        end

        % Menu selected function: Menu_27
        function Menu_27Selected(app, event)
            % 这里的程序普适性适中
            % （除了课设外可能遇到历时超过7天的洪水）
            % p2 同倍比放大 量比
            Wmp = max([app.FloodEditFieldP2_W1.Value,app.FloodEditFieldP2_W3.Value,app.FloodEditFieldP2_W7.Value]);
            Wmd = max([app.FloodEditFieldW1d.Value,app.FloodEditFieldW3d.Value,app.FloodEditFieldW7d.Value]); %典型洪量不变
            outputTable = app.FloodUITableP2;
            outputAxes = app.FloodUIAxesP2;

            func_enlarge_samemultiple(app,Wmp,Wmd,outputTable,outputAxes)
        end

        % Menu selected function: Menu_28
        function Menu_28Selected(app, event)
            % p3 同倍比放大 峰比
            Qmp = app.FloodEditFieldP3_Qm.Value;
            Qmd = app.FloodEditFieldQmd.Value; % 典型洪峰不变
            outputTable = app.FloodUITableP3;
            outputAxes = app.FloodUIAxesP3;

            func_enlarge_samemultiple(app,Qmp,Qmd,outputTable,outputAxes)
        end

        % Menu selected function: Menu_29
        function Menu_29Selected(app, event)
            % 这里的程序普适性适中
            % （除了课设外可能遇到历时超过7天的洪水）
            % p3 同倍比放大 量比
            Wmp = max([app.FloodEditFieldP3_W1.Value,app.FloodEditFieldP3_W3.Value,app.FloodEditFieldP3_W7.Value]);
            Wmd = max([app.FloodEditFieldW1d.Value,app.FloodEditFieldW3d.Value,app.FloodEditFieldW7d.Value]); %典型洪量不变
            outputTable = app.FloodUITableP3;
            outputAxes = app.FloodUIAxesP3;

            func_enlarge_samemultiple(app,Wmp,Wmd,outputTable,outputAxes)
        end

        % Menu selected function: Menu2_1
        function Menu2_1Selected(app, event)
            url = 'https://succinct-football-b0d.notion.site/HaHC_App-493e2754ca7b4f4b951cd424f6c3c9ef';
            web(url);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '水文水利计算';
            app.UIFigure.Icon = 'logo.png';
            app.Menu1 = uimenu(app.UIFigure);
            app.Menu1.Tooltip = {''};
            app.Menu1.Text = '特定化预设';
            app.Menu1_1 = uimenu(app.Menu1);
            app.Menu1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_1Selected, true);
            app.Menu1_1.Tooltip = {'一键导入2019级三峡大学课设数据'};
            app.Menu1_1.Text = '一键导入全部数据（课设）';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '帮助';
            app.Menu2_1 = uimenu(app.Menu_2);
            app.Menu2_1.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1Selected, true);
            app.Menu2_1.Text = '帮助文档';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup1 = uitabgroup(app.GridLayout);
            app.Tab_Data = uitab(app.TabGroup1);
            app.Tab_Data.Title = '导入数据';
            app.GridLayout1 = uigridlayout(app.Tab_Data);
            app.Panel1_4 = uipanel(app.GridLayout1);
            app.Panel1_4.Title = '历史洪水资料';
            app.GridLayout1_4_1 = uigridlayout(app.Panel1_4);
            app.UITableData4_1 = uitable(app.GridLayout1_4_1);
            app.UITableData4_1.ColumnName = '';
            app.UITableData4_1.RowName = {};
            app.UITableData4_1.ColumnSortable = true;
            app.UITableData4_1.ColumnEditable = true;
            app.UITableData4_1.Tag = 'table1_4';
            app.UITableData4_1.KeyPressFcn = createCallbackFcn(app, @UITableData4_1KeyPress, true);
            app.Panel1_3 = uipanel(app.GridLayout1);
            app.Panel1_3.Title = '流域其他资料';
            app.GridLayout1_3_1 = uigridlayout(app.Panel1_3);
            app.UITableData3_1 = uitable(app.GridLayout1_3_1);
            app.UITableData3_1.ColumnName = '';
            app.UITableData3_1.RowName = {};
            app.UITableData3_1.ColumnSortable = true;
            app.UITableData3_1.ColumnEditable = true;
            app.UITableData3_1.Tag = 'table1_3';
            app.UITableData3_1.KeyPressFcn = createCallbackFcn(app, @UITableData3_1KeyPress, true);
            app.Panel1_2 = uipanel(app.GridLayout1);
            app.Panel1_2.Tooltip = {''};
            app.Panel1_2.Title = '水文站典型洪水流量';
            app.GridLayout1_2 = uigridlayout(app.Panel1_2);
            app.TabGroupData_2 = uitabgroup(app.GridLayout1_2);
            app.TabData2_1 = uitab(app.TabGroupData_2);
            app.TabData2_1.Tooltip = {''};
            app.TabData2_1.Title = '导入典型洪水数据';
            app.GridLayout1_2_1 = uigridlayout(app.TabData2_1);
            app.UITableData2_1 = uitable(app.GridLayout1_2_1);
            app.UITableData2_1.ColumnName = '';
            app.UITableData2_1.RowName = {};
            app.UITableData2_1.CellSelectionCallback = createCallbackFcn(app, @UITableData2_1CellSelection, true);
            app.UITableData2_1.Tag = 'table1_2';
            app.UITableData2_1.KeyPressFcn = createCallbackFcn(app, @UITableData2_1KeyPress, true);
            app.TabData2_2 = uitab(app.TabGroupData_2);
            app.TabData2_2.Tooltip = {'如果之前已经选过某列数据，如再次想要选中该列画图，需要先点击同一表格其他位置，来重置表格选中'};
            app.TabData2_2.Title = '整理数据时间格式';
            app.GridLayout1_2_2 = uigridlayout(app.TabData2_2);
            app.UITableData2_2 = uitable(app.GridLayout1_2_2);
            app.UITableData2_2.ColumnName = '';
            app.UITableData2_2.RowName = {};
            app.UITableData2_2.CellSelectionCallback = createCallbackFcn(app, @UITableData2_2CellSelection, true);
            app.UITableData2_2.KeyPressFcn = createCallbackFcn(app, @UITableData2_2KeyPress, true);
            app.TabData2_3 = uitab(app.TabGroupData_2);
            app.TabData2_3.Tooltip = {''};
            app.TabData2_3.Title = '插值为等时段数据';
            app.GridLayout26 = uigridlayout(app.TabData2_3);
            app.UITableData2_3 = uitable(app.GridLayout26);
            app.UITableData2_3.ColumnName = '';
            app.UITableData2_3.RowName = {};
            app.UITableData2_3.CellSelectionCallback = createCallbackFcn(app, @UITableData2_3CellSelection, true);
            app.UITableData2_3.KeyPressFcn = createCallbackFcn(app, @UITableData2_3KeyPress, true);
            app.Panel1_1 = uipanel(app.GridLayout1);
            app.Panel1_1.Title = '水文站洪水特征统计';
            app.GridLayout1_1 = uigridlayout(app.Panel1_1);
            app.TabGroupData_1 = uitabgroup(app.GridLayout1_1);
            app.TabData1_1 = uitab(app.TabGroupData_1);
            app.TabData1_1.Title = '导入洪水特征统计数据';
            app.GridLayout1_1_1 = uigridlayout(app.TabData1_1);
            app.UITableData1_1 = uitable(app.GridLayout1_1_1);
            app.UITableData1_1.ColumnName = '';
            app.UITableData1_1.RowName = {};
            app.UITableData1_1.CellSelectionCallback = createCallbackFcn(app, @UITableData1_1CellSelection, true);
            app.UITableData1_1.KeyPressFcn = createCallbackFcn(app, @UITableData1_1KeyPress, true);
            app.UITableData1_1.KeyReleaseFcn = createCallbackFcn(app, @UITableData1_1KeyRelease, true);
            app.TabData1_2 = uitab(app.TabGroupData_1);
            app.TabData1_2.Title = '数据的插补延展';
            app.TabData1_2.Tag = 'table1_1';
            app.GridLayout1_1_2 = uigridlayout(app.TabData1_2);
            app.UITableData1_2 = uitable(app.GridLayout1_1_2);
            app.UITableData1_2.ColumnName = '';
            app.UITableData1_2.RowName = {};
            app.UITableData1_2.CellSelectionCallback = createCallbackFcn(app, @UITableData1_2CellSelection, true);
            app.UITableData1_2.KeyPressFcn = createCallbackFcn(app, @UITableData1_2KeyPress, true);
            app.Panel_5 = uipanel(app.GridLayout1);
            app.Panel_5.Title = '图示化表格数据';
            app.GridLayout27 = uigridlayout(app.Panel_5);
            app.TabGroupData_3 = uitabgroup(app.GridLayout27);
            app.TabData3_1 = uitab(app.TabGroupData_3);
            app.TabData3_1.Title = '图示化1：水文站洪水特征统计';
            app.GridLayout28 = uigridlayout(app.TabData3_1);
            app.UIAxesData1 = uiaxes(app.GridLayout28);
            zlabel(app.UIAxesData1, 'Z')
            app.TabData3_2 = uitab(app.TabGroupData_3);
            app.TabData3_2.Title = '图示化2：水文站典型洪水流量';
            app.GridLayout29 = uigridlayout(app.TabData3_2);
            app.UIAxesData2 = uiaxes(app.GridLayout29);
            zlabel(app.UIAxesData2, 'Z')
            app.Tab_P3 = uitab(app.TabGroup1);
            app.Tab_P3.Title = '经验适线';
            app.Tab_P3.ButtonDownFcn = createCallbackFcn(app, @Tab_P3ButtonDown, true);
            app.GridLayout2 = uigridlayout(app.Tab_P3);
            app.PanelP3_2 = uipanel(app.GridLayout2);
            app.PanelP3_2.Title = '适线';
            app.GridLayout4 = uigridlayout(app.PanelP3_2);
            app.TabGroupP3_2 = uitabgroup(app.GridLayout4);
            app.TabP3_2_1 = uitab(app.TabGroupP3_2);
            app.TabP3_2_1.Title = '适线';
            app.GridLayout18 = uigridlayout(app.TabP3_2_1);
            app.UIAxesP3_1 = uiaxes(app.GridLayout18);
            zlabel(app.UIAxesP3_1, 'Z')
            app.TabGroupCvCs1 = uitabgroup(app.GridLayout18);
            app.TabCvCs1_2 = uitab(app.TabGroupCvCs1);
            app.TabCvCs1_2.Title = '不固定Cs/Cv';
            app.GridLayout19_4 = uigridlayout(app.TabCvCs1_2);
            app.CsEditField1_13 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_13.Limits = [0 Inf];
            app.CsEditField1_13.ValueDisplayFormat = '%.3f';
            app.CsEditField1_13.Editable = 'off';
            app.CsEditField1_13.Enable = 'off';
            app.CsEditField1_13.Tooltip = {'不可以直接调整'};
            app.CsEditField1_13.Value = 1;
            app.CsEditField1_12 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_12.Limits = [0 Inf];
            app.CsEditField1_12.ValueDisplayFormat = '%.3f';
            app.CsEditField1_12.Editable = 'off';
            app.CsEditField1_12.Enable = 'off';
            app.CsEditField1_12.Tooltip = {'不能直接调整'};
            app.CsEditField1_12.Value = 1;
            app.CsEditField1_11 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_11.Limits = [0 Inf];
            app.CsEditField1_11.ValueDisplayFormat = '%.3f';
            app.CsEditField1_11.Editable = 'off';
            app.CsEditField1_11.Enable = 'off';
            app.CsEditField1_11.Tooltip = {'不能直接调整'};
            app.CsEditField1_11.Value = 1;
            app.CsEditField1_10 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_10.Limits = [0 100];
            app.CsEditField1_10.ValueChangedFcn = createCallbackFcn(app, @CsEditField1_10ValueChanged, true);
            app.CsEditField1_10.Value = 1;
            app.CsEditField1_9 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_9.Editable = 'off';
            app.CsEditField1_9.Enable = 'off';
            app.CsEditField1_9.Tooltip = {'优化适线——目标函数值（误差）'};
            app.CsEditField1_9.Value = Inf;
            app.CsLabel1_8 = uilabel(app.GridLayout19_4);
            app.CsLabel1_8.Tooltip = {'优化适线——对应概率的设计值'};
            app.CsLabel1_8.Text = '';
            app.CsButton1_8 = uibutton(app.GridLayout19_4, 'state');
            app.CsButton1_8.ValueChangedFcn = createCallbackFcn(app, @CsButton1_8ValueChanged, true);
            app.CsButton1_8.Text = '优化适线';
            app.CsDropDown1_7 = uidropdown(app.GridLayout19_4);
            app.CsDropDown1_7.Items = {'OLS', 'ABS', 'WLS'};
            app.CsDropDown1_7.ItemsData = {'1', '2', '3'};
            app.CsDropDown1_7.Value = '2';
            app.CsLabel1_6 = uilabel(app.GridLayout19_4);
            app.CsLabel1_6.Tooltip = {'经验适线——对应概率的设计值'};
            app.CsLabel1_6.Text = '';
            app.CsButton1_6 = uibutton(app.GridLayout19_4, 'state');
            app.CsButton1_6.ValueChangedFcn = createCallbackFcn(app, @CsButton1_6ValueChanged, true);
            app.CsButton1_6.Text = '经验适线';
            app.CsEditField1_5 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_5.Editable = 'off';
            app.CsEditField1_5.Enable = 'off';
            app.CsEditField1_5.Tooltip = {'经验适线——目标函数值（误差）'};
            app.CsEditField1_5.Value = Inf;
            app.CsEditField1_4 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_4.Limits = [0 100];
            app.CsEditField1_4.ValueChangedFcn = createCallbackFcn(app, @CsEditField1_4ValueChanged, true);
            app.CsEditField1_4.Value = 1;
            app.CsEditField1_3 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_3.Limits = [0 Inf];
            app.CsEditField1_3.ValueDisplayFormat = '%.3f';
            app.CsEditField1_3.Editable = 'off';
            app.CsEditField1_3.Enable = 'off';
            app.CsEditField1_3.Tooltip = {'不可以直接调整'};
            app.CsEditField1_3.Value = 1;
            app.CsEditField1_2 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_2.Limits = [0 10];
            app.CsEditField1_2.ValueDisplayFormat = '%.3f';
            app.CsEditField1_2.ValueChangedFcn = createCallbackFcn(app, @CsEditField1_2ValueChanged, true);
            app.CsEditField1_2.Tooltip = {''};
            app.CsEditField1_2.Value = 1;
            app.CsEditField1_1 = uieditfield(app.GridLayout19_4, 'numeric');
            app.CsEditField1_1.Limits = [0 10];
            app.CsEditField1_1.ValueDisplayFormat = '%.3f';
            app.CsEditField1_1.ValueChangedFcn = createCallbackFcn(app, @CsEditField1_1ValueChanged, true);
            app.CsEditField1_1.Value = 1;
            app.CsCvEditField_7Label = uilabel(app.GridLayout19_4);
            app.CsCvEditField_7Label.Text = 'Cs/Cv';
            app.CsCvEditField_8Label = uilabel(app.GridLayout19_4);
            app.CsCvEditField_8Label.Text = 'Cs/Cv';
            app.CvEditField_7Label = uilabel(app.GridLayout19_4);
            app.CvEditField_7Label.Text = 'Cv';
            app.CsEditField_7Label = uilabel(app.GridLayout19_4);
            app.CsEditField_7Label.Text = 'Cs';
            app.CvEditField_8Label = uilabel(app.GridLayout19_4);
            app.CvEditField_8Label.Text = 'Cv';
            app.CsEditField_8Label = uilabel(app.GridLayout19_4);
            app.CsEditField_8Label.Text = 'Cs';
            app.DropDown_4Label = uilabel(app.GridLayout19_4);
            app.DropDown_4Label.Text = '目标函数';
            app.EditField_15Label = uilabel(app.GridLayout19_4);
            app.EditField_15Label.Text = '概率(%)';
            app.EditField_17Label = uilabel(app.GridLayout19_4);
            app.EditField_17Label.Enable = 'off';
            app.EditField_17Label.Text = '适线误差和';
            app.EditField_14Label = uilabel(app.GridLayout19_4);
            app.EditField_14Label.Enable = 'off';
            app.EditField_14Label.Text = '适线误差和';
            app.EditField_19Label = uilabel(app.GridLayout19_4);
            app.EditField_19Label.Text = '概率(%)';
            app.TabCv1 = uitab(app.TabGroupCvCs1);
            app.TabCv1.Title = '固定Cs/Cv';
            app.GridLayout19 = uigridlayout(app.TabCv1);
            app.CvEditField1_13 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_13.Limits = [0 10];
            app.CvEditField1_13.ValueDisplayFormat = '%11.3g';
            app.CvEditField1_13.ValueChangedFcn = createCallbackFcn(app, @CvEditField1_13ValueChanged, true);
            app.CvEditField1_13.Tooltip = {''};
            app.CvEditField1_13.Value = 1;
            app.CvEditField1_12 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_12.Limits = [0 10];
            app.CvEditField1_12.ValueDisplayFormat = '%11.3g';
            app.CvEditField1_12.Editable = 'off';
            app.CvEditField1_12.Enable = 'off';
            app.CvEditField1_12.Tooltip = {'不可以直接调整'};
            app.CvEditField1_12.Value = 1;
            app.CsCvEditFieldLabel = uilabel(app.GridLayout19);
            app.CsCvEditFieldLabel.Text = 'Cs/Cv';
            app.CvEditField1_11 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_11.Limits = [0 10];
            app.CvEditField1_11.ValueDisplayFormat = '%11.3g';
            app.CvEditField1_11.Editable = 'off';
            app.CvEditField1_11.Enable = 'off';
            app.CvEditField1_11.Tooltip = {'不可以直接调整'};
            app.CvEditField1_11.Value = 1;
            app.CvEditField1_10 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_10.Limits = [0 100];
            app.CvEditField1_10.Value = 1;
            app.CsCvEditField_2Label = uilabel(app.GridLayout19);
            app.CsCvEditField_2Label.Text = 'Cs/Cv';
            app.CvEditFieldLabel = uilabel(app.GridLayout19);
            app.CvEditFieldLabel.Text = 'Cv';
            app.CsEditFieldLabel = uilabel(app.GridLayout19);
            app.CsEditFieldLabel.Text = 'Cs';
            app.CvEditField1_9 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_9.Editable = 'off';
            app.CvEditField1_9.Enable = 'off';
            app.CvEditField1_9.Tooltip = {'优化适线——目标函数值（误差）'};
            app.CvEditField1_9.Value = Inf;
            app.CsEditField_2Label = uilabel(app.GridLayout19);
            app.CsEditField_2Label.Text = 'Cs';
            app.CvEditField1_3 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_3.Limits = [0 10];
            app.CvEditField1_3.ValueDisplayFormat = '%11.3g';
            app.CvEditField1_3.ValueChangedFcn = createCallbackFcn(app, @CvEditField1_3ValueChanged, true);
            app.CvEditField1_3.Value = 1;
            app.Label_3 = uilabel(app.GridLayout19);
            app.Label_3.Text = '目标函数';
            app.CvEditField_2Label = uilabel(app.GridLayout19);
            app.CvEditField_2Label.Text = 'Cv';
            app.CvLabel1_8 = uilabel(app.GridLayout19);
            app.CvLabel1_8.Tooltip = {'优化适线——对应概率的设计值'};
            app.CvLabel1_8.Text = '';
            app.CvButton1_8 = uibutton(app.GridLayout19, 'state');
            app.CvButton1_8.ValueChangedFcn = createCallbackFcn(app, @CvButton1_8ValueChanged, true);
            app.CvButton1_8.Text = '优化适线';
            app.Label_6 = uilabel(app.GridLayout19);
            app.Label_6.Text = '概率(%)';
            app.CvLabel1_6 = uilabel(app.GridLayout19);
            app.CvLabel1_6.Tooltip = {'经验适线——对应概率的设计值'};
            app.CvLabel1_6.Text = '';
            app.CvButton1_6 = uibutton(app.GridLayout19, 'state');
            app.CvButton1_6.ValueChangedFcn = createCallbackFcn(app, @CvButton1_6ValueChanged, true);
            app.CvButton1_6.Text = '经验适线';
            app.Label_4 = uilabel(app.GridLayout19);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '适线误差和';
            app.CvEditField1_5 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_5.Editable = 'off';
            app.CvEditField1_5.Enable = 'off';
            app.CvEditField1_5.Tooltip = {'经验适线——目标函数值（误差）'};
            app.CvEditField1_5.Value = Inf;
            app.CvEditField1_1 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_1.Limits = [0 10];
            app.CvEditField1_1.ValueDisplayFormat = '%11.3g';
            app.CvEditField1_1.ValueChangedFcn = createCallbackFcn(app, @CvEditField1_1ValueChanged, true);
            app.CvEditField1_1.Value = 1;
            app.CvEditField1_4 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_4.Limits = [0 100];
            app.CvEditField1_4.ValueChangedFcn = createCallbackFcn(app, @CvEditField1_4ValueChanged, true);
            app.CvEditField1_4.Value = 1;
            app.CvDropDown1_7 = uidropdown(app.GridLayout19);
            app.CvDropDown1_7.Items = {'OLS', 'ABS', 'WLS'};
            app.CvDropDown1_7.ItemsData = {'1', '2', '3'};
            app.CvDropDown1_7.Value = '2';
            app.CvEditField1_2 = uieditfield(app.GridLayout19, 'numeric');
            app.CvEditField1_2.Limits = [0 Inf];
            app.CvEditField1_2.ValueDisplayFormat = '%11.3g';
            app.CvEditField1_2.Editable = 'off';
            app.CvEditField1_2.Enable = 'off';
            app.CvEditField1_2.Tooltip = {'不可以直接调整'};
            app.CvEditField1_2.Value = 1;
            app.EditField_9Label = uilabel(app.GridLayout19);
            app.EditField_9Label.Enable = 'off';
            app.EditField_9Label.Text = '适线误差和';
            app.EditField_18Label = uilabel(app.GridLayout19);
            app.EditField_18Label.Text = '概率(%)';
            app.PanelP3_1 = uipanel(app.GridLayout2);
            app.PanelP3_1.Title = '频率计算';
            app.GridLayout3 = uigridlayout(app.PanelP3_1);
            app.TabGroupP3_1 = uitabgroup(app.GridLayout3);
            app.TabP3_1_1 = uitab(app.TabGroupP3_1);
            app.TabP3_1_1.Title = '频率计算';
            app.GridLayout5 = uigridlayout(app.TabP3_1_1);
            app.PanelP3_1_3 = uipanel(app.GridLayout5);
            app.PanelP3_1_3.Title = '频率计算结果';
            app.GridLayout17 = uigridlayout(app.PanelP3_1_3);
            app.UITableP3_1_3 = uitable(app.GridLayout17);
            app.UITableP3_1_3.ColumnName = '';
            app.UITableP3_1_3.RowName = {};
            app.UITableP3_1_3.KeyPressFcn = createCallbackFcn(app, @UITableP3_1_3KeyPress, true);
            app.PanelP3_1_2 = uipanel(app.GridLayout5);
            app.PanelP3_1_2.Title = '调查考证期资料';
            app.GridLayout15 = uigridlayout(app.PanelP3_1_2);
            app.UITableP3_1_2 = uitable(app.GridLayout15);
            app.UITableP3_1_2.ColumnName = '';
            app.UITableP3_1_2.RowName = {};
            app.UITableP3_1_2.KeyPressFcn = createCallbackFcn(app, @UITableP3_1_2KeyPress, true);
            app.PanelP3_1_1 = uipanel(app.GridLayout5);
            app.PanelP3_1_1.Title = '实测期资料';
            app.GridLayout14 = uigridlayout(app.PanelP3_1_1);
            app.UITableP3_1_1 = uitable(app.GridLayout14);
            app.UITableP3_1_1.ColumnName = '';
            app.UITableP3_1_1.RowName = {};
            app.UITableP3_1_1.KeyPressFcn = createCallbackFcn(app, @UITableP3_1_1KeyPress, true);
            app.Tab_Flood = uitab(app.TabGroup1);
            app.Tab_Flood.Title = '（流量）设计洪水';
            app.GridLayout20 = uigridlayout(app.Tab_Flood);
            app.Panel = uipanel(app.GridLayout20);
            app.Panel.Title = '         ';
            app.GridLayout21 = uigridlayout(app.Panel);
            app.Panel_3 = uipanel(app.GridLayout21);
            app.Panel_3.Title = '输入拟定设计洪水数据';
            app.GridLayout23 = uigridlayout(app.Panel_3);
            app.FloodEditFieldP3_W7 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP3_W7.Limits = [0 Inf];
            app.FloodEditFieldP3_W7.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP3_W7.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP3_W3 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP3_W3.Limits = [0 Inf];
            app.FloodEditFieldP3_W3.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP3_W3.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP3_W3.Value = 136904200;
            app.FloodEditFieldP3_W1 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP3_W1.Limits = [0 Inf];
            app.FloodEditFieldP3_W1.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP3_W1.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP3_W1.Value = 93492000;
            app.FloodEditFieldP3_Qm = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP3_Qm.Limits = [0 Inf];
            app.FloodEditFieldP3_Qm.ValueDisplayFormat = '%11.6g';
            app.FloodEditFieldP3_Qm.Tooltip = {'流量统一单位为m3/s'};
            app.FloodEditFieldP3_Qm.Value = 2969.64;
            app.FloodEditFieldP3 = uieditfield(app.GridLayout23, 'text');
            app.FloodEditFieldP3.Value = '0.05';
            app.FloodButtonP3 = uibutton(app.GridLayout23, 'state');
            app.FloodButtonP3.Text = 'P3(%)';
            app.FloodEditFieldP2_W7 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP2_W7.Limits = [0 Inf];
            app.FloodEditFieldP2_W7.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP2_W7.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP2_W3 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP2_W3.Limits = [0 Inf];
            app.FloodEditFieldP2_W3.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP2_W3.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP2_W3.Value = 88384100;
            app.FloodEditFieldP2_W1 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP2_W1.Limits = [0 Inf];
            app.FloodEditFieldP2_W1.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP2_W1.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP2_W1.Value = 57485400;
            app.FloodEditFieldP2_Qm = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP2_Qm.Limits = [0 Inf];
            app.FloodEditFieldP2_Qm.ValueDisplayFormat = '%11.6g';
            app.FloodEditFieldP2_Qm.Tooltip = {'流量统一单位为m3/s'};
            app.FloodEditFieldP2_Qm.Value = 1736.64;
            app.FloodEditFieldP2 = uieditfield(app.GridLayout23, 'text');
            app.FloodEditFieldP2.Value = '1';
            app.FloodButtonP2 = uibutton(app.GridLayout23, 'state');
            app.FloodButtonP2.Text = 'P2(%)';
            app.FloodEditFieldP1_W7 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP1_W7.Limits = [0 Inf];
            app.FloodEditFieldP1_W7.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP1_W7.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP1_W3 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP1_W3.Limits = [0 Inf];
            app.FloodEditFieldP1_W3.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP1_W3.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP1_W3.Value = 62317000;
            app.FloodEditFieldP1_W1 = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP1_W1.Limits = [0 Inf];
            app.FloodEditFieldP1_W1.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP1_W1.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldP1_W1.Value = 38773900;
            app.FloodEditFieldP1_Qm = uieditfield(app.GridLayout23, 'numeric');
            app.FloodEditFieldP1_Qm.Limits = [0 Inf];
            app.FloodEditFieldP1_Qm.ValueDisplayFormat = '%11.10g';
            app.FloodEditFieldP1_Qm.Tooltip = {'流量统一单位为m3/s'};
            app.FloodEditFieldP1_Qm.Value = 1120.9;
            app.FloodEditFieldP1 = uieditfield(app.GridLayout23, 'text');
            app.FloodEditFieldP1.Value = '5';
            app.FloodButtonP1 = uibutton(app.GridLayout23, 'state');
            app.FloodButtonP1.Text = 'P1(%)';
            app.W_3P_7Label = uilabel(app.GridLayout23);
            app.W_3P_7Label.Interpreter = 'latex';
            app.W_3P_7Label.Text = '$W_{3P_7}';
            app.W_3P_3Label = uilabel(app.GridLayout23);
            app.W_3P_3Label.Interpreter = 'latex';
            app.W_3P_3Label.Text = '$W_{3P_3}';
            app.W_3P_1Label = uilabel(app.GridLayout23);
            app.W_3P_1Label.Interpreter = 'latex';
            app.W_3P_1Label.Text = '$W_{3P_1}';
            app.W_1P_3Label = uilabel(app.GridLayout23);
            app.W_1P_3Label.Interpreter = 'latex';
            app.W_1P_3Label.Text = '$W_{1P_3}';
            app.W_7P_1Label = uilabel(app.GridLayout23);
            app.W_7P_1Label.Interpreter = 'latex';
            app.W_7P_1Label.Text = '$W_{7P_1}';
            app.W_7P_2Label = uilabel(app.GridLayout23);
            app.W_7P_2Label.Interpreter = 'latex';
            app.W_7P_2Label.Text = '$W_{7P_2}';
            app.W_3P_2Label = uilabel(app.GridLayout23);
            app.W_3P_2Label.Interpreter = 'latex';
            app.W_3P_2Label.Text = '$W_{3P_2}';
            app.W_1P_2Label = uilabel(app.GridLayout23);
            app.W_1P_2Label.Interpreter = 'latex';
            app.W_1P_2Label.Text = '$W_{1P_2}';
            app.Q_mp_1Label = uilabel(app.GridLayout23);
            app.Q_mp_1Label.Interpreter = 'latex';
            app.Q_mp_1Label.Text = '$Q_{mp_1}';
            app.Q_mp_2Label = uilabel(app.GridLayout23);
            app.Q_mp_2Label.Interpreter = 'latex';
            app.Q_mp_2Label.Text = '$Q_{mp_2}';
            app.Q_mp_3Label = uilabel(app.GridLayout23);
            app.Q_mp_3Label.Interpreter = 'latex';
            app.Q_mp_3Label.Text = '$Q_{mp_3}';
            app.W_1P_1Label = uilabel(app.GridLayout23);
            app.W_1P_1Label.Interpreter = 'latex';
            app.W_1P_1Label.Text = '$W_{1P_1}';
            app.Panel_4 = uipanel(app.GridLayout21);
            app.Panel_4.Title = '典型洪水最大洪量';
            app.GridLayout24 = uigridlayout(app.Panel_4);
            app.W1dEditFieldLabel = uilabel(app.GridLayout24);
            app.W1dEditFieldLabel.Text = '典型洪水最大一日洪量(W1d)';
            app.W3dEditFieldLabel = uilabel(app.GridLayout24);
            app.W3dEditFieldLabel.Text = '典型洪水最大三日洪量(W3d)';
            app.FloodEditFieldW7d = uieditfield(app.GridLayout24, 'numeric');
            app.FloodEditFieldW7d.ValueDisplayFormat = '%11.0f';
            app.FloodEditFieldW7d.Tooltip = {'洪量统一单位为m3'};
            app.W7dLabel = uilabel(app.GridLayout24);
            app.W7dLabel.Text = '典型洪水最大七日洪量(W7d)';
            app.FloodEditFieldW3d = uieditfield(app.GridLayout24, 'numeric');
            app.FloodEditFieldW3d.ValueDisplayFormat = '%11.f';
            app.FloodEditFieldW3d.Tooltip = {'洪量统一单位为m3'};
            app.FloodEditFieldW1d = uieditfield(app.GridLayout24, 'numeric');
            app.FloodEditFieldW1d.ValueDisplayFormat = '%11.f';
            app.FloodEditFieldW1d.Tooltip = {'洪量统一单位为m3'};
            app.QmdLabel = uilabel(app.GridLayout24);
            app.QmdLabel.Text = '典型洪水洪峰流量(Qmd)';
            app.FloodEditFieldQmd = uieditfield(app.GridLayout24, 'numeric');
            app.FloodEditFieldQmd.ValueDisplayFormat = '%11.6g';
            app.FloodEditFieldQmd.Tooltip = {'流量统一单位为m3/s'};
            app.Panel_2 = uipanel(app.GridLayout20);
            app.Panel_2.Title = '典型洪水结果展示';
            app.GridLayout22 = uigridlayout(app.Panel_2);
            app.TabGroup2 = uitabgroup(app.GridLayout22);
            app.P1Tab = uitab(app.TabGroup2);
            app.P1Tab.Title = 'P1(%)设计洪水';
            app.GridLayout25 = uigridlayout(app.P1Tab);
            app.FloodUIAxesP1 = uiaxes(app.GridLayout25);
            zlabel(app.FloodUIAxesP1, 'Z')
            app.FloodUIAxesP1.ButtonDownFcn = createCallbackFcn(app, @FloodUIAxesP1ButtonDown, true);
            app.FloodUITableP1 = uitable(app.GridLayout25);
            app.FloodUITableP1.ColumnName = '';
            app.FloodUITableP1.RowName = {};
            app.FloodUITableP1.CellSelectionCallback = createCallbackFcn(app, @FloodUITableP1CellSelection, true);
            app.FloodUITableP1.ButtonDownFcn = createCallbackFcn(app, @FloodUITableP1ButtonDown, true);
            app.FloodUITableP1.KeyPressFcn = createCallbackFcn(app, @FloodUITableP1KeyPress, true);
            app.P2Tab = uitab(app.TabGroup2);
            app.P2Tab.Title = 'P2(%)设计洪水';
            app.GridLayout25_2 = uigridlayout(app.P2Tab);
            app.FloodUIAxesP2 = uiaxes(app.GridLayout25_2);
            zlabel(app.FloodUIAxesP2, 'Z')
            app.FloodUIAxesP2.ButtonDownFcn = createCallbackFcn(app, @FloodUIAxesP2ButtonDown, true);
            app.FloodUITableP2 = uitable(app.GridLayout25_2);
            app.FloodUITableP2.ColumnName = '';
            app.FloodUITableP2.RowName = {};
            app.FloodUITableP2.KeyPressFcn = createCallbackFcn(app, @FloodUITableP2KeyPress, true);
            app.P3Tab = uitab(app.TabGroup2);
            app.P3Tab.Title = 'P3(%)设计洪水';
            app.GridLayout25_3 = uigridlayout(app.P3Tab);
            app.FloodUIAxesP3 = uiaxes(app.GridLayout25_3);
            zlabel(app.FloodUIAxesP3, 'Z')
            app.FloodUIAxesP3.ButtonDownFcn = createCallbackFcn(app, @FloodUIAxesP3ButtonDown, true);
            app.FloodUITableP3 = uitable(app.GridLayout25_3);
            app.FloodUITableP3.ColumnName = '';
            app.FloodUITableP3.RowName = {};
            app.FloodUITableP3.ButtonDownFcn = createCallbackFcn(app, @FloodUITableP3ButtonDown, true);
            app.FloodUITableP3.KeyPressFcn = createCallbackFcn(app, @FloodUITableP3KeyPress, true);
            app.Tab_rainflood = uitab(app.TabGroup1);
            app.Tab_rainflood.Title = '（暴雨）设计洪水';
            app.Tab_rainflood.ButtonDownFcn = createCallbackFcn(app, @Tab_rainfloodButtonDown, true);
            app.GridLayout30 = uigridlayout(app.Tab_rainflood);
            app.Panel_6 = uipanel(app.GridLayout30);
            app.Panel_6.Title = '点雨量输入';
            app.GridLayout31 = uigridlayout(app.Panel_6);
            app.overlineH_1Label = uilabel(app.GridLayout31);
            app.overlineH_1Label.Interpreter = 'latex';
            app.overlineH_1Label.Text = '$\overline{H}_1';
            app.overlineH_1EditField = uieditfield(app.GridLayout31, 'numeric');
            app.overlineH_1EditField.Limits = [0 Inf];
            app.overlineH_1EditField.Value = 40;
            app.overlineH_6Label = uilabel(app.GridLayout31);
            app.overlineH_6Label.Interpreter = 'latex';
            app.overlineH_6Label.Text = '$\overline{H}_6';
            app.overlineH_6EditField = uieditfield(app.GridLayout31, 'numeric');
            app.overlineH_6EditField.Limits = [0 Inf];
            app.overlineH_6EditField.Value = 68;
            app.overlineH_24Label = uilabel(app.GridLayout31);
            app.overlineH_24Label.Interpreter = 'latex';
            app.overlineH_24Label.Text = '$\overline{H}_{24}';
            app.overlineH_24EditField = uieditfield(app.GridLayout31, 'numeric');
            app.overlineH_24EditField.Limits = [0 Inf];
            app.overlineH_24EditField.Value = 100;
            app.C_V1EditFieldLabel = uilabel(app.GridLayout31);
            app.C_V1EditFieldLabel.Interpreter = 'latex';
            app.C_V1EditFieldLabel.Text = '$C_{V1}';
            app.C_V1EditField = uieditfield(app.GridLayout31, 'numeric');
            app.C_V1EditField.Limits = [0 Inf];
            app.C_V1EditField.Value = 0.42;
            app.C_V6EditFieldLabel = uilabel(app.GridLayout31);
            app.C_V6EditFieldLabel.Interpreter = 'latex';
            app.C_V6EditFieldLabel.Text = '$C_{V6}';
            app.C_V6EditField = uieditfield(app.GridLayout31, 'numeric');
            app.C_V6EditField.Limits = [0 Inf];
            app.C_V6EditField.Value = 0.47;
            app.C_V24EditFieldLabel = uilabel(app.GridLayout31);
            app.C_V24EditFieldLabel.Interpreter = 'latex';
            app.C_V24EditFieldLabel.Text = '$C_{V24}';
            app.C_V24EditField = uieditfield(app.GridLayout31, 'numeric');
            app.C_V24EditField.Limits = [0 Inf];
            app.C_V24EditField.Value = 0.54;
            app.PLabel = uilabel(app.GridLayout31);
            app.PLabel.Interpreter = 'latex';
            app.PLabel.Text = '$P(\%)';
            app.PEditField = uieditfield(app.GridLayout31, 'numeric');
            app.PEditField.Limits = [0 100];
            app.PEditField.Value = 5;
            app.C_SC_VEditFieldLabel = uilabel(app.GridLayout31);
            app.C_SC_VEditFieldLabel.Interpreter = 'latex';
            app.C_SC_VEditFieldLabel.Text = '$C_S/C_V';
            app.C_SC_VEditField = uieditfield(app.GridLayout31, 'numeric');
            app.C_SC_VEditField.Limits = [0 Inf];
            app.C_SC_VEditField.Value = 3.5;
            app.rainfloodButton4 = uibutton(app.GridLayout31, 'push');
            app.rainfloodButton4.ButtonPushedFcn = createCallbackFcn(app, @rainfloodButton4Pushed, true);
            app.rainfloodButton4.Text = '计算设计点雨量';
            app.Panel_7 = uipanel(app.GridLayout30);
            app.Panel_7.Title = '点雨量输出';
            app.GridLayout32 = uigridlayout(app.Panel_7);
            app.H_1EditFieldLabel = uilabel(app.GridLayout32);
            app.H_1EditFieldLabel.Interpreter = 'latex';
            app.H_1EditFieldLabel.Text = '$H_{1点}';
            app.H_1dianEditField = uieditfield(app.GridLayout32, 'numeric');
            app.H_1dianEditField.ValueDisplayFormat = '%.1f';
            app.H_6EditFieldLabel = uilabel(app.GridLayout32);
            app.H_6EditFieldLabel.Interpreter = 'latex';
            app.H_6EditFieldLabel.Text = '$H_{6点}';
            app.H_6dianEditField = uieditfield(app.GridLayout32, 'numeric');
            app.H_6dianEditField.ValueDisplayFormat = '%.1f';
            app.H_24EditFieldLabel = uilabel(app.GridLayout32);
            app.H_24EditFieldLabel.Interpreter = 'latex';
            app.H_24EditFieldLabel.Text = '$H_{24点}';
            app.H_24dianEditField = uieditfield(app.GridLayout32, 'numeric');
            app.H_24dianEditField.ValueDisplayFormat = '%.1f';
            app.Panel_8 = uipanel(app.GridLayout30);
            app.Panel_8.Title = '面雨量输入';
            app.GridLayout33 = uigridlayout(app.Panel_8);
            app.alpha_1EditFieldLabel = uilabel(app.GridLayout33);
            app.alpha_1EditFieldLabel.Interpreter = 'latex';
            app.alpha_1EditFieldLabel.Text = '$\alpha_1';
            app.alpha_1EditField = uieditfield(app.GridLayout33, 'numeric');
            app.alpha_1EditField.Value = 0.684;
            app.alpha_6EditFieldLabel = uilabel(app.GridLayout33);
            app.alpha_6EditFieldLabel.Interpreter = 'latex';
            app.alpha_6EditFieldLabel.Text = '$\alpha_6';
            app.alpha_6EditField = uieditfield(app.GridLayout33, 'numeric');
            app.alpha_6EditField.Value = 0.754;
            app.alpha_24EditFieldLabel = uilabel(app.GridLayout33);
            app.alpha_24EditFieldLabel.Interpreter = 'latex';
            app.alpha_24EditFieldLabel.Text = '$\alpha_{24}';
            app.alpha_24EditField = uieditfield(app.GridLayout33, 'numeric');
            app.alpha_24EditField.Value = 0.814;
            app.Panel_9 = uipanel(app.GridLayout30);
            app.Panel_9.Title = '面雨量输出';
            app.GridLayout34 = uigridlayout(app.Panel_9);
            app.rainfloodUITable = uitable(app.GridLayout34);
            app.rainfloodUITable.ColumnName = '';
            app.rainfloodUITable.RowName = {};
            app.rainfloodUITable.KeyPressFcn = createCallbackFcn(app, @rainfloodUITableKeyPress, true);
            app.H_1Label = uilabel(app.GridLayout34);
            app.H_1Label.Interpreter = 'latex';
            app.H_1Label.Text = '$H_{1面}';
            app.H_1mianEditField = uieditfield(app.GridLayout34, 'numeric');
            app.H_1mianEditField.ValueDisplayFormat = '%.1f';
            app.H_6Label = uilabel(app.GridLayout34);
            app.H_6Label.Interpreter = 'latex';
            app.H_6Label.Text = '$H_{6面}';
            app.H_6mianEditField = uieditfield(app.GridLayout34, 'numeric');
            app.H_6mianEditField.ValueDisplayFormat = '%.1f';
            app.H_24Label = uilabel(app.GridLayout34);
            app.H_24Label.Interpreter = 'latex';
            app.H_24Label.Text = '$H_{24面}';
            app.H_24mianEditField = uieditfield(app.GridLayout34, 'numeric');
            app.H_24mianEditField.ValueDisplayFormat = '%.1f';
            app.beta_1EditFieldLabel = uilabel(app.GridLayout34);
            app.beta_1EditFieldLabel.Interpreter = 'latex';
            app.beta_1EditFieldLabel.Text = '$\beta_1';
            app.beta_1EditField = uieditfield(app.GridLayout34, 'numeric');
            app.beta_1EditField.ValueDisplayFormat = '%.3f';
            app.beta_2EditFieldLabel = uilabel(app.GridLayout34);
            app.beta_2EditFieldLabel.Interpreter = 'latex';
            app.beta_2EditFieldLabel.Text = '$\beta_2';
            app.beta_2EditField = uieditfield(app.GridLayout34, 'numeric');
            app.beta_2EditField.ValueDisplayFormat = '%.3f';
            app.n_1EditFieldLabel = uilabel(app.GridLayout34);
            app.n_1EditFieldLabel.Interpreter = 'latex';
            app.n_1EditFieldLabel.Text = '$n_1';
            app.n_1EditField = uieditfield(app.GridLayout34, 'numeric');
            app.n_1EditField.ValueDisplayFormat = '%.3f';
            app.n_2EditFieldLabel = uilabel(app.GridLayout34);
            app.n_2EditFieldLabel.Interpreter = 'latex';
            app.n_2EditFieldLabel.Text = '$n_2';
            app.n_2EditField = uieditfield(app.GridLayout34, 'numeric');
            app.n_2EditField.ValueDisplayFormat = '%.3f';
            app.rainfloodButton5 = uibutton(app.GridLayout34, 'push');
            app.rainfloodButton5.ButtonPushedFcn = createCallbackFcn(app, @rainfloodButton5Pushed, true);
            app.rainfloodButton5.Text = '计算设计面雨量';
            app.Panel_10 = uipanel(app.GridLayout30);
            app.Panel_10.Title = '洪水的地区组成';
            app.GridLayout35 = uigridlayout(app.Panel_10);
            app.rainfloodUIAxes = uiaxes(app.GridLayout35);
            xlabel(app.rainfloodUIAxes, '时段')
            ylabel(app.rainfloodUIAxes, '流量')
            zlabel(app.rainfloodUIAxes, 'Z')
            app.rainfloodUIAxes.ButtonDownFcn = createCallbackFcn(app, @rainfloodUIAxesButtonDown, true);
            app.rainfloodUITable1 = uitable(app.GridLayout35);
            app.rainfloodUITable1.ColumnName = {'流域出口设计洪水过程'};
            app.rainfloodUITable1.RowName = {};
            app.rainfloodUITable1.KeyPressFcn = createCallbackFcn(app, @rainfloodUITable1KeyPress, true);
            app.rainfloodUITable2 = uitable(app.GridLayout35);
            app.rainfloodUITable2.ColumnName = {'区间设计洪水过程'};
            app.rainfloodUITable2.RowName = {};
            app.rainfloodUITable2.KeyPressFcn = createCallbackFcn(app, @rainfloodUITable2KeyPress, true);
            app.rainfloodButton1 = uibutton(app.GridLayout35, 'state');
            app.rainfloodButton1.ValueChangedFcn = createCallbackFcn(app, @rainfloodButton1ValueChanged, true);
            app.rainfloodButton1.Text = '流域出口设计洪水';
            app.rainfloodButton2 = uibutton(app.GridLayout35, 'state');
            app.rainfloodButton2.ValueChangedFcn = createCallbackFcn(app, @rainfloodButton2ValueChanged, true);
            app.rainfloodButton2.Text = '水库相应洪水';
            app.rainfloodButton3 = uibutton(app.GridLayout35, 'state');
            app.rainfloodButton3.ValueChangedFcn = createCallbackFcn(app, @rainfloodButton3ValueChanged, true);
            app.rainfloodButton3.Text = '区间设计洪水';
            app.Tab_flood_routing = uitab(app.TabGroup1);
            app.Tab_flood_routing.Title = '调洪计算';
            app.Tab_flood_routing.ButtonDownFcn = createCallbackFcn(app, @Tab_flood_routingButtonDown, true);
            app.GridLayout36 = uigridlayout(app.Tab_flood_routing);
            app.Panel_11 = uipanel(app.GridLayout36);
            app.Panel_11.Title = '调洪计算输入';
            app.GridLayout37 = uigridlayout(app.Panel_11);
            app.UITableZ_V = uitable(app.GridLayout37);
            app.UITableZ_V.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableZ_V.RowName = {};
            app.UITableZ_V.KeyPressFcn = createCallbackFcn(app, @UITableZ_VKeyPress, true);
            app.Label_7 = uilabel(app.GridLayout37);
            app.Label_7.Text = '起调水位';
            app.Label_8 = uilabel(app.GridLayout37);
            app.Label_8.Tooltip = {''};
            app.Label_8.Text = '泄洪道高程';
            app.EditFieldH0 = uieditfield(app.GridLayout37, 'numeric');
            app.EditFieldH0.Limits = [0 Inf];
            app.EditFieldH0.Tooltip = {'又称防洪限制水位'};
            app.EditFieldH0.Value = 430;
            app.EditFieldHspillway = uieditfield(app.GridLayout37, 'numeric');
            app.EditFieldHspillway.Limits = [0 Inf];
            app.EditFieldHspillway.Tooltip = {'设计的溢洪道高程要小于起调水位'};
            app.EditFieldHspillway.Value = 429;
            app.MEditFieldLabel = uilabel(app.GridLayout37);
            app.MEditFieldLabel.Text = 'M';
            app.EditFieldM = uieditfield(app.GridLayout37, 'numeric');
            app.EditFieldM.Limits = [0 Inf];
            app.EditFieldM.Tooltip = {'溢洪道泄流计算公式参数M'};
            app.EditFieldM.Value = 1.57;
            app.BEditFieldLabel = uilabel(app.GridLayout37);
            app.BEditFieldLabel.Text = 'B';
            app.EditFieldB = uieditfield(app.GridLayout37, 'numeric');
            app.EditFieldB.Limits = [0 Inf];
            app.EditFieldB.Tooltip = {'溢洪道泄流计算公式参数B'};
            app.EditFieldB.Value = 20;
            app.DeltaTEditFieldLabel = uilabel(app.GridLayout37);
            app.DeltaTEditFieldLabel.Interpreter = 'latex';
            app.DeltaTEditFieldLabel.Text = '$\Delta T';
            app.EditFieldDeltaT = uieditfield(app.GridLayout37, 'numeric');
            app.EditFieldDeltaT.Limits = [0 Inf];
            app.EditFieldDeltaT.ValueDisplayFormat = '%11.6g';
            app.EditFieldDeltaT.Tooltip = {'调洪演算中水量平衡公式中的时间间隔T'};
            app.EditFieldDeltaT.Value = 3600;
            app.Label_9 = uilabel(app.GridLayout37);
            app.Label_9.Text = '下游安全泄量';
            app.EditFieldq_safedischarge = uieditfield(app.GridLayout37, 'numeric');
            app.EditFieldq_safedischarge.Limits = [0 Inf];
            app.EditFieldq_safedischarge.ValueDisplayFormat = '%11.6g';
            app.EditFieldq_safedischarge.Tooltip = {'区间流量+水库下泄流量要小于下游城市安全泄量'};
            app.EditFieldq_safedischarge.Value = 500;
            app.EditField_C2A = uieditfield(app.GridLayout37, 'numeric');
            app.EditField_C2A.Limits = [0 Inf];
            app.EditField_C2A.Tooltip = {'用面积比法进行缩放各频率设计洪水的参数'};
            app.EditField_C2A.Value = 0.8799;
            app.Label_13 = uilabel(app.GridLayout37);
            app.Label_13.Text = '流量变幅不得超过';
            app.EditField_maxdiff_q = uieditfield(app.GridLayout37, 'numeric');
            app.EditField_maxdiff_q.Limits = [0 Inf];
            app.EditField_maxdiff_q.ValueDisplayFormat = '%11.6g';
            app.EditField_maxdiff_q.Tooltip = {'考虑河道通航约束，相邻两时段的下泄流量变幅不得超过的值'};
            app.EditField_maxdiff_q.Value = 450;
            app.n_splineLabel = uilabel(app.GridLayout37);
            app.n_splineLabel.Interpreter = 'latex';
            app.n_splineLabel.Text = '$n_{spline}';
            app.n_splineEditField = uieditfield(app.GridLayout37, 'numeric');
            app.n_splineEditField.Limits = [1 Inf];
            app.n_splineEditField.RoundFractionalValues = 'on';
            app.n_splineEditField.Tooltip = {'每时段插值的份数'};
            app.n_splineEditField.Value = 1;
            app.nLabel = uilabel(app.GridLayout37);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = '$n';
            app.nEditField = uieditfield(app.GridLayout37, 'numeric');
            app.nEditField.Limits = [0 1];
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Tooltip = {'面积比法的修正指数'};
            app.nEditField.Value = 0.7;
            app.Label_C2A = uilabel(app.GridLayout37);
            app.Label_C2A.Interpreter = 'latex';
            app.Label_C2A.Text = '$(F_设/F_参)^{0.7}';
            app.Panel_12 = uipanel(app.GridLayout36);
            app.Panel_12.Title = '调洪计算的结果';
            app.GridLayout38 = uigridlayout(app.Panel_12);
            app.TabGroup3 = uitabgroup(app.GridLayout38);
            app.Tab_H1 = uitab(app.TabGroup3);
            app.Tab_H1.Title = '防洪高水位';
            app.GridLayout38_2 = uigridlayout(app.Tab_H1);
            app.UIAxesH1 = uiaxes(app.GridLayout38_2);
            xlabel(app.UIAxesH1, '时段')
            ylabel(app.UIAxesH1, '流量')
            zlabel(app.UIAxesH1, 'Z')
            app.UIAxesH1.ButtonDownFcn = createCallbackFcn(app, @UIAxesH1ButtonDown, true);
            app.UITableH1_Q = uitable(app.GridLayout38_2);
            app.UITableH1_Q.ColumnName = {'水库相应洪水过程'};
            app.UITableH1_Q.RowName = {};
            app.UITableH1_Q.KeyPressFcn = createCallbackFcn(app, @UITableH1_QKeyPress, true);
            app.UITableH1_q = uitable(app.GridLayout38_2);
            app.UITableH1_q.ColumnName = {'调洪下泄流量过程'};
            app.UITableH1_q.RowName = {};
            app.UITableH1_q.KeyPressFcn = createCallbackFcn(app, @UITableH1_qKeyPress, true);
            app.Label_14 = uilabel(app.GridLayout38_2);
            app.Label_14.Text = '防洪高水位';
            app.EditFieldH1 = uieditfield(app.GridLayout38_2, 'numeric');
            app.Label_15 = uilabel(app.GridLayout38_2);
            app.Label_15.Text = '防洪库容';
            app.EditFieldV1 = uieditfield(app.GridLayout38_2, 'numeric');
            app.EditFieldV1.ValueDisplayFormat = '%11.10g';
            app.Label_16 = uilabel(app.GridLayout38_2);
            app.Label_16.Text = '相应最大下泄流量';
            app.EditFieldq1 = uieditfield(app.GridLayout38_2, 'numeric');
            app.Tab_H2 = uitab(app.TabGroup3);
            app.Tab_H2.Title = '设计洪水位';
            app.GridLayout38_3 = uigridlayout(app.Tab_H2);
            app.UIAxesH2 = uiaxes(app.GridLayout38_3);
            xlabel(app.UIAxesH2, '时段')
            ylabel(app.UIAxesH2, '流量')
            zlabel(app.UIAxesH2, 'Z')
            app.UIAxesH2.ButtonDownFcn = createCallbackFcn(app, @UIAxesH2ButtonDown, true);
            app.UITableH2_Q = uitable(app.GridLayout38_3);
            app.UITableH2_Q.ColumnName = {'水库设计洪水过程'};
            app.UITableH2_Q.RowName = {};
            app.UITableH2_Q.KeyPressFcn = createCallbackFcn(app, @UITableH2_QKeyPress, true);
            app.UITableH2_q = uitable(app.GridLayout38_3);
            app.UITableH2_q.ColumnName = {'调洪下泄流量过程'};
            app.UITableH2_q.RowName = {};
            app.UITableH2_q.KeyPressFcn = createCallbackFcn(app, @UITableH2_qKeyPress, true);
            app.Label_17 = uilabel(app.GridLayout38_3);
            app.Label_17.Text = '设计洪水位';
            app.EditFieldH2 = uieditfield(app.GridLayout38_3, 'numeric');
            app.Label_18 = uilabel(app.GridLayout38_3);
            app.Label_18.Text = '拦洪库容';
            app.EditFieldV2 = uieditfield(app.GridLayout38_3, 'numeric');
            app.EditFieldV2.ValueDisplayFormat = '%11.10g';
            app.EditField_10Label = uilabel(app.GridLayout38_3);
            app.EditField_10Label.Text = '相应最大下泄流量';
            app.EditFieldq2 = uieditfield(app.GridLayout38_3, 'numeric');
            app.Tab_H3 = uitab(app.TabGroup3);
            app.Tab_H3.Title = '校核洪水位';
            app.GridLayout38_4 = uigridlayout(app.Tab_H3);
            app.UIAxesH3 = uiaxes(app.GridLayout38_4);
            xlabel(app.UIAxesH3, '时段')
            ylabel(app.UIAxesH3, '流量')
            zlabel(app.UIAxesH3, 'Z')
            app.UIAxesH3.ButtonDownFcn = createCallbackFcn(app, @UIAxesH3ButtonDown, true);
            app.UITableH3_Q = uitable(app.GridLayout38_4);
            app.UITableH3_Q.ColumnName = {'水库校核洪水过程'};
            app.UITableH3_Q.RowName = {};
            app.UITableH3_Q.KeyPressFcn = createCallbackFcn(app, @UITableH3_QKeyPress, true);
            app.UITableH3_q = uitable(app.GridLayout38_4);
            app.UITableH3_q.ColumnName = {'调洪下泄流量过程'};
            app.UITableH3_q.RowName = {};
            app.UITableH3_q.KeyPressFcn = createCallbackFcn(app, @UITableH3_qKeyPress, true);
            app.Label_19 = uilabel(app.GridLayout38_4);
            app.Label_19.Text = '校核洪水位';
            app.EditFieldH3 = uieditfield(app.GridLayout38_4, 'numeric');
            app.Label_20 = uilabel(app.GridLayout38_4);
            app.Label_20.Text = '调洪库容';
            app.EditFieldV3 = uieditfield(app.GridLayout38_4, 'numeric');
            app.EditFieldV3.ValueDisplayFormat = '%11.10g';
            app.EditField_13Label = uilabel(app.GridLayout38_4);
            app.EditField_13Label.Text = '相应最大下泄流量';
            app.EditFieldq3 = uieditfield(app.GridLayout38_4, 'numeric');
            app.ContextMenu1_1 = uicontextmenu(app.UIFigure);
            app.Menu1_1_1 = uimenu(app.ContextMenu1_1);
            app.Menu1_1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_1_1Selected, true);
            app.Menu1_1_1.Text = '相关分析法进行插补';
            app.Menu_20 = uimenu(app.ContextMenu1_1);
            app.Menu_20.Text = '导入课设以外的数据（有待完善）...';
            % Assign app.ContextMenu1_1
            app.UITableData1_1.ContextMenu = app.ContextMenu1_1;
            app.ContextMenu2_1_1 = uicontextmenu(app.UIFigure);
            app.Menu2_1_1_1 = uimenu(app.ContextMenu2_1_1);
            app.Menu2_1_1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1_1_1Selected, true);
            app.Menu2_1_1_1.Text = '添加实测数据';
            % Assign app.ContextMenu2_1_1
            app.UITableP3_1_1.ContextMenu = app.ContextMenu2_1_1;
            app.ContextMenu2_1_2 = uicontextmenu(app.UIFigure);
            app.Menu2_1_2_1 = uimenu(app.ContextMenu2_1_2);
            app.Menu2_1_2_1.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1_2_1Selected, true);
            app.Menu2_1_2_1.Text = '添加调查考证数据';
            app.Menu2_1_2_2 = uimenu(app.ContextMenu2_1_2);
            app.Menu2_1_2_2.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1_2_2Selected, true);
            app.Menu2_1_2_2.Text = '清空调查考证数据';
            % Assign app.ContextMenu2_1_2
            app.UITableP3_1_2.ContextMenu = app.ContextMenu2_1_2;
            app.ContextMenu1_2_2 = uicontextmenu(app.UIFigure);
            app.Menu1_2_2 = uimenu(app.ContextMenu1_2_2);
            app.Menu1_2_2.MenuSelectedFcn = createCallbackFcn(app, @Menu1_2_2Selected, true);
            app.Menu1_2_2.Text = '插值为等时段数据';
            % Assign app.ContextMenu1_2_2
            app.UITableData2_2.ContextMenu = app.ContextMenu1_2_2;
            app.ContextMenu2_1_3 = uicontextmenu(app.UIFigure);
            app.Menu2_1_3_1 = uimenu(app.ContextMenu2_1_3);
            app.Menu2_1_3_1.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1_3_1Selected, true);
            app.Menu2_1_3_1.Tooltip = {'只参考实测资料'};
            app.Menu2_1_3_1.Text = '连续系列的经验概率计算';
            app.Menu2_1_3_2 = uimenu(app.ContextMenu2_1_3);
            app.Menu2_1_3_2.Tooltip = {'除了参考实测资料，还考虑调查考证资料'};
            app.Menu2_1_3_2.Text = '非连续系列的经验频率计算';
            app.Menu_2_1_3_2_1 = uimenu(app.Menu2_1_3_2);
            app.Menu_2_1_3_2_1.MenuSelectedFcn = createCallbackFcn(app, @Menu_2_1_3_2_1Selected, true);
            app.Menu_2_1_3_2_1.Tooltip = {''};
            app.Menu_2_1_3_2_1.Text = '统一处理法';
            app.Menu_2_1_3_2_2 = uimenu(app.Menu2_1_3_2);
            app.Menu_2_1_3_2_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2_1_3_2_2Selected, true);
            app.Menu_2_1_3_2_2.Text = '分别处理法';
            % Assign app.ContextMenu2_1_3
            app.UITableP3_1_3.ContextMenu = app.ContextMenu2_1_3;
            app.ContextMenu1_2_1 = uicontextmenu(app.UIFigure);
            app.Menu1_2_1_1 = uimenu(app.ContextMenu1_2_1);
            app.Menu1_2_1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_2_1_1Selected, true);
            app.Menu1_2_1_1.Text = '整理数据时间格式';
            app.Menu_21 = uimenu(app.ContextMenu1_2_1);
            app.Menu_21.Text = '导入课设以外的数据（有待完善）...';
            % Assign app.ContextMenu1_2_1
            app.UITableData2_1.ContextMenu = app.ContextMenu1_2_1;
            app.ContextMenu1_2_3 = uicontextmenu(app.UIFigure);
            app.Menu1_2_3_9 = uimenu(app.ContextMenu1_2_3);
            app.Menu1_2_3_9.MenuSelectedFcn = createCallbackFcn(app, @Menu1_2_3_9Selected2, true);
            app.Menu1_2_3_9.Text = '计算典型洪水最大洪量';
            % Assign app.ContextMenu1_2_3
            app.UITableData2_3.ContextMenu = app.ContextMenu1_2_3;
            app.Panel_4.ContextMenu = app.ContextMenu1_2_3;
            app.GridLayout24.ContextMenu = app.ContextMenu1_2_3;
            app.W1dEditFieldLabel.ContextMenu = app.ContextMenu1_2_3;
            app.W3dEditFieldLabel.ContextMenu = app.ContextMenu1_2_3;
            app.FloodEditFieldW7d.ContextMenu = app.ContextMenu1_2_3;
            app.W7dLabel.ContextMenu = app.ContextMenu1_2_3;
            app.FloodEditFieldW3d.ContextMenu = app.ContextMenu1_2_3;
            app.FloodEditFieldW1d.ContextMenu = app.ContextMenu1_2_3;
            app.QmdLabel.ContextMenu = app.ContextMenu1_2_3;
            app.FloodEditFieldQmd.ContextMenu = app.ContextMenu1_2_3;
            app.ContextMenu3_3_1 = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu3_3_1);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '同频率放大';
            app.Menu_4 = uimenu(app.ContextMenu3_3_1);
            app.Menu_4.Text = '同倍比放大';
            app.Menu_24 = uimenu(app.Menu_4);
            app.Menu_24.MenuSelectedFcn = createCallbackFcn(app, @Menu_24Selected, true);
            app.Menu_24.Text = '峰比放大';
            app.Menu_25 = uimenu(app.Menu_4);
            app.Menu_25.MenuSelectedFcn = createCallbackFcn(app, @Menu_25Selected, true);
            app.Menu_25.Text = '量比放大';
            % Assign app.ContextMenu3_3_1
            app.FloodUITableP1.ContextMenu = app.ContextMenu3_3_1;
            app.ContextMenu3_3_2 = uicontextmenu(app.UIFigure);
            app.Menu_5 = uimenu(app.ContextMenu3_3_2);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '同频率放大';
            app.Menu_6 = uimenu(app.ContextMenu3_3_2);
            app.Menu_6.Text = '同倍比放大';
            app.Menu_26 = uimenu(app.Menu_6);
            app.Menu_26.MenuSelectedFcn = createCallbackFcn(app, @Menu_26Selected, true);
            app.Menu_26.Text = '峰比放大';
            app.Menu_27 = uimenu(app.Menu_6);
            app.Menu_27.MenuSelectedFcn = createCallbackFcn(app, @Menu_27Selected, true);
            app.Menu_27.Text = '量比放大';
            % Assign app.ContextMenu3_3_2
            app.FloodUITableP2.ContextMenu = app.ContextMenu3_3_2;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.Menu_7 = uimenu(app.ContextMenu4);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '同频率放大';
            app.Menu_8 = uimenu(app.ContextMenu4);
            app.Menu_8.Text = '同倍比放大';
            app.Menu_28 = uimenu(app.Menu_8);
            app.Menu_28.MenuSelectedFcn = createCallbackFcn(app, @Menu_28Selected, true);
            app.Menu_28.Text = '峰比放大';
            app.Menu_29 = uimenu(app.Menu_8);
            app.Menu_29.MenuSelectedFcn = createCallbackFcn(app, @Menu_29Selected, true);
            app.Menu_29.Text = '量比放大';
            % Assign app.ContextMenu4
            app.FloodUITableP3.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.UIFigure);
            app.Menu_9 = uimenu(app.ContextMenu5);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '洪水修匀';
            % Assign app.ContextMenu5
            app.FloodUIAxesP1.ContextMenu = app.ContextMenu5;
            app.ContextMenu6 = uicontextmenu(app.UIFigure);
            app.Menu_10 = uimenu(app.ContextMenu6);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '区间设计洪水的计算';
            % Assign app.ContextMenu6
            app.rainfloodUITable.ContextMenu = app.ContextMenu6;
            app.ContextMenu7 = uicontextmenu(app.UIFigure);
            app.Menu_11 = uimenu(app.ContextMenu7);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '选择流域出口设计洪水过程';
            % Assign app.ContextMenu7
            app.rainfloodUITable1.ContextMenu = app.ContextMenu7;
            app.ContextMenu8 = uicontextmenu(app.UIFigure);
            app.Menu_12 = uimenu(app.ContextMenu8);
            app.Menu_12.MenuSelectedFcn = createCallbackFcn(app, @Menu_12Selected, true);
            app.Menu_12.Text = '洪水修匀';
            % Assign app.ContextMenu8
            app.FloodUIAxesP2.ContextMenu = app.ContextMenu8;
            app.ContextMenu9 = uicontextmenu(app.UIFigure);
            app.Menu_13 = uimenu(app.ContextMenu9);
            app.Menu_13.MenuSelectedFcn = createCallbackFcn(app, @Menu_13Selected, true);
            app.Menu_13.Text = '洪水修匀';
            % Assign app.ContextMenu9
            app.FloodUIAxesP3.ContextMenu = app.ContextMenu9;
            app.ContextMenu10 = uicontextmenu(app.UIFigure);
            app.Menu_14 = uimenu(app.ContextMenu10);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '添加水库相应洪水过程';
            app.Menu_17 = uimenu(app.ContextMenu10);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '防洪高水位的调洪计算';
            % Assign app.ContextMenu10
            app.UITableH1_Q.ContextMenu = app.ContextMenu10;
            app.ContextMenu11 = uicontextmenu(app.UIFigure);
            app.Menu_15 = uimenu(app.ContextMenu11);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @Menu_15Selected, true);
            app.Menu_15.Text = '添加水库设计洪水过程';
            app.Menu_18 = uimenu(app.ContextMenu11);
            app.Menu_18.MenuSelectedFcn = createCallbackFcn(app, @Menu_18Selected, true);
            app.Menu_18.Text = '设计洪水位的调洪计算';
            % Assign app.ContextMenu11
            app.UITableH2_Q.ContextMenu = app.ContextMenu11;
            app.ContextMenu12 = uicontextmenu(app.UIFigure);
            app.Menu_16 = uimenu(app.ContextMenu12);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '添加水库校核洪水过程';
            app.Menu_19 = uimenu(app.ContextMenu12);
            app.Menu_19.MenuSelectedFcn = createCallbackFcn(app, @Menu_19Selected, true);
            app.Menu_19.Text = '校核洪水位的调洪计算';
            % Assign app.ContextMenu12
            app.UITableH3_Q.ContextMenu = app.ContextMenu12;
            app.ContextMenu13 = uicontextmenu(app.UIFigure);
            app.Menu_22 = uimenu(app.ContextMenu13);
            app.Menu_22.Text = '导入课设以外的数据（有待完善）...';
            % Assign app.ContextMenu13
            app.UITableData4_1.ContextMenu = app.ContextMenu13;
            app.ContextMenu14 = uicontextmenu(app.UIFigure);
            app.Menu_23 = uimenu(app.ContextMenu14);
            app.Menu_23.Text = '导入课设以外的数据（有待完善）...';
            % Assign app.ContextMenu14
            app.UITableData3_1.ContextMenu = app.ContextMenu14;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HWCC

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end