classdef HWCC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu1               matlab.ui.container.Menu
        Menu1_1             matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        TabGroup1           matlab.ui.container.TabGroup
        Tab_Data            matlab.ui.container.Tab
        GridLayout1         matlab.ui.container.GridLayout
        Panel1_1            matlab.ui.container.Panel
        GridLayout1_1       matlab.ui.container.GridLayout
        TabGroup1_1         matlab.ui.container.TabGroup
        Tab1_1              matlab.ui.container.Tab
        GridLayout1_1_1     matlab.ui.container.GridLayout
        UITableData1_1      matlab.ui.control.Table
        Tab_Data1_2         matlab.ui.container.Tab
        GridLayout1_1_2     matlab.ui.container.GridLayout
        UITableData1_2      matlab.ui.control.Table
        Panel1_2            matlab.ui.container.Panel
        GridLayout1_2       matlab.ui.container.GridLayout
        TabGroup1_2         matlab.ui.container.TabGroup
        Tab2_1              matlab.ui.container.Tab
        GridLayout1_2_1     matlab.ui.container.GridLayout
        UITableData2_1      matlab.ui.control.Table
        Tab2_2              matlab.ui.container.Tab
        GridLayout1_2_2     matlab.ui.container.GridLayout
        UITableData2_2      matlab.ui.control.Table
        Panel1_3            matlab.ui.container.Panel
        GridLayout1_3_1     matlab.ui.container.GridLayout
        UITableData3_1      matlab.ui.control.Table
        Panel1_4            matlab.ui.container.Panel
        GridLayout1_4_1     matlab.ui.container.GridLayout
        UITableData4_1      matlab.ui.control.Table
        Tab_P3              matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        Panel2_1            matlab.ui.container.Panel
        GridLayout2_1       matlab.ui.container.GridLayout
        TabGroup2_1         matlab.ui.container.TabGroup
        Tab_P3_1_1          matlab.ui.container.Tab
        GridLayout2_1_1     matlab.ui.container.GridLayout
        Panel2_1_1          matlab.ui.container.Panel
        GridLayout14        matlab.ui.container.GridLayout
        UITableP3_1_1       matlab.ui.control.Table
        Panel2_1_2          matlab.ui.container.Panel
        GridLayout15        matlab.ui.container.GridLayout
        UITableP3_1_2       matlab.ui.control.Table
        Panel2_1_3          matlab.ui.container.Panel
        GridLayout17        matlab.ui.container.GridLayout
        UITableP3_1_3       matlab.ui.control.Table
        Panel2_2            matlab.ui.container.Panel
        GridLayout2_2       matlab.ui.container.GridLayout
        TabGroup2_2         matlab.ui.container.TabGroup
        Tab_P3_2_7          matlab.ui.container.Tab
        ContextMenu1_1      matlab.ui.container.ContextMenu
        Menu1_1_1           matlab.ui.container.Menu
        Menu1_1_2           matlab.ui.container.Menu
        ContextMenu2_1_1_1  matlab.ui.container.ContextMenu
        Menu2_1_1_1_1       matlab.ui.container.Menu
        Menu2_1_1_1_2       matlab.ui.container.Menu
        ContextMenu2_1_1_2  matlab.ui.container.ContextMenu
        Menu2_1_1_2_1       matlab.ui.container.Menu
        Menu2_1_1_2_2       matlab.ui.container.Menu
        ContextMenu1_2      matlab.ui.container.ContextMenu
        Menu1_2_1           matlab.ui.container.Menu
        Menu1_2_2           matlab.ui.container.Menu
    end


    properties (Access = public)
        DataUItable2_2_timetable % Tab_Data中第2个panel中的第2个tab
        Dataindex_choose % 记录选择的数据范围
        DataUItable_choose % 记录选择的哪个UItable
        DataFlag_choose % 记录写入开关（需要选择数据写入的时候才是 true）
        DataUItable_write % 记录写入哪个UItable
    end

    methods (Access = public)

        function func_choose2writeData(app)
        try
            temp_col = app.Dataindex_choose(2,1);
            temp_row = app.Dataindex_choose(:,1);
        catch
            errordlg('你没有选择任何数据','选择数据失败')
            return
        end
            if all(app.Dataindex_choose(:,2) ~= temp_col)
                errordlg('你选择的数据超过一列','选择数据失败')
                return
            else
                app.DataUItable_write.Data = app.DataUItable_choose.Data(temp_row,[1,temp_col])';
                app.DataUItable_write.RowName =app.DataUItable_choose.ColumnName([1,temp_col]);
                n = length(app.DataUItable_write.Data);
            temp_cell = cell(1,n);
            addColumnWidth = 40;
            for i=1:n
                temp_cell(i) = {addColumnWidth};
            end
            app.DataUItable_write.ColumnWidth = temp_cell;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Menu1_1Selected(app, 'true');
            Menu1_1_1Selected(app, 'on');
            app.DataFlag_choose = false; % 初始选择数据flag为false
        end

        % Menu selected function: Menu1_1
        function Menu1_1Selected(app, event)
            % 位置：菜单栏第一个选项
            % 功能: 一键加载课设数据
            filename='..\课程设计数据\No2019101629.xls';
            cell_data=readcell(filename,'sheet','防洪计算');
            % 1、水文站洪水特征统计 A3-D23
            app.UITableData1_1.ColumnName = cell_data(2,1:4);
            app.UITableData1_1.Data = cell_data(3:23,1:4);
            %             app.UITableData1_2.ColumnName = app.UITableData1_1.ColumnName;
            %             app.UITableData1_2.Data = app.UITableData1_1.Data;
            % 2、水文站典型洪水流量 F3-H32
            app.UITableData2_1.ColumnName = cell_data(2,6:8);
            app.UITableData2_1.Data = cell_data(3:32,6:8);
            % 3、坝址有关数据 N1-N11
            app.UITableData3_1.ColumnName = {'名称';'值'};
            app.UITableData3_1.Data = cell_data(1:11,13:14);
            % 4、特大洪水数据 M15-N16
            app.UITableData4_1.ColumnName = cell_data(14,13:14);
            app.UITableData4_1.Data = cell_data(15:16,13:14);
        end

        % Menu selected function: Menu1_1_1
        function Menu1_1_1Selected(app, event)
            % 位置：Tab Data中panel_1中的表1
            % 功能：进行相关性分析法插补数据（使用1日与3日的相关关系）
            temp =  cell2mat(app.UITableData1_1.Data);
            p = polyfit(temp(:,2),temp(:,4),1);
            temp_index = find(temp(:,4)==-1);
            temp(temp_index,4) = round(polyval(p,temp(temp_index,2)));
            app.UITableData1_2.ColumnName = app.UITableData1_1.ColumnName;
            app.UITableData1_2.Data = temp;
        end

        % Callback function
        function Menu_tableData2_2_1Selected(app, event)
            % 无用
            %             temp_MD = app.UITableData2_1.Data(:,1);
            %             temp_HM = app.UITableData2_1.Data(:,2);
            %             format = 'M月d日 HH:mm';
            %             %             temp_table =
            %             % %             temp_table = zeros(length(temp_MD),1);
            %             for i=1:length(temp_MD)
            %                 temp_md = string(temp_MD{i});
            %                 temp_hm = string(temp_HM{i});
            %                 if floor(temp_HM{i}) == double(temp_HM{i})
            %                     temp_hm = strcat(" ",temp_hm);
            %                     date = strcat(temp_md,temp_hm);
            %                     infmt = 'M.d H';
            %                     temp_table(i) = datetime(date,...
            %                         "InputFormat",infmt,"Format",format);
            %                 else
            %                     temp_index = strfind(temp_hm,'.');
            %                     integer_part = temp_hm{1}(1:temp_index-1);
            %                     decimal_part = temp_hm{1}(temp_index+1:end);
            %                     temp_m = floor(60/10^length(decimal_part)*eval(decimal_part));
            %                     temp_m = sprintf('%02d',temp_m);
            %                     temp_h = sprintf('%02c',integer_part);
            %                     infmt = 'M.d HH:mm';
            %                     date = strcat(temp_md," ",temp_h,':',temp_m);
            %                     temp_table(i) = datetime(date,...
            %                         "InputFormat",infmt,"Format",format);
            %                 end
            %             end
            %             app.UItable2_2_timetable = timetable(temp_table', ...
            %                 cell2mat(app.UITableData2_1.Data(:,3)));
            %             app.UITableData2_2.Data = timetable2table(app.UItable2_2_timetable);
            %

        end

        % Menu selected function: Menu2_1_1_1_2
        function Menu2_1_1_1_2Selected(app, event)
            % 位置：Tab P3中panel_1中tab 1中 表1
            % 功能：选择实测数据
            app.UITableP3_1_1.Data =  app.UITableData1_2.Data(:,[1,3])';
            app.UITableP3_1_1.ColumnName = [];
            app.UITableP3_1_1.RowName = app.UITableData1_2.ColumnName([1,3]);
            n = length(app.UITableP3_1_1.Data);
            temp_cell = cell(1,n);
            addColumnWidth = 40;
            for i=1:n
                temp_cell(i) = {addColumnWidth};
            end
            app.UITableP3_1_1.ColumnWidth = temp_cell;
        end

        % Menu selected function: Menu2_1_1_2_1
        function Menu2_1_1_2_1Selected(app, event)
            app.UITableP3_1_2.Data =  app.UITableData4_1.Data';
            app.UITableP3_1_2.ColumnName = [];
            app.UITableP3_1_2.RowName = app.UITableData4_1.ColumnName([1,2]);
            n = length(app.UITableP3_1_2.Data);
            temp_cell = cell(1,n);
            addColumnWidth = 58.5;
            for i=1:n
                temp_cell(i) = {addColumnWidth};
            end
            app.UITableP3_1_2.ColumnWidth = temp_cell;
        end

        % Cell selection callback: UITableData1_2
        function UITableData1_2CellSelection(app, event)
            % 位置：'UITableData1_2' Tab Data中panel_1 表2
            % 功能：鼠标选择'UITableData1_2'的数据
            app.Dataindex_choose = event.Indices;
            app.DataUItable_choose = app.UITableData1_2;
        end

        % Menu selected function: Menu1_2_1
        function Menu1_2_1Selected(app, event)
            % 位置：Tab Data中panel_2中的表2
            % 功能：转换并统一时间格式
            temp_MD = app.UITableData2_1.Data(:,1);
            temp_HM = app.UITableData2_1.Data(:,2);
            format = 'M月d日 HH:mm';
            %             temp_table =
            % %             temp_table = zeros(length(temp_MD),1);
            for i=1:length(temp_MD)
                temp_md = string(temp_MD{i});
                temp_hm = string(temp_HM{i});
                if floor(temp_HM{i}) == double(temp_HM{i})
                    temp_hm = strcat(" ",temp_hm);
                    date = strcat(temp_md,temp_hm);
                    infmt = 'M.d H';
                    temp_table(i) = datetime(date,...
                        "InputFormat",infmt,"Format",format);
                else
                    temp_index = strfind(temp_hm,'.');
                    integer_part = temp_hm{1}(1:temp_index-1);
                    decimal_part = temp_hm{1}(temp_index+1:end);
                    temp_m = floor(60/10^length(decimal_part)*eval(decimal_part));
                    temp_m = sprintf('%02d',temp_m);
                    temp_h = sprintf('%02c',integer_part);
                    infmt = 'M.d HH:mm';
                    date = strcat(temp_md," ",temp_h,':',temp_m);
                    temp_table(i) = datetime(date,...
                        "InputFormat",infmt,"Format",format);
                end
            end
            app.DataUItable2_2_timetable = timetable(temp_table', ...
                cell2mat(app.UITableData2_1.Data(:,3)));
            app.UITableData2_2.Data = timetable2table(app.DataUItable2_2_timetable);
        end

        % Menu selected function: Menu2_1_1_1_1
        function Menu2_1_1_1_1Selected(app, event)
            % 添加实测资料
            app.TabGroup1.SelectedTab = app.Tab_Data;
            app.DataFlag_choose = true;
            app.DataUItable_write = app.UITableP3_1_1;
            %             if app.TabGroup1.SelectedTab == app.Tab_P3
            %             try
            %                 app.Dataindex_choose
            %                 app.DataUItable_choose
            %             catch
            %                 warndlg("出现意想不到的问题！")
            %             end
        end

        % Cell selection callback: UITableData1_1
        function UITableData1_1CellSelection(app, event)
            % 位置：'UITableData1_1' Tab Data中panel_1 表1
            % 功能：鼠标选择'UITableData1_1'的数据
            app.Dataindex_choose = event.Indices;
            app.DataUItable_choose = app.UITableData1_1;

        end

        % Button down function: Tab_P3
        function Tab_P3ButtonDown(app, event)
            if app.DataFlag_choose 
                func_choose2writeData(app)
                app.DataFlag_choose = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu1 = uimenu(app.UIFigure);
            app.Menu1.Text = '特定化预设';
            app.Menu1_1 = uimenu(app.Menu1);
            app.Menu1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_1Selected, true);
            app.Menu1_1.Text = '一键导入全部数据（课设）';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '帮助';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup1 = uitabgroup(app.GridLayout);
            app.Tab_Data = uitab(app.TabGroup1);
            app.Tab_Data.Title = '导入数据';
            app.GridLayout1 = uigridlayout(app.Tab_Data);
            app.Panel1_4 = uipanel(app.GridLayout1);
            app.Panel1_4.Title = '历史洪水资料';
            app.GridLayout1_4_1 = uigridlayout(app.Panel1_4);
            app.UITableData4_1 = uitable(app.GridLayout1_4_1);
            app.UITableData4_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData4_1.RowName = {};
            app.UITableData4_1.ColumnSortable = true;
            app.UITableData4_1.ColumnEditable = true;
            app.UITableData4_1.Tag = 'table1_4';
            app.Panel1_3 = uipanel(app.GridLayout1);
            app.Panel1_3.Title = '流域其他资料';
            app.GridLayout1_3_1 = uigridlayout(app.Panel1_3);
            app.UITableData3_1 = uitable(app.GridLayout1_3_1);
            app.UITableData3_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData3_1.RowName = {};
            app.UITableData3_1.ColumnSortable = true;
            app.UITableData3_1.ColumnEditable = true;
            app.UITableData3_1.Tag = 'table1_3';
            app.Panel1_2 = uipanel(app.GridLayout1);
            app.Panel1_2.Title = '水文站典型洪水流量';
            app.GridLayout1_2 = uigridlayout(app.Panel1_2);
            app.TabGroup1_2 = uitabgroup(app.GridLayout1_2);
            app.Tab2_1 = uitab(app.TabGroup1_2);
            app.Tab2_1.Title = '原始数据';
            app.GridLayout1_2_1 = uigridlayout(app.Tab2_1);
            app.UITableData2_1 = uitable(app.GridLayout1_2_1);
            app.UITableData2_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData2_1.RowName = {};
            app.UITableData2_1.ColumnSortable = true;
            app.UITableData2_1.ColumnEditable = true;
            app.UITableData2_1.Tag = 'table1_2';
            app.Tab2_2 = uitab(app.TabGroup1_2);
            app.Tab2_2.Title = '插值生成等时段数据';
            app.GridLayout1_2_2 = uigridlayout(app.Tab2_2);
            app.UITableData2_2 = uitable(app.GridLayout1_2_2);
            app.UITableData2_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData2_2.RowName = {};
            app.UITableData2_2.ColumnSortable = true;
            app.UITableData2_2.ColumnEditable = true;
            app.Panel1_1 = uipanel(app.GridLayout1);
            app.Panel1_1.Title = '水文站典型洪水流量';
            app.GridLayout1_1 = uigridlayout(app.Panel1_1);
            app.TabGroup1_1 = uitabgroup(app.GridLayout1_1);
            app.Tab1_1 = uitab(app.TabGroup1_1);
            app.Tab1_1.Title = '原始数据';
            app.GridLayout1_1_1 = uigridlayout(app.Tab1_1);
            app.UITableData1_1 = uitable(app.GridLayout1_1_1);
            app.UITableData1_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData1_1.RowName = {};
            app.UITableData1_1.ColumnSortable = true;
            app.UITableData1_1.ColumnEditable = true;
            app.UITableData1_1.CellSelectionCallback = createCallbackFcn(app, @UITableData1_1CellSelection, true);
            app.Tab_Data1_2 = uitab(app.TabGroup1_1);
            app.Tab_Data1_2.Title = '插补展延';
            app.Tab_Data1_2.Tag = 'table1_1';
            app.GridLayout1_1_2 = uigridlayout(app.Tab_Data1_2);
            app.UITableData1_2 = uitable(app.GridLayout1_1_2);
            app.UITableData1_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData1_2.RowName = {};
            app.UITableData1_2.ColumnSortable = true;
            app.UITableData1_2.ColumnEditable = true;
            app.UITableData1_2.CellSelectionCallback = createCallbackFcn(app, @UITableData1_2CellSelection, true);
            app.Tab_P3 = uitab(app.TabGroup1);
            app.Tab_P3.Title = '经验适线';
            app.Tab_P3.ButtonDownFcn = createCallbackFcn(app, @Tab_P3ButtonDown, true);
            app.GridLayout2 = uigridlayout(app.Tab_P3);
            app.Panel2_2 = uipanel(app.GridLayout2);
            app.Panel2_2.Title = '适线';
            app.GridLayout2_2 = uigridlayout(app.Panel2_2);
            app.TabGroup2_2 = uitabgroup(app.GridLayout2_2);
            app.Tab_P3_2_7 = uitab(app.TabGroup2_2);
            app.Tab_P3_2_7.Title = '适线07';
            app.Panel2_1 = uipanel(app.GridLayout2);
            app.Panel2_1.Title = '频率计算';
            app.GridLayout2_1 = uigridlayout(app.Panel2_1);
            app.TabGroup2_1 = uitabgroup(app.GridLayout2_1);
            app.Tab_P3_1_1 = uitab(app.TabGroup2_1);
            app.Tab_P3_1_1.Title = '频率计算01';
            app.GridLayout2_1_1 = uigridlayout(app.Tab_P3_1_1);
            app.Panel2_1_3 = uipanel(app.GridLayout2_1_1);
            app.Panel2_1_3.Title = '频率计算的结果';
            app.GridLayout17 = uigridlayout(app.Panel2_1_3);
            app.UITableP3_1_3 = uitable(app.GridLayout17);
            app.UITableP3_1_3.ColumnName = '';
            app.UITableP3_1_3.RowName = {};
            app.Panel2_1_2 = uipanel(app.GridLayout2_1_1);
            app.Panel2_1_2.Title = '调查考证期资料';
            app.GridLayout15 = uigridlayout(app.Panel2_1_2);
            app.UITableP3_1_2 = uitable(app.GridLayout15);
            app.UITableP3_1_2.ColumnName = '';
            app.UITableP3_1_2.RowName = {};
            app.Panel2_1_1 = uipanel(app.GridLayout2_1_1);
            app.Panel2_1_1.Title = '实测期资料';
            app.GridLayout14 = uigridlayout(app.Panel2_1_1);
            app.UITableP3_1_1 = uitable(app.GridLayout14);
            app.UITableP3_1_1.ColumnName = '';
            app.UITableP3_1_1.RowName = {};
            app.ContextMenu1_1 = uicontextmenu(app.UIFigure);
            app.Menu1_1_1 = uimenu(app.ContextMenu1_1);
            app.Menu1_1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_1_1Selected, true);
            app.Menu1_1_1.Text = '相关分析法进行插补';
            app.Menu1_1_2 = uimenu(app.ContextMenu1_1);
            app.Menu1_1_2.Text = '有待开发...';
            % Assign app.ContextMenu1_1
            app.UITableData1_2.ContextMenu = app.ContextMenu1_1;
            app.ContextMenu2_1_1_1 = uicontextmenu(app.UIFigure);
            app.Menu2_1_1_1_1 = uimenu(app.ContextMenu2_1_1_1);
            app.Menu2_1_1_1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1_1_1_1Selected, true);
            app.Menu2_1_1_1_1.Text = '添加实测数据';
            app.Menu2_1_1_1_2 = uimenu(app.ContextMenu2_1_1_1);
            app.Menu2_1_1_1_2.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1_1_1_2Selected, true);
            app.Menu2_1_1_1_2.Text = '添加数据';
            % Assign app.ContextMenu2_1_1_1
            app.UITableP3_1_3.ContextMenu = app.ContextMenu2_1_1_1;
            app.UITableP3_1_1.ContextMenu = app.ContextMenu2_1_1_1;
            app.ContextMenu2_1_1_2 = uicontextmenu(app.UIFigure);
            app.Menu2_1_1_2_1 = uimenu(app.ContextMenu2_1_1_2);
            app.Menu2_1_1_2_1.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1_1_2_1Selected, true);
            app.Menu2_1_1_2_1.Text = '添加调查考证数据';
            app.Menu2_1_1_2_2 = uimenu(app.ContextMenu2_1_1_2);
            app.Menu2_1_1_2_2.Text = 'Menu2';
            % Assign app.ContextMenu2_1_1_2
            app.UITableP3_1_2.ContextMenu = app.ContextMenu2_1_1_2;
            app.ContextMenu1_2 = uicontextmenu(app.UIFigure);
            app.Menu1_2_1 = uimenu(app.ContextMenu1_2);
            app.Menu1_2_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_2_1Selected, true);
            app.Menu1_2_1.Text = '转换并统一时间格式';
            app.Menu1_2_2 = uimenu(app.ContextMenu1_2);
            app.Menu1_2_2.Text = '有待开发...';
            % Assign app.ContextMenu1_2
            app.UITableData2_2.ContextMenu = app.ContextMenu1_2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HWCC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end