classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Label_2    matlab.ui.control.Label
        EditField  matlab.ui.control.NumericEditField
        Label      matlab.ui.control.Label
        Button     matlab.ui.control.Button
    end


    properties (Access = public)
        mainapp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            % 调用的时候以主程序居中
            x = app.mainapp.UIFigure.Position(1)+app.mainapp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            y = app.mainapp.UIFigure.Position(2)+app.mainapp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            w = app.UIFigure.Position(3);
            h = app.UIFigure.Position(4);
            app.UIFigure.Position = [x,y,w,h];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
                Student_No = string(app.EditField.Value);
                %             filename='..\课程设计数据\No2019101629.xls';
                exe_abs_fullpath = pwd; 
                filename = sprintf('%s\\课程设计数据\\No%s.xls',exe_abs_fullpath,Student_No);
                cell_data=readcell(filename,'sheet','防洪计算');
                % 1、水文站洪水特征统计 A3-D23
                app.mainapp.UITableData1_1.ColumnName = cell_data(2,1:4);
                app.mainapp.UITableData1_1.Data = cell2mat(cell_data(3:23,1:4));
                %             app.UITableData1_2.ColumnName = app.UITableData1_1.ColumnName;
                %             app.UITableData1_2.Data = app.UITableData1_1.Data;
                % 2、水文站典型洪水流量 F3-H32
                app.mainapp.UITableData2_1.ColumnName = cell_data(2,6:8);
                app.mainapp.UITableData2_1.Data = cell_data(3:32,6:8);
                % 3、坝址有关数据 N1-N11
                app.mainapp.UITableData3_1.ColumnName = {'名称';'值'};
                app.mainapp.UITableData3_1.Data = cell_data(1:11,13:14);
                % 4、特大洪水数据 M15-N16
                app.mainapp.UITableData4_1.ColumnName = cell_data(14,13:14);
                app.mainapp.UITableData4_1.Data = cell2mat(cell_data(15:16,13:14));
                % 主Tab回到 Tab_Data
                app.mainapp.TabGroup1.SelectedTab = app.mainapp.Tab_Data;
                % Tab_Data 中tab1 回到输入表1
                app.mainapp.TabGroupData_1.SelectedTab = app.mainapp.TabData1_1;
                % Tab_Data 中tab2 回到输入表2
                app.mainapp.TabGroupData_2.SelectedTab = app.mainapp.TabData2_1;
                delete(app.UIFigure)
            catch
                errormsg = sprintf('''%s\\课程设计数据'' 没有 ''No%s.xls'' 文件',exe_abs_fullpath,Student_No);
                errordlg(errormsg,'导入失败')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = 'logo.png';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '输入学号';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueDisplayFormat = '%.20g';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 2019101629;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '导入三峡大学2019级课设数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end