classdef rain_flood < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        Tab_1               matlab.ui.container.Tab
        GridLayout4         matlab.ui.container.GridLayout
        Button_5            matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        GridLayout5         matlab.ui.container.GridLayout
        fcEditField         matlab.ui.control.NumericEditField
        fcEditFieldLabel    matlab.ui.control.Label
        I0EditField         matlab.ui.control.NumericEditField
        I0EditFieldLabel    matlab.ui.control.Label
        UITable2            matlab.ui.control.Table
        Button_4            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        Tab_2               matlab.ui.container.Tab
        GridLayout3         matlab.ui.container.GridLayout
        UITable5            matlab.ui.control.Table
        Panel_3             matlab.ui.container.Panel
        GridLayout7         matlab.ui.container.GridLayout
        UITable4            matlab.ui.control.Table
        Button_6            matlab.ui.control.Button
        FEditFieldLabel     matlab.ui.control.Label
        FEditField          matlab.ui.control.NumericEditField
        kEditFieldLabel     matlab.ui.control.Label
        kEditField          matlab.ui.control.NumericEditField
        nEditFieldLabel     matlab.ui.control.Label
        nEditField          matlab.ui.control.NumericEditField
        TmaxEditFieldLabel  matlab.ui.control.Label
        TmaxEditField       matlab.ui.control.NumericEditField
        Panel_2             matlab.ui.container.Panel
        GridLayout6         matlab.ui.container.GridLayout
        UITable3            matlab.ui.control.Table
        UIAxes              matlab.ui.control.UIAxes
        Tab_3               matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        Button_11           matlab.ui.control.StateButton
        Button_10           matlab.ui.control.StateButton
        Button_9            matlab.ui.control.StateButton
        UITable6            matlab.ui.control.Table
        Panel_5             matlab.ui.container.Panel
        GridLayout8_2       matlab.ui.container.GridLayout
        Button_8            matlab.ui.control.Button
        Q_gEditFieldLabel   matlab.ui.control.Label
        Q_gEditField        matlab.ui.control.NumericEditField
        TEditFieldLabel     matlab.ui.control.Label
        TEditField          matlab.ui.control.NumericEditField
        Q_0EditFieldLabel   matlab.ui.control.Label
        Q_0EditField        matlab.ui.control.NumericEditField
        betaEditField       matlab.ui.control.NumericEditField
        betaEditFieldLabel  matlab.ui.control.Label
        Panel_4             matlab.ui.container.Panel
        GridLayout8         matlab.ui.container.GridLayout
        FEditField_2        matlab.ui.control.NumericEditField
        FEditField_2Label   matlab.ui.control.Label
        Button_7            matlab.ui.control.Button
        DLabel              matlab.ui.control.Label
        DEditField          matlab.ui.control.NumericEditField
        t_cEditFieldLabel   matlab.ui.control.Label
        t_cEditField        matlab.ui.control.NumericEditField
        DeltatLabel         matlab.ui.control.Label
        DeltatEditField     matlab.ui.control.NumericEditField
        f_cEditFieldLabel   matlab.ui.control.Label
        f_cEditField        matlab.ui.control.NumericEditField
        UIAxes2             matlab.ui.control.UIAxes
        ContextMenu         matlab.ui.container.ContextMenu
        Menu                matlab.ui.container.Menu
        ContextMenu2        matlab.ui.container.ContextMenu
        Menu_2              matlab.ui.container.Menu
    end


    properties (Access = private)
        callingTable
        H1 % 地下径流
        H2 % 地表径流
        H3 % 地下+地表径流
        mainapp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, rainflood1_24_notsort, callingTable)
            app.mainapp = mainapp;
            app.callingTable = callingTable;
            % 调用的时候以主程序居中
            x = app.mainapp.UIFigure.Position(1)+app.mainapp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            y = app.mainapp.UIFigure.Position(2)+app.mainapp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            w = app.UIFigure.Position(3);
            h = app.UIFigure.Position(4);
            app.UIFigure.Position = [x,y,w,h];
            % 初始化，净雨计算表，并加载传入的1-24小时未排列的面雨量
            app.UITable.ColumnName = {'1-24小时的设计面雨量','时段面雨量','设计时段面雨量','扣除初损面雨量','Rs','Rg'};
            app.UITable.Data = zeros(24,length(app.UITable.ColumnName));
            try
                app.UITable.Data(:,1) = rainflood1_24_notsort.Data;
            catch
                delete(app.UIFigure)
                errordlg("24小时面雨量为空，无法计算","计算失败")
                return
            end
            app.UITable.RowName = num2cell(1:24);

            %             app.UITable.Data(:,1) = [65.4000000000000
            %                 86.1368913562927
            %                 101.194615526693
            %                 113.448991628834
            %                 123.967237940710
            %                 133.336933551429
            %                 142.136841491264
            %                 150.227753302299
            %                 157.745890006444
            %                 164.789352857814
            %                 171.431486752710
            %                 177.728780894749
            %                 183.725806085953
            %                 189.458441000735
            %                 194.956059343567
            %                 200.243058992176
            %                 205.339959518434
            %                 210.264207937470
            %                 215.030782021184
            %                 219.652649928065
            %                 224.141125784679
            %                 228.506148566718
            %                 232.756503531465
            %                 236.900000000000];

            % 默认雨型表
            app.UITable2.Data = [16,15,17,18,13,14,11,12,10,9,7,8,5,4,2,1,3,6,20,21,22,19,23,24]';
            app.UITable2.ColumnName = {'24小时暴雨雨型'};
            app.UITable2.RowName = num2cell(1:24);

            hold(app.UIAxes2,"on")
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            rainfall1_24 = app.UITable.Data(:,1);
            app.UITable.Data(:,2) = rainfall1_24(1:end)-[0;rainfall1_24(1:end-1)];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 纯输入（查表）
            rain_sort =app.UITable2.Data;
            % 第3列 24小时的设计雨型排列后的面雨量过程
            rain_diff = app.UITable.Data(:,2);
            app.UITable.Data(:,3) = rain_diff(rain_sort);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 纯输入（查表）
            rainfall_R = app.UITable.Data(:,3); % 复制第三列数据
            I0 = app.I0EditField.Value;

            fc = 0.0615*(sum(rainfall_R)-I0)^0.61;
            if fc > 0
                app.fcEditField.Value = fc;
            else
                errordlg('总时段面雨量过小，请检查面雨量计算是否出错','计算错误')
                return
            end

            % 第4列 计算Ri（扣除初损的面雨量过程）
            temp = I0;


            for i=1:length(rainfall_R)
                if rainfall_R(i) < temp
                    temp = temp -rainfall_R(i);
                    rainfall_R(i) = 0;
                else
                    rainfall_R(i) = rainfall_R(i) - temp;
                    break
                end
            end
            app.UITable.Data(:,4) = rainfall_R;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            rainfall = app.UITable.Data(:,3); % 提取第三列数据
            rainfall_R = app.UITable.Data(:,4); % 提取第四列数据
            fc = app.fcEditField.Value;
            i = find(rainfall_R>0,1);
            deltaT = 1;
            deltaTs = ones(length(rainfall_R),1)*deltaT;
            deltaTs(i) = rainfall_R(i)/rainfall(i)*deltaT;
            temp = deltaTs * fc;
            Rg = zeros(length(rainfall),1);

            for j = 1:length(rainfall)
                if rainfall_R(j) > temp(j)
                    Rg(j) = temp(j);
                else
                    Rg(j) = rainfall_R(j);
                end
            end
            Rs = rainfall_R - Rg;
            app.UITable.Data(:,5) = Rs;
            app.UITable.Data(:,6) = Rg;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_2;
            app.UITable3.Data = app.UITable.Data(:,5)';
            app.UITable3.ColumnName = num2cell(1:24);
            %             Colwidth = 60;
            %             app.UITable3.ColumnWidth = num2cell(ones(1,24)*Colwidth);
            app.UITable3.RowName = {'Rs'};
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            Tmax = app.TmaxEditField.Value;
            n = app.nEditField.Value;
            k = app.kEditField.Value;
            F = app.FEditField.Value;
            deltaT = 1;% 默认deltaT 为一小时的单位线
            Ts = 0:Tmax;
            st = gamcdf(Ts,n,k);
            st_1 = [0,st(1:end-1)];
            ut = st-st_1;

            qt = 10*F/3.6 *deltaT*ut;
            app.UITable4.Data = qt;
            app.UITable4.ColumnName = num2cell(0:Tmax);
            %             Colwidth = 60;
            %             app.UITable4.ColumnWidth = num2cell(ones(1,Tmax+1)*Colwidth);
            app.UITable4.RowName = {'qt'};
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            rainfall = app.UITable3.Data;
            qt = app.UITable4.Data';
            %             if isrow(qt)
            %                 qt = qt';% 如果qt为行向量，就变为列向量
            %             end

            col = length(rainfall);
            row = length(qt);
            array = zeros(row+col-1,col);
            row_index = 1:row;
            for i=1:col
                array(row_index,i) = qt/10.*rainfall(i);
                row_index = row_index+1;
            end
            surface_runoff = sum(array,2);

            app.UITable5.Data = surface_runoff;
            app.UITable5.RowName = num2cell(1:length(surface_runoff));

            plot(app.UIAxes,app.UITable5.Data,'.-')
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 直接读取
            app.FEditField_2.Value = app.FEditField.Value;
            app.f_cEditField.Value = app.fcEditField.Value;
            % delta t直接指定为1h
            app.DeltatEditField.Value =1;
            % 可能出问题(已解决)
            % tc的计算
            tc = sum(app.UITable.Data(:,6))/app.fcEditField.Value;
            tc = ceil(tc);
            app.t_cEditField.Value = tc;
            % D的计算
            app.DEditField.Value = app.TmaxEditField.Value;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % beta的计算
            app.betaEditField.Value = 0.133*app.FEditField_2.Value^(-0.28);
            % Q0的计算
            app.Q_0EditField.Value = 0.021*app.f_cEditField.Value^1.14*app.FEditField_2.Value;
            % T的计算
            app.TEditField.Value = app.t_cEditField.Value+app.DEditField.Value-app.DeltatEditField.Value;
            % Qg的计算
            fc = app.f_cEditField.Value;
            tc = app.t_cEditField.Value;
            F = app.FEditField_2.Value;
            beta = app.betaEditField.Value;
            Q0 =app.Q_0EditField.Value;
            T = app.TEditField.Value;
            Qg = (fc*tc*F/3.6-(T/2-1/beta)*Q0)/(T/2+1/beta);
            app.Q_gEditField.Value = Qg;

            groundwater_runoff = zeros(2*T,1);
            groundwater_runoff(1:T) = interp1([1;T],[Q0;Qg],(1:T)');
            groundwater_runoff(T+1:end) = Qg*exp(-beta*([T+1:2*T]-T));

            app.UITable6.Data = groundwater_runoff;
            app.UITable6.RowName = num2cell(1:length(groundwater_runoff));
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            value = app.Button_9.Value;
            if value == 1
                app.H1 = plot(app.UIAxes2,app.UITable6.Data,'.-');
            else
                delete(app.H1)
            end
        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            value = app.Button_10.Value;
            if value == 1
                app.H2 = plot(app.UIAxes2,app.UITable5.Data,'.-');
            else
                delete(app.H2)
            end
        end

        % Value changed function: Button_11
        function Button_11ValueChanged(app, event)
            value = app.Button_11.Value;
            if value == 1
                surface_runoff  = app.UITable5.Data;
                groundwater_runoff = app.UITable6.Data;
                n1 = length(surface_runoff);
                n2 = length(groundwater_runoff);
                if n1>n2
                    rainflood_Data = surface_runoff;
                    rainflood_Data(1:n2) = rainflood_Data(1:n2) + groundwater_runoff;
                else
                    rainflood_Data = groundwater_runoff;
                    rainflood_Data(1:n1) = rainflood_Data(1:n1) +surface_runoff;
                end
                app.H3 = plot(app.UIAxes2,rainflood_Data,'.-');

            else
                delete(app.H3)
            end
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            try
                app.callingTable.Data = app.H3.YData';
                app.callingTable.RowName = num2cell(1:length( app.callingTable.Data));
                msgbox('成功导出地下+地表径流数据')
            catch
                errordlg('导出失败','失败')
            end

        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;

            sheetname = '净雨计算';
            SavecallingTable = app.UITable;
            if key == 'n'
                func_save_excel_n(app.mainapp,sheetname,SavecallingTable)
            end
            if key == 's'
                func_save_excel_s(app.mainapp,sheetname,SavecallingTable)
            end
        end

        % Key press function: UITable2
        function UITable2KeyPress(app, event)
            key = event.Key;

            sheetname = '24小时暴雨雨型';
            SavecallingTable = app.UITable2;
            if key == 'n'
                func_save_excel_n(app.mainapp,sheetname,SavecallingTable)
            end
            if key == 's'
                func_save_excel_s(app.mainapp,sheetname,SavecallingTable)
            end
        end

        % Key press function: UITable3
        function UITable3KeyPress(app, event)
            key = event.Key;

            sheetname = '地表净雨过程';
            SavecallingTable = app.UITable3;
            if key == 'n'
                func_save_excel_n(app.mainapp,sheetname,SavecallingTable)
            end
            if key == 's'
                func_save_excel_s(app.mainapp,sheetname,SavecallingTable)
            end
        end

        % Key press function: UITable4
        function UITable4KeyPress(app, event)
            key = event.Key;

            sheetname = '单位线';
            SavecallingTable = app.UITable4;
            if key == 'n'
                func_save_excel_n(app.mainapp,sheetname,SavecallingTable)
            end
            if key == 's'
                func_save_excel_s(app.mainapp,sheetname,SavecallingTable)
            end
        end

        % Key press function: UITable5
        function UITable5KeyPress(app, event)
            key = event.Key;

            sheetname = '地表径流过程线';
            SavecallingTable = app.UITable5;
            if key == 'n'
                func_save_excel_n(app.mainapp,sheetname,SavecallingTable)
            end
            if key == 's'
                func_save_excel_s(app.mainapp,sheetname,SavecallingTable)
            end
        end

        % Key press function: UITable6
        function UITable6KeyPress(app, event)
            key = event.Key;

            sheetname = '地下径流过程线';
            SavecallingTable = app.UITable6;
            if key == 'n'
                func_save_excel_n(app.mainapp,sheetname,SavecallingTable)
            end
            if key == 's'
                func_save_excel_s(app.mainapp,sheetname,SavecallingTable)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '暴雨洪水计算';
            app.UIFigure.Icon = 'logo.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '净雨计算';
            app.GridLayout4 = uigridlayout(app.Tab_1);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'1-24小时的设计面雨量'; '时段面雨量'; '设计时段面雨量'; '扣除初损面雨量'; 'Rs'; 'Rg'};
            app.UITable.RowName = {};
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '时段面雨量的计算';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '面雨量雨型排序';
            app.Button_3 = uibutton(app.GridLayout4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '扣除初损的计算';
            app.Button_4 = uibutton(app.GridLayout4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '地面净雨和地下净雨的水源划分的计算';
            app.Panel = uipanel(app.GridLayout4);
            app.Panel.Title = '净雨计算的参数';
            app.GridLayout5 = uigridlayout(app.Panel);
            app.UITable2 = uitable(app.GridLayout5);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.KeyPressFcn = createCallbackFcn(app, @UITable2KeyPress, true);
            app.I0EditFieldLabel = uilabel(app.GridLayout5);
            app.I0EditFieldLabel.Text = '初损I0';
            app.I0EditField = uieditfield(app.GridLayout5, 'numeric');
            app.I0EditField.Limits = [0 Inf];
            app.I0EditField.Value = 22.5;
            app.fcEditFieldLabel = uilabel(app.GridLayout5);
            app.fcEditFieldLabel.Text = '稳定下渗率fc';
            app.fcEditField = uieditfield(app.GridLayout5, 'numeric');
            app.fcEditField.Enable = 'off';
            app.fcEditField.Tooltip = {'不用输入，可由初损I0和24小时雨量计算得出'};
            app.Button_5 = uibutton(app.GridLayout4, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '下一步';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '地表径流';
            app.GridLayout3 = uigridlayout(app.Tab_2);
            app.UIAxes = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes, '时段')
            ylabel(app.UIAxes, '地表径流')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.GridLayout3);
            app.Panel_2.Title = '地表净雨过程';
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.UITable3 = uitable(app.GridLayout6);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.UITable3.KeyPressFcn = createCallbackFcn(app, @UITable3KeyPress, true);
            app.Panel_3 = uipanel(app.GridLayout3);
            app.Panel_3.Title = '单位线的计算';
            app.GridLayout7 = uigridlayout(app.Panel_3);
            app.TmaxEditField = uieditfield(app.GridLayout7, 'numeric');
            app.TmaxEditField.Value = 24;
            app.TmaxEditFieldLabel = uilabel(app.GridLayout7);
            app.TmaxEditFieldLabel.Text = 'Tmax';
            app.nEditField = uieditfield(app.GridLayout7, 'numeric');
            app.nEditField.Value = 4.9;
            app.nEditFieldLabel = uilabel(app.GridLayout7);
            app.nEditFieldLabel.Text = 'n';
            app.kEditField = uieditfield(app.GridLayout7, 'numeric');
            app.kEditField.Value = 1.1;
            app.kEditFieldLabel = uilabel(app.GridLayout7);
            app.kEditFieldLabel.Text = 'k';
            app.FEditField = uieditfield(app.GridLayout7, 'numeric');
            app.FEditField.Value = 62;
            app.FEditFieldLabel = uilabel(app.GridLayout7);
            app.FEditFieldLabel.Text = 'F';
            app.Button_6 = uibutton(app.GridLayout7, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '单位线计算';
            app.UITable4 = uitable(app.GridLayout7);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.UITable4.KeyPressFcn = createCallbackFcn(app, @UITable4KeyPress, true);
            app.UITable5 = uitable(app.GridLayout3);
            app.UITable5.ColumnName = {'地表径流过程线'};
            app.UITable5.RowName = {};
            app.UITable5.KeyPressFcn = createCallbackFcn(app, @UITable5KeyPress, true);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '地下径流';
            app.GridLayout2 = uigridlayout(app.Tab_3);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes2, '时段')
            ylabel(app.UIAxes2, '径流')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_4 = uipanel(app.GridLayout2);
            app.Panel_4.Title = '输入参数';
            app.GridLayout8 = uigridlayout(app.Panel_4);
            app.f_cEditField = uieditfield(app.GridLayout8, 'numeric');
            app.f_cEditFieldLabel = uilabel(app.GridLayout8);
            app.f_cEditFieldLabel.Interpreter = 'latex';
            app.f_cEditFieldLabel.Text = '$f_c';
            app.DeltatEditField = uieditfield(app.GridLayout8, 'numeric');
            app.DeltatLabel = uilabel(app.GridLayout8);
            app.DeltatLabel.Interpreter = 'latex';
            app.DeltatLabel.Text = '$\Delta t';
            app.t_cEditField = uieditfield(app.GridLayout8, 'numeric');
            app.t_cEditFieldLabel = uilabel(app.GridLayout8);
            app.t_cEditFieldLabel.Interpreter = 'latex';
            app.t_cEditFieldLabel.Text = '$t_c';
            app.DEditField = uieditfield(app.GridLayout8, 'numeric');
            app.DLabel = uilabel(app.GridLayout8);
            app.DLabel.Interpreter = 'latex';
            app.DLabel.Text = '$D';
            app.Button_7 = uibutton(app.GridLayout8, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '前文自动获取';
            app.FEditField_2Label = uilabel(app.GridLayout8);
            app.FEditField_2Label.Interpreter = 'latex';
            app.FEditField_2Label.Text = '$F';
            app.FEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.Panel_5 = uipanel(app.GridLayout2);
            app.Panel_5.Title = '计算中间参数';
            app.GridLayout8_2 = uigridlayout(app.Panel_5);
            app.betaEditFieldLabel = uilabel(app.GridLayout8_2);
            app.betaEditFieldLabel.Interpreter = 'latex';
            app.betaEditFieldLabel.Text = '$\beta';
            app.betaEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.Q_0EditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.Q_0EditFieldLabel = uilabel(app.GridLayout8_2);
            app.Q_0EditFieldLabel.Interpreter = 'latex';
            app.Q_0EditFieldLabel.Text = '$Q_0';
            app.TEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.TEditFieldLabel = uilabel(app.GridLayout8_2);
            app.TEditFieldLabel.Interpreter = 'latex';
            app.TEditFieldLabel.Text = '$T';
            app.Q_gEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.Q_gEditFieldLabel = uilabel(app.GridLayout8_2);
            app.Q_gEditFieldLabel.Interpreter = 'latex';
            app.Q_gEditFieldLabel.Text = '$Q_g';
            app.Button_8 = uibutton(app.GridLayout8_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '计算';
            app.UITable6 = uitable(app.GridLayout2);
            app.UITable6.ColumnName = {'地下径流过程线'};
            app.UITable6.RowName = {};
            app.UITable6.KeyPressFcn = createCallbackFcn(app, @UITable6KeyPress, true);
            app.Button_9 = uibutton(app.GridLayout2, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '地下径流';
            app.Button_10 = uibutton(app.GridLayout2, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Text = '地表径流';
            app.Button_11 = uibutton(app.GridLayout2, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @Button_11ValueChanged, true);
            app.Button_11.Text = '地下+地表径流';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '净雨 + 单位线的径流推算';
            % Assign app.ContextMenu
            app.UITable5.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '导出地下+地表径流数据';
            % Assign app.ContextMenu2
            app.UIAxes2.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rain_flood(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end