classdef smooth_flood < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        TabGroup         matlab.ui.container.TabGroup
        Tab_1            matlab.ui.container.Tab
        GridLayout3      matlab.ui.container.GridLayout
        EditFieldTab1_1  matlab.ui.control.NumericEditField
        EditFieldTab1_2  matlab.ui.control.NumericEditField
        Label_9          matlab.ui.control.Label
        Button_plotline  matlab.ui.control.Button
        Button_next      matlab.ui.control.Button
        Label            matlab.ui.control.Label
        UIAxes_1         matlab.ui.control.UIAxes
        Tab_2            matlab.ui.container.Tab
        GridLayout2      matlab.ui.container.GridLayout
        EditFieldTab2_1  matlab.ui.control.NumericEditField
        Label_10         matlab.ui.control.Label
        EditFieldTab2_3  matlab.ui.control.NumericEditField
        EditFieldTab2_2  matlab.ui.control.NumericEditField
        Label_8          matlab.ui.control.Label
        Label_11         matlab.ui.control.Label
        Label_12         matlab.ui.control.Label
        EditFieldTab2_5  matlab.ui.control.NumericEditField
        EditFieldTab2_4  matlab.ui.control.NumericEditField
        Label_13         matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        EditFieldTab2_6  matlab.ui.control.NumericEditField
        UIAxes_2         matlab.ui.control.UIAxes
        FloodUITable     matlab.ui.control.Table
        ContextMenu      matlab.ui.container.ContextMenu
        Menu             matlab.ui.container.Menu
        ContextMenu2     matlab.ui.container.ContextMenu
        Menu_2           matlab.ui.container.Menu
    end


    properties (Access = private)
        callingTable
        callingAxes
        Tick_day1 % Description
        Tick_day3_1
        n
        Hpointn1
        Hpointn2
        new_flood
        Hline

        mainapp % 主函数app，可调用public方法
    end

    methods (Access = private)

        function func_plotflood(app,flood)

            delete(app.UIAxes_1.Children)
            app.FloodUITable.Data = flood;
            app.FloodUITable.ColumnName = {'修匀前洪水过程线'};
            app.FloodUITable.RowName = num2cell(1:length(flood));

            plot(app.UIAxes_1,app.FloodUITable.Data,'.-')
            app.n = size(flood,1);

            [~,max_Tick] = max(flood);
            Tick = zeros(1,2);

            for i=max_Tick:app.n-1
                if flood(i) < flood(i+1)
                    Tick(2) = i;
                    Tick(1) = Tick(2)-24;
                    break
                end
            end
            app.Tick_day1 = [Tick(1):Tick(2)]';
            app.Tick_day3_1 = [1:Tick(1),Tick(2):app.n];


            Tick_mean_value = mean([flood(Tick(2)),flood(Tick(2)+1)]);
            num1 = find(flood(max_Tick:Tick(2)) < Tick_mean_value,1) + max_Tick-2;
            num2 = find(flood(Tick(2)+1:end) < Tick_mean_value,1) + Tick(2);


            hold(app.UIAxes_1,"on")
            plot(app.UIAxes_1,[num1,num2],[Tick_mean_value,Tick_mean_value])
        end
    end

    methods (Access = public)

        function Sum_Area = func_getArea(~,y,equal_length_x)
            % 等宽x
            k = length(y);
            Sum_Area = 0;
            for i=1:k-1
                h = (y(i)+y(i+1))/2;
                Area = h*equal_length_x;
                Sum_Area = Sum_Area + Area;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, callingTable, callingAxes)
            try
                app.mainapp = mainapp;
                % 调用的时候以主程序居中
                x = app.mainapp.UIFigure.Position(1)+app.mainapp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
                y = app.mainapp.UIFigure.Position(2)+app.mainapp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
                w = app.UIFigure.Position(3);
                h = app.UIFigure.Position(4);
                app.UIFigure.Position = [x,y,w,h];
                app.callingTable = callingTable;
                app.callingAxes = callingAxes;
                flood = callingTable.Data(:,2).Var1;
                app.FloodUITable.Data = flood;
                app.FloodUITable.ColumnName = '修匀前洪水过程线';
                app.n = length(flood);
                app.FloodUITable.RowName = num2cell(1:app.n);
                func_plotflood(app,flood)
                if app.EditFieldTab1_1.Value ~= 0
                    n1 = app.EditFieldTab1_1.Value;
                    app.Hpointn1 = plot(app.UIAxes_1,n1,flood(n1),'*');
                end
                if app.EditFieldTab1_2.Value ~= 0
                    n2 = app.EditFieldTab1_2.Value;
                    app.Hpointn2 = plot(app.UIAxes_1,n2,flood(n2),'*');
                end
                %             plot(app.UIAxes_1,y,'.-')
                %             hold(app.UIAxes_2,'on')
            catch
                delete(app.UIFigure)
                errordlg("选择的设计洪水表为空，无法修匀","修匀失败")
                return
            end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            [file,path] = uigetfile('*.xlsx','*.xls');
            if isequal(file,0)
                msgbox('导入数据已取消')
            else
                filename = [path,file];
                table = readtable(filename,'VariableNamingRule','preserve');
                flood = table2array(table(:,2));
                func_plotflood(app,flood)
            end
        end

        % Value changed function: EditFieldTab1_1
        function EditFieldTab1_1ValueChanged(app, event)
            app.EditFieldTab1_1.Value = round(app.EditFieldTab1_1.Value);
            value = app.EditFieldTab1_1.Value;
            if value>app.n || value<1
                msgbox('线性修匀的起始点取值超过了范围')
                app.EditFieldTab1_1.Value = 0;
            else
                delete(app.Hpointn1)
                x = value;
                y = app.FloodUITable.Data(x);
                app.Hpointn1 = plot(app.UIAxes_1,x,y,'*');
            end
        end

        % Value changed function: EditFieldTab1_2
        function EditFieldTab1_2ValueChanged(app, event)
            app.EditFieldTab1_2.Value = round(app.EditFieldTab1_2.Value);
            value = app.EditFieldTab1_2.Value;
            if value>app.n || value<1
                msgbox('线性修匀的终止点取值超过了范围')
                app.EditFieldTab1_2.Value = 0;
            else
                delete(app.Hpointn2)
                x = value;
                y = app.FloodUITable.Data(x);
                app.Hpointn2 = plot(app.UIAxes_1,x,y,'*');
            end
        end

        % Button pushed function: Button_plotline
        function Button_plotlinePushed(app, event)
            delete(app.Hline)
            n1 = app.EditFieldTab1_1.Value;
            n2 = app.EditFieldTab1_2.Value;
            if n1 >= n2
                errordlg('修匀起始点不能大于等于修匀终止点','画图失败')
            else
                % 开始起调点，结束起调点线性插值下降
                new_y = interp1([n1,n2],[app.FloodUITable.Data(n1),app.FloodUITable.Data(n2)],n1:n2);
                app.new_flood = app.FloodUITable.Data;
                app.new_flood(n1:n2) = new_y;
                app.Hline = plot(app.UIAxes_1,n1:n2,new_y,'.-');
            end
        end

        % Button pushed function: Button_next
        function Button_nextPushed(app, event)

            try
                Tick = [app.Tick_day1(1),app.Tick_day1(end)];
            catch
                errordlg('数据为空','运行错误')
                return
            end
            Button_plotlinePushed(app,'on')
            app.TabGroup.SelectedTab = app.Tab_2;

            flood = app.FloodUITable.Data;
            n1 = app.EditFieldTab1_1.Value;
            n2 = app.EditFieldTab1_2.Value;
            [~,max_Tick] = max(flood);

            diff_area_day1 = sum(app.new_flood(n1:Tick(2)) - flood(n1:Tick(2)));
            diff_area_day3_1 = sum(flood(Tick(2)+1:n2) - app.new_flood(Tick(2)+1:n2));


            reduction_zone_day1 = setdiff([Tick(1):n1-1],max_Tick);  % day1减少区可自己拟定，可修改
            app.new_flood(reduction_zone_day1) = app.new_flood(reduction_zone_day1) ...
                - (diff_area_day1/length(reduction_zone_day1)) + app.EditFieldTab2_3.Value; % 后面的常数需要修改,常数为梯形面积与矩形面积的修正值
            % 常数修改的方式，在大致通过观测横线图，确定 [n1,day1减少区；n2,day3_1增加区]后, 只需要重复运行程序调整 第50行 后面加的小数

            addition_zone_day3_1 = [n2:app.n]; % day3-1增加区可自己拟定，可修改
            app.new_flood(addition_zone_day3_1) = app.new_flood(addition_zone_day3_1) + ...
                (diff_area_day3_1/length(addition_zone_day3_1)) + app.EditFieldTab2_6.Value; % 后面的常数需要修改,常数为梯形面积与矩形面积的修正值

            app.EditFieldTab2_1.Value = app.func_getArea(flood(Tick(1):Tick(2)),3600);
            app.EditFieldTab2_2.Value = app.func_getArea(app.new_flood(Tick(1):Tick(2)),3600);

            app.EditFieldTab2_4.Value = app.func_getArea(flood,3600);
            app.EditFieldTab2_5.Value = app.func_getArea(app.new_flood,3600);

            delete(app.UIAxes_2.Children);
            plot(app.UIAxes_2,flood,'.-');
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,app.new_flood,'.-');


        end

        % Value changed function: EditFieldTab2_3
        function EditFieldTab2_3ValueChanged(app, event)
            Button_nextPushed(app,'on')
        end

        % Value changed function: EditFieldTab2_6
        function EditFieldTab2_6ValueChanged(app, event)
            Button_nextPushed(app,'on')
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            try
            app.callingTable.Data.Var1 = app.new_flood;
            %             delete(app.callingAxes.Children)
            plot(app.callingAxes,app.callingTable.Data.Time,app.new_flood,'.-')
            msgbox('成功导出修匀洪水')
            catch
                errordlg('导出失败','失败')
            end
        end

        % Key press function: FloodUITable
        function FloodUITableKeyPress(app, event)
            key = event.Key;

            sheetname = '修匀app传入的洪水';
            SavecallingTable = app.FloodUITable;
            if key == 'n'
                func_save_excel_n(app.mainapp,sheetname,SavecallingTable)
            end
            if key == 's'
                func_save_excel_s(app.mainapp,sheetname,SavecallingTable)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '洪水修匀';
            app.UIFigure.Icon = 'logo.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FloodUITable = uitable(app.GridLayout);
            app.FloodUITable.ColumnName = '';
            app.FloodUITable.RowName = {};
            app.FloodUITable.KeyPressFcn = createCallbackFcn(app, @FloodUITableKeyPress, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '选择要修匀位置';
            app.GridLayout3 = uigridlayout(app.Tab_1);
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes_1, 'Z')
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '线性修匀的起始点';
            app.Button_next = uibutton(app.GridLayout3, 'push');
            app.Button_next.ButtonPushedFcn = createCallbackFcn(app, @Button_nextPushed, true);
            app.Button_next.Text = '下一步';
            app.Button_plotline = uibutton(app.GridLayout3, 'push');
            app.Button_plotline.ButtonPushedFcn = createCallbackFcn(app, @Button_plotlinePushed, true);
            app.Button_plotline.Text = '画线';
            app.Label_9 = uilabel(app.GridLayout3);
            app.Label_9.Text = '线性修匀的终止点';
            app.EditFieldTab1_2 = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldTab1_2.ValueChangedFcn = createCallbackFcn(app, @EditFieldTab1_2ValueChanged, true);
            app.EditFieldTab1_2.Value = 37;
            app.EditFieldTab1_1 = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldTab1_1.ValueChangedFcn = createCallbackFcn(app, @EditFieldTab1_1ValueChanged, true);
            app.EditFieldTab1_1.Value = 23;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '水量平衡修正';
            app.GridLayout2 = uigridlayout(app.Tab_2);
            app.UIAxes_2 = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes_2, 'Z')
            app.EditFieldTab2_6 = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldTab2_6.ValueDisplayFormat = '%.5g';
            app.EditFieldTab2_6.ValueChangedFcn = createCallbackFcn(app, @EditFieldTab2_6ValueChanged, true);
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '最大一日洪量（修匀前）';
            app.Label_13 = uilabel(app.GridLayout2);
            app.Label_13.Text = '最大三日洪量（修匀后）';
            app.EditFieldTab2_4 = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldTab2_4.ValueDisplayFormat = '%.5g';
            app.EditFieldTab2_4.Editable = 'off';
            app.EditFieldTab2_4.Enable = 'off';
            app.EditFieldTab2_5 = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldTab2_5.ValueDisplayFormat = '%.5g';
            app.EditFieldTab2_5.Editable = 'off';
            app.EditFieldTab2_5.Enable = 'off';
            app.Label_12 = uilabel(app.GridLayout2);
            app.Label_12.Text = '最大三日洪量（修匀后）';
            app.Label_11 = uilabel(app.GridLayout2);
            app.Label_11.Text = '最大一日洪量（修匀后）';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '最大一日洪量面积修正系数';
            app.EditFieldTab2_2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldTab2_2.ValueDisplayFormat = '%.5g';
            app.EditFieldTab2_2.Editable = 'off';
            app.EditFieldTab2_2.Enable = 'off';
            app.EditFieldTab2_3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldTab2_3.ValueDisplayFormat = '%.5g';
            app.EditFieldTab2_3.ValueChangedFcn = createCallbackFcn(app, @EditFieldTab2_3ValueChanged, true);
            app.Label_10 = uilabel(app.GridLayout2);
            app.Label_10.Text = '最大三日洪量面积修正系数';
            app.EditFieldTab2_1 = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldTab2_1.ValueDisplayFormat = '%.5g';
            app.EditFieldTab2_1.Editable = 'off';
            app.EditFieldTab2_1.Enable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '导入数据';
            % Assign app.ContextMenu
            app.FloodUITable.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '导出修匀洪水';
            % Assign app.ContextMenu2
            app.UIAxes_2.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smooth_flood(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end